classdef patientid__gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PATIENTIDUIFigure              matlab.ui.Figure
        ID                             matlab.ui.control.EditField
        EnterPATIENTIDandCONFIRMLabel  matlab.ui.control.Label
        PrefixnoteditableLabel         matlab.ui.control.Label
        ProgressiveuniqueIDLabel       matlab.ui.control.Label
        CONFIRMButton                  matlab.ui.control.Button
        SuffixnoteditableLabel         matlab.ui.control.Label
        VISIT                          matlab.ui.control.EditField
        PATIENTLabel                   matlab.ui.control.Label
        VISITLabel                     matlab.ui.control.Label
        lprefix                        matlab.ui.control.Label
        ldot                           matlab.ui.control.Label
        lsuffix                        matlab.ui.control.Label
    end

    
    properties (Access = public)
        out
        error_00
        error_000
        report_path
        new_val_pat
        new_val_vis
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, in, a, b)
            % Change figure icon
            change__figicon(app.PATIENTIDUIFigure);
            PAT = upper(in.pathology);
            USER = in.buttons_on(strcmp(in.buttons_on(:,1), in.pathology), 3);
            USER = USER{1};
            CENTER = in.buttons_on(strcmp(in.buttons_on(:,1), in.pathology), 4);
            CENTER = CENTER{1};
            MOD = upper(in.mod);
            app.report_path = in.report_path;
            
            strprefix = strcat(CENTER, '-', PAT, '__P');
            strsuffix = MOD;
            set(app.lprefix, 'Text', strprefix);
            set(app.lsuffix, 'Text', strsuffix);
            set(app.ID, 'Tooltip', 'Please, enter only three-digit numbers (e.g. 001, 010, 100).');
            set(app.VISIT, 'Tooltip', 'Please, enter only two-digit numbers (e.g. 01, 10, 99).');
            
            app.out.save = in.save;
            
            app.ID.Value = a;
            app.VISIT.Value = b;
            if length(a)==3 && length(b) == 2
                app.error_00 = 1;
                app.error_000 = 1;
            else
                app.error_00 = 0;
                app.error_000 = 0;
            end
            app.new_val_pat = 1;
            app.new_val_vis = 1;
            
            movegui(app.PATIENTIDUIFigure,'center');  
%             app.error_00 = 0;
%             app.error_000 = 0;
%             PAT = upper(in.pathology);
%             USER = in.buttons_on(strcmp(in.buttons_on(:,1), in.pathology), 3);
%             USER = USER{1};
%             CENTER = in.buttons_on(strcmp(in.buttons_on(:,1), in.pathology), 4);
%             CENTER = CENTER{1};
%             MOD = upper(in.mod);
%             app.report_path = in.report_path;
%             
%             strprefix = strcat(CENTER, '-', PAT, '__P');
%             strsuffix = MOD;
%             
%             set(app.lprefix, 'Text', strprefix);
%             set(app.lsuffix, 'Text', strsuffix);
%             set(app.ID, 'Tooltip', 'Please, enter only three-digit numbers (e.g. 001, 010, 100).');
%             set(app.VISIT, 'Tooltip', 'Please, enter only two-digit numbers (e.g. 01, 10, 99).');
%             
%             app.out.save = in.save;
%             
%             app.ID.Value = a;
%             app.VISIT.Value = b;
%             
%             movegui(app.PATIENTIDUIFigure,'center');  
        end

        % Value changed function: ID
        function IDValueChanged(app, event)
            if isempty(app.ID.Value) || size(app.ID.Value, 2) ~= 3 || isnan(str2double(app.ID.Value))
                app.error_000 = 0;
            else
                app.error_000 = 1;
            end
        end

        % Button pushed function: CONFIRMButton
        function CONFIRMButtonPushed(app, event)
            if app.error_000 == 0 || app.error_00 == 0
                text = sprintf('Incorrect Patient (and/or Visit) code fields.\nPlease fill the fields with exactly 3 (2) digits.');
                uialert(app.PATIENTIDUIFigure, text,'Error','Icon','error');
            else
                ERROR = 0;
                if isempty(app.ID.Value) || isempty(app.VISIT.Value) || size(app.ID.Value, 2) ~= 3 || size(app.VISIT.Value, 2) ~= 2
                    app.ID.Value = '---';
                    app.VISIT.Value = '--';
                    ERROR = 1;
                end
                PID = strcat(app.lprefix.Text, app.ID.Value, '.', app.VISIT.Value, app.lsuffix.Text);
                lista = dir(fullfile(app.report_path, [PID(1:end-1),'*']));
                if ~isempty(lista)
                    lista2 = dir(fullfile(app.report_path, PID,[PID,'.*']));
                    if ~isempty(lista2)
                        path_old_file = fullfile(lista2(1).folder,lista2(1).name);
                        msg = sprintf('Warning! the inserted PATIENT ID has already been used.\nThe file " %s " would be overwritten.',path_old_file);
                        title = 'ID already used';
                        selection = uiconfirm(app.PATIENTIDUIFigure,msg,title,...
                                   'Options',{'CONFIRM ID','CHANGE ID','Cancel'},...
                                   'DefaultOption',1,'CancelOption',3,'Icon','warning');
                        if strcmp(selection,'Cancel') || strcmp(selection,'CHANGE ID')
                        else
                            save(app.out.save, 'PID', 'ERROR');
                            closereq
                        end
                    else
                        save(app.out.save, 'PID', 'ERROR');
                        closereq
                    end
                else
                    save(app.out.save, 'PID', 'ERROR');
                    closereq
                end
            end
        end

        % Value changed function: VISIT
        function VISITValueChanged(app, event)
            if isempty(app.VISIT.Value) || size(app.VISIT.Value, 2) ~= 2 || isnan(str2double(app.VISIT.Value))
                app.error_00 = 0;
            else
                app.error_00 = 1;
            end
        end

        % Value changing function: ID
        function IDValueChanging(app, event)
            if app.new_val_pat == 1
                app.new_val_pat = 0;
                changingValue = event.Value;
                app.ID.Value = '';
                or_value = 'e.g. 001';
                if length(changingValue)<length(or_value)
                    i = 1;
                    j = 1;
                    k = 1;
                    new_char = [];
                    while j<=length(changingValue)
                        if strcmp(changingValue(j),or_value(k))
                            j = j + 1;
                            k = k + 1;
                        else
                            k = k + (length(or_value)-length(changingValue));
                            if strcmp(changingValue(j),or_value(k))
                                j = length(changingValue)+1;
                            else
                                new_char{i} = changingValue(j);
                                k = k + 1 ;
                                j = j + 1;
                                i = i + 1;
                            end
                            
                            while j<=length(changingValue)
                                if strcmp(changingValue(j),or_value(k))
                                    j = length(changingValue)+1;
                                else
                                    new_char{i} = changingValue(j);
                                    j = j + 1;
                                    i = i + 1;
                                    k = k + 1;
                                end
                            end
                        end
                    end
%                     if ~isempty(new_char)
                        Value=num2str(cell2mat(new_char));
%                     end
                elseif length(changingValue)>length(or_value)
                    i = 1;
                    j = 1;
                    k = 1;
                    new_char = [];
                    while j<=length(changingValue) && k<=length(or_value)
                        if strcmp(changingValue(j),or_value(k))
                            j = j + 1;
                            k = k + 1;
                        else
                            new_char{i} = changingValue(j);
%                             k = k + 1 ;
                            j = j + 1;
                            i = i + 1;
                            
                            while j<=length(changingValue) && k<=length(or_value)
                                if strcmp(changingValue(j),or_value(k))
                                    j = length(changingValue)+1;
                                else
                                    new_char{i} = changingValue(j);
                                    j = j + 1;
                                    i = i + 1;
%                                     k = k + 1;
                                end
                            end
                        end
                    end
                    
                    if ~isempty(new_char)
                        Value = num2str(cell2mat(new_char));
                    else
                        Value = changingValue(length(or_value) + 1: end);
                    end
                    
                else
                    i = 1;
                    j = 1;
                    k = 1;
                    new_char = [];
                    while j<=length(changingValue)
                        if strcmp(changingValue(j),or_value(k))
                            j = j + 1;
                            k = k + 1;
                        else
                            new_char{i} = changingValue(j);
                            k = k + 1 ;
                            j = j + 1;
                            i = i + 1;
                            
                            while j<=length(changingValue)
                                if strcmp(changingValue(j),or_value(k))
                                    j = length(changingValue)+1;
                                else
                                    new_char{i} = changingValue(j);
                                    j = j + 1;
                                    i = i + 1;
                                    k = k + 1;
                                end
                            end
                        end
                    end
%                     if ~isempty(new_char)
                        Value=num2str(cell2mat(new_char));
%                     end
                end
                
                app.ID.Value = Value;

            end
        end

        % Value changing function: VISIT
        function VISITValueChanging(app, event)
            if app.new_val_vis == 1
                app.new_val_vis = 0;
                changingValue = event.Value;
                app.VISIT.Value = '';
                or_value = 'e.g. 01';
                if length(changingValue)<length(or_value)
                    i = 1;
                    j = 1;
                    k = 1;
                    new_char = [];
                    while j<=length(changingValue)
                        if strcmp(changingValue(j),or_value(k))
                            j = j + 1;
                            k = k + 1;
                        else
                            k = k + (length(or_value)-length(changingValue));
                            if strcmp(changingValue(j),or_value(k))
                                j = length(changingValue)+1;
                            else
                                new_char{i} = changingValue(j);
                                k = k + 1 ;
                                j = j + 1;
                                i = i + 1;
                            end
                            
                            while j<=length(changingValue)
                                if strcmp(changingValue(j),or_value(k))
                                    j = length(changingValue)+1;
                                else
                                    new_char{i} = changingValue(j);
                                    j = j + 1;
                                    i = i + 1;
                                    k = k + 1;
                                end
                            end
                        end
                    end
%                     if ~isempty(new_char)
                        Value=num2str(cell2mat(new_char));
%                     end
                elseif length(changingValue)>length(or_value)
                    i = 1;
                    j = 1;
                    k = 1;
                    new_char = [];
                    while j<=length(changingValue) && k<=length(or_value)
                        if strcmp(changingValue(j),or_value(k))
                            j = j + 1;
                            k = k + 1;
                        else
                            new_char{i} = changingValue(j);
%                             k = k + 1 ;
                            j = j + 1;
                            i = i + 1;
                            
                            while j<=length(changingValue) && k<=length(or_value)
                                if strcmp(changingValue(j),or_value(k))
                                    j = length(changingValue)+1;
                                else
                                    new_char{i} = changingValue(j);
                                    j = j + 1;
                                    i = i + 1;
%                                     k = k + 1;
                                end
                            end
                        end
                    end
                    
                    if ~isempty(new_char)
                        Value = num2str(cell2mat(new_char));
                    else
                        Value = changingValue(length(or_value) + 1: end);
                    end
                    
                else
                    i = 1;
                    j = 1;
                    k = 1;
                    new_char = [];
                    while j<=length(changingValue)
                        if strcmp(changingValue(j),or_value(k))
                            j = j + 1;
                            k = k + 1;
                        else
                            new_char{i} = changingValue(j);
                            k = k + 1 ;
                            j = j + 1;
                            i = i + 1;
                            
                            while j<=length(changingValue)
                                if strcmp(changingValue(j),or_value(k))
                                    j = length(changingValue)+1;
                                else
                                    new_char{i} = changingValue(j);
                                    j = j + 1;
                                    i = i + 1;
                                    k = k + 1;
                                end
                            end
                        end
                    end
%                     if ~isempty(new_char)
                        Value=num2str(cell2mat(new_char));
%                     end
                end
                
                app.VISIT.Value = Value;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PATIENTIDUIFigure = uifigure('Visible', 'off');
            app.PATIENTIDUIFigure.Name = 'PATIENT ID';
            app.ID = uieditfield(app.PATIENTIDUIFigure, 'text');
            app.ID.ValueChangedFcn = createCallbackFcn(app, @IDValueChanged, true);
            app.ID.ValueChangingFcn = createCallbackFcn(app, @IDValueChanging, true);
            app.ID.Tooltip = {''};
            app.ID.Value = 'e.g. 001';
            app.EnterPATIENTIDandCONFIRMLabel = uilabel(app.PATIENTIDUIFigure);
            app.EnterPATIENTIDandCONFIRMLabel.Text = 'Enter PATIENT ID and CONFIRM:';
            app.PrefixnoteditableLabel = uilabel(app.PATIENTIDUIFigure);
            app.PrefixnoteditableLabel.Text = {'Prefix'; '(not editable)'};
            app.ProgressiveuniqueIDLabel = uilabel(app.PATIENTIDUIFigure);
            app.ProgressiveuniqueIDLabel.Text = {'Progressive'; 'unique ID'};
            app.CONFIRMButton = uibutton(app.PATIENTIDUIFigure, 'push');
            app.CONFIRMButton.ButtonPushedFcn = createCallbackFcn(app, @CONFIRMButtonPushed, true);
            app.CONFIRMButton.Text = 'CONFIRM';
            app.SuffixnoteditableLabel = uilabel(app.PATIENTIDUIFigure);
            app.SuffixnoteditableLabel.Text = {'Suffix'; '(not editable)'};
            app.VISIT = uieditfield(app.PATIENTIDUIFigure, 'text');
            app.VISIT.ValueChangedFcn = createCallbackFcn(app, @VISITValueChanged, true);
            app.VISIT.ValueChangingFcn = createCallbackFcn(app, @VISITValueChanging, true);
            app.VISIT.Tooltip = {''};
            app.VISIT.Value = 'e.g. 01';
            app.PATIENTLabel = uilabel(app.PATIENTIDUIFigure);
            app.PATIENTLabel.Enable = 'off';
            app.PATIENTLabel.Text = 'PATIENT';
            app.VISITLabel = uilabel(app.PATIENTIDUIFigure);
            app.VISITLabel.Enable = 'off';
            app.VISITLabel.Text = 'VISIT';
            app.lprefix = uilabel(app.PATIENTIDUIFigure);
            app.lprefix.Text = '-';
            app.ldot = uilabel(app.PATIENTIDUIFigure);
            app.ldot.Text = '.';
            app.lsuffix = uilabel(app.PATIENTIDUIFigure);
            app.lsuffix.Text = '-';
            % Show the figure after all components are created
            app.PATIENTIDUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = patientid__gui(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PATIENTIDUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PATIENTIDUIFigure)
        end
    end
end