classdef segmentation__2d < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                        matlab.ui.Figure
        logo                           matlab.ui.control.Image
        DeepTraceTechnologiesSRLLabel  matlab.ui.control.Label
        img                            matlab.ui.control.UIAxes
        cm__label                      matlab.ui.control.Label
        cm                             matlab.ui.control.DropDown
        draw                           matlab.ui.control.Button
        erase                          matlab.ui.control.Button
        save                           matlab.ui.control.Button
        contour__label                 matlab.ui.control.Label
    end

    
    properties (Access = private)
        output;
        temp;
    end
    
    properties (Access = public)
    end
    
    methods (Access = private)
                
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function seg2d_OpeningFcn(app, in, root)
           
            app.temp.path = in;
            app.temp.root = root;
            app.temp.info = dicominfo(in);
            app.temp.image = dicomread(in);
            app.temp.mask = zeros(size(app.temp.image));
            
            % Plot
            imshow(app.temp.image, [], 'Parent', app.img);
            
            movegui(app.figure1,'center');

        end

        % Value changed function: cm
        function cmValueChanged(app, event)
            value = app.cm.Value;
            % h__temp = figure('Visible', 'off');
            cm__ = colormap(lower(value));
            imshow(app.temp.image, [], 'Parent', app.img, 'Colormap', cm__);
        end

        % Button pushed function: draw
        function drawButtonPushed(app, event)
            h = drawfreehand(app.img);%, 'Multiclick', 1);
            app.temp.mask = uint16(createMask(h));
            app.temp.mask__pos = h.Position;
        end

        % Button pushed function: save
        function saveButtonPushed(app, event)
            five__percrow = round(size(app.temp.mask, 1) * 0.05);
            five__perccol = round(size(app.temp.mask, 2) * 0.05);
    
            [r, c] = find(app.temp.mask>0);
    
            colmin = min(min(c)) - five__perccol;
            rowmin = min(min(r)) - five__percrow;
            colmax = max(max(c)) + five__perccol;
            rowmax = max(max(r)) + five__percrow;
            if colmin <= 0
                colmin = 1;
            end
            if rowmin <= 0
                rowmin = 1;
            end
            if colmax > size(app.temp.mask, 2)
                colmax = size(app.temp.mask, 2);
            end
            if rowmax > size(app.temp.mask, 1)
                rowmax = size(app.temp.mask, 1);
            end
            
            crop__img = squeeze(app.temp.image(rowmin:rowmax, colmin:colmax));
            crop__mask = squeeze(app.temp.mask(rowmin:rowmax, colmin:colmax));
            
            % Save img
            new__img = strcat(app.temp.root, 'vol.nii');
            W = size(crop__img, 1);
            H = size(crop__img, 2);
            % Default values
            dx = [1 1];
            nii = make_nii(crop__img, [dx(1,1) dx(1,2)], [H/2 W/2], 64);
            save_nii(nii, new__img);
            
            % Save mask
            new__mask = strcat(app.temp.root, 'mask.nii');
            W = size(crop__mask, 1);
            H = size(crop__mask, 2);
            % Default values
            dx = [1 1];
            nii = make_nii(crop__mask, [dx(1,1) dx(1,2)], [H/2 W/2], 64);
            save_nii(nii, new__mask);
            
            closereq;
        end

        % Button pushed function: erase
        function eraseButtonPushed(app, event)
            % mask(:,:,n_fetta) = 0;
            % mask_pos.(names{n_fetta}) = n_fetta;
            % axes(handles.slice_fig)
            % hf = imagesc(volume(:,:,n_fetta));
            % axis off
            % colormap(mappa)
            % caxis([zMin, zMax]);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'TRACE4';
            app.logo = uiimage(app.figure1);
            app.logo.ImageSource = 'logo-trace4.png';
            app.DeepTraceTechnologiesSRLLabel = uilabel(app.figure1);
            app.DeepTraceTechnologiesSRLLabel.Text = '© 2019 DeepTrace Technologies S.R.L.';
            app.img = uiaxes(app.figure1);
            title(app.img, 'Segmentation')
            xlabel(app.img, 'X')
            ylabel(app.img, 'Y')
            app.cm__label = uilabel(app.figure1);
            app.cm__label.Text = 'Colormap';
            app.cm = uidropdown(app.figure1);
            app.cm.Items = {'Gray', 'Hot', 'Jet'};
            app.cm.ValueChangedFcn = createCallbackFcn(app, @cmValueChanged, true);
            app.cm.Value = 'Gray';
            app.draw = uibutton(app.figure1, 'push');
            app.draw.ButtonPushedFcn = createCallbackFcn(app, @drawButtonPushed, true);
            app.draw.Text = 'Draw';
            app.erase = uibutton(app.figure1, 'push');
            app.erase.ButtonPushedFcn = createCallbackFcn(app, @eraseButtonPushed, true);
            app.erase.Text = 'Erase';
            app.save = uibutton(app.figure1, 'push');
            app.save.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.save.Text = {'Save and'; 'process'};
            app.contour__label = uilabel(app.figure1);
            app.contour__label.Text = 'Contour';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = segmentation__2d(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)seg2d_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end