classdef trace4BUS_login < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TRACE4BUSUIFigure       matlab.ui.Figure
        logo                    matlab.ui.control.Image
        subtitle                matlab.ui.control.Label
        ed__img                 matlab.ui.control.Image
        label                   matlab.ui.control.Label
        PASSWORDEditFieldLabel  matlab.ui.control.Label
        PASSWORDEditField       matlab.ui.control.EditField
        LostpasswordButton      matlab.ui.control.Button
        RemembermeCheckBox      matlab.ui.control.CheckBox
        ed__img_2               matlab.ui.control.Image
        text_initialization     matlab.ui.control.Label
        text_general            matlab.ui.control.Label
        error__message          matlab.ui.control.Label
        license_error__message  matlab.ui.control.Label
        final_error__message    matlab.ui.control.Label
        newP__message           matlab.ui.control.Label
        USERNAMEEditFieldLabel  matlab.ui.control.Label
        USERNAMEEditField       matlab.ui.control.EditField
        HTML                    matlab.ui.control.HTML
        SIGNINButton            matlab.ui.control.Button
        subtitle_2              matlab.ui.control.Label
    end

    
    properties (Access = private)
        username
        password
        license__fullpath
        auth__
        modelli
        license
%         buttons_on
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function trace4BUS_login__gui_OpeningFcn(app, varargin)
%             % This function has no output args, see OutputFcn.
%             % hObject    handle to figure
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % handles    structure with handles and user data (see GUIDATA)
%             % varargin   command line arguments to trace4__gui (see VARARGIN)
%             
%             % Choose default command line output for trace4__gui
%             app.output = hObject;
%             
%             % Update handles structure
%             guidata(hObject, handles);
            % Change figure icon
            change__figicon(app.TRACE4BUSUIFigure);
            if ~isdeployed
                addpath(genpath('./'));
            end
            
            % Set main buttons / functions invisible
            gui__handles{1}  = app.ed__img;
            gui__handles{2}  = app.USERNAMEEditField;
            gui__handles{3}  = app.USERNAMEEditFieldLabel;
            gui__handles{4}  = app.PASSWORDEditField;
            gui__handles{5}  = app.PASSWORDEditFieldLabel;
            gui__handles{6}  = app.SIGNINButton;
            gui__handles{7}  = app.HTML;
            gui__handles{8}  = app.text_general;
            gui__handles{9}  = app.RemembermeCheckBox;
            gui__handles{10} = app.LostpasswordButton;
            for i = 1:size(gui__handles,2)
                set(gui__handles{i},'Visible','off');
            end
            label_copy = string__copyright;
            set(app.label,'text',label_copy);
            
            % Set username and "Rembember me" option
            try
            
                [check__pref, preferences__fullpath] = check__preferences__local();
                if check__pref == 1
                    load(preferences__fullpath, 't4bus__preferences');
                    set(app.RemembermeCheckBox, 'Value', t4bus__preferences.remember__me);
                    
                    if t4bus__preferences.remember__me == 1
                        set(app.USERNAMEEditField, 'Value', t4bus__preferences.last__login);
                        app.username = app.USERNAMEEditField.Value;
                    end
                    
                    clearvars 't4bus__preferences'
                end
                
            catch ME
                
                uialert(app.TRACE4BUSUIFigure, 'Unexpected error.', 'Error','Icon','error')
                
            end            
            
            movegui(app.TRACE4BUSUIFigure,'center');
            pause(0.01)
            p = mfilename('fullpath');
            endout = regexp(p, filesep, 'split');
        
            for i = 1:size(endout,2)-1
                if i == 1
                    path_exe = endout{1,1};
                else
                    path_exe = strcat(path_exe, '/' ,endout{1,i});
                end  
            end
            try if ~isempty(varargin)
                    app.modelli = varargin{1};
                else
%                     app.text_initialization.Visible = 'on';
                                                                            messageout = 'Initialization...';
                                                                            hout = uiprogressdlg(app.TRACE4BUSUIFigure,'Title',messageout,...
                                                                            'Indeterminate','on');
                                                                            drawnow
                    app.text_general.Visible = 'off';
                    app.RemembermeCheckBox.Visible = 'off';
                    app.LostpasswordButton.Visible = 'off';
                    pause(0.01)
                    model__abspath = path_exe;
                    mod=dir(fullfile(model__abspath,'model\','*model_BioUS.mat'));
                    app.modelli = load(fullfile(mod(1).folder,mod(1).name));
    %                 mod=dir(fullfile(model__abspath,'model\','*model_cystic.mat'));
    %                 app.modello.cystic_model = load(fullfile(mod(1).folder,mod(1).name));
    %                 mod=dir(fullfile(model__abspath,'model\','*model_mix.mat'));
    %                 app.modello.mix_model = load(fullfile(mod(1).folder,mod(1).name));
%                     app.text_initialization.Visible = 'off';
                                                                            try close(hout); end
                    app.text_general.Visible = 'on';
                    app.RemembermeCheckBox.Visible = 'on';
                    app.LostpasswordButton.Visible = 'on';
                    pause(0.01)
                end
            catch
                app.text_initialization.Visible = 'on';
                app.text_general.Visible = 'off';
                app.RemembermeCheckBox.Visible = 'off';
                app.LostpasswordButton.Visible = 'off';
                pause(0.01)
                model__abspath = path_exe;
                mod=dir(fullfile(model__abspath,'model\','*model_BioUS.mat'));
                app.modelli = load(fullfile(mod(1).folder,mod(1).name));
%                 mod=dir(fullfile(model__abspath,'model\','*model_cystic.mat'));
%                 app.modello.cystic_model = load(fullfile(mod(1).folder,mod(1).name));
%                 mod=dir(fullfile(model__abspath,'model\','*model_mix.mat'));
%                 app.modello.mix_model = load(fullfile(mod(1).folder,mod(1).name));
                app.text_initialization.Visible = 'off';
                app.text_general.Visible = 'on';
                app.RemembermeCheckBox.Visible = 'on';
                app.LostpasswordButton.Visible = 'on';
                pause(0.01)
            end
            
            
            [cl, app.license__fullpath.private] = check__license__private();
            [c2, app.license__fullpath.local] = check__license__local();
            if cl == 1 && c2 == 1
                for i = 1:size(gui__handles,2)
                    set(gui__handles{i},'Visible','on');
                end
            end
        end

        % Button pushed function: SIGNINButton
        function SIGNINButtonPushed(app, event)
            app.SIGNINButton.Enable = 'off';
            pause(0.1)
%                                                                             prog = 0.1;
                                                                            messageout = 'Checking credentials...';
                                                                            hout = uiprogressdlg(app.TRACE4BUSUIFigure,'Title',messageout,...
                                                                            'Indeterminate','on');
                                                                            drawnow
%                                                                             hout = waitbar(prog,messageout);
            set(app.error__message,'Visible','off');
            set(app.error__message,'Text','Username or password incorrect');
            set(app.final_error__message,'Visible','off');
            set(app.license_error__message,'Visible','off');
            set(app.newP__message,'Visible','off');
            [app.auth__,buttons_ok] = authentication(app.username, app.password, app.license__fullpath);
%                                                                             try
%                                                                                 prog = prog + 0.5;
% %                                                                                 messageout = sprintf('Processing CT %d of %d: segmentation',i,length(app.path.list_file));
%                                                                                 waitbar(prog,hout,messageout);
%                                                                                 drawnow
%                                                                             catch
% %                                                                                 messageout = sprintf('Processing CT %d of %d: segmentation',i,length(app.path.list_file));
%                                                                                 hout = waitbar(prog,messageout);
%                                                                                 drawnow
%                                                                             end
            if app.auth__ == 1
                
                try
                
                    [check__pref, preferences__fullpath] = check__preferences__local();
                    if check__pref == 1
                        load(preferences__fullpath, 't4bus__preferences');
                        t4bus__preferences.last__login = app.username;
                        save(preferences__fullpath, '-append', 't4bus__preferences');
                    end
                    
                catch ME
                    
                    uialert(app.TRACE4BUSUIFigure, 'Unexpected error.', 'Error','Icon','error')
                    
                end
                
%                 increase__counter(app.license__fullpath,'login');
                out.buttons_on = buttons_ok;
                out.pathology = 'bc';
                out.username = app.username;
                out.password = app.password;
                out.modelli = app.modelli;
%                                                                             try %#ok<*TRYNC>
%                                                                                 waitbar(1,hout);
                                                                                close(hout);
%                                                                             end
                closereq;
                trace4bus__multi_test1(out,[],[]);
%                 trace4BUS__main(out);%%%%%%%
            elseif app.auth__ == 1.1 %first time
                try
                
                    [check__pref, preferences__fullpath] = check__preferences__local();
                    if check__pref == 1
                        load(preferences__fullpath, 't4bus__preferences');
                        t4bus__preferences.last__login = app.username;
                        save(preferences__fullpath, '-append', 't4bus__preferences');
                    end
                    
                catch ME
                    
                    uialert(app.TRACE4BUSUIFigure, 'Unexpected error.', 'Error','Icon','error')
                    
                end
                
%                 increase__counter(app.license__fullpath,'login');
                out.buttons_on = buttons_ok;
                out.pathology = 'bc';
                out.username = app.username;
                out.password = app.password;
                out.modelli = app.modelli;
                lpath = app.license__fullpath;
%                                                                             try %#ok<*TRYNC>
%                                                                                 waitbar(1,hout);
                                                                                close(hout);
%                                                                             end
                closereq;
                trace4bus__first_time(out,lpath);
            elseif app.auth__ == -1
%                                                                             try %#ok<*TRYNC>
%                                                                                 waitbar(1,hout);
                                                                                close(hout);
%                                                                             end
                closereq;
                trace4__superuser;
            elseif app.auth__ == -2
%                                                                             try %#ok<*TRYNC>
%                                                                                 waitbar(1,hout);
                                                                                close(hout);
%                                                                             end
                out.modelli = app.modelli;
                closereq;
                trace4__superadmin(out);    
            elseif app.auth__ == 2
%                                                                             try %#ok<*TRYNC>
%                                                                                 waitbar(1,hout);
                                                                                close(hout);
%                                                                             end
                set(app.license_error__message,'Visible','on');
                set(app.error__message,'Visible','off');
                set(app.final_error__message,'Visible','off');
                set(app.newP__message,'Visible','off');
%                 app.PASSWORDEditField.Value = '';
%                 app.password = '';
            elseif app.auth__ == -25
%                                                                             try %#ok<*TRYNC>
%                                                                                 waitbar(1,hout);
                                                                                close(hout);
%                                                                             end
                set(app.license_error__message,'Visible','off');
                set(app.error__message,'Visible','off');
                set(app.final_error__message,'Visible','on');
                set(app.newP__message,'Visible','off');
%                 app.PASSWORDEditField.Value = '';
%                 app.password = '';
            elseif app.auth__ == 3
%                                                                             try %#ok<*TRYNC>
%                                                                                 waitbar(1,hout);
                                                                                close(hout);
%                                                                             end
                set(app.license_error__message,'Visible','off');
                set(app.error__message,'Visible','off');
                set(app.newP__message,'Visible','on');
                set(app.final_error__message,'Visible','off');
                user=app.username;
                pass=app.password;
                app.HTML.Data = '';
                waitfor(trace4__pwdreset(user,pass,3));
                set(app.newP__message,'Visible','off');
%                 app.PASSWORDEditField.Value = '';
%                 app.password = '';
            elseif app.auth__ > 40
%                                                                             try %#ok<*TRYNC>
%                                                                                 waitbar(1,hout);
                                                                                close(hout);
%                                                                             end
                app.license = load(app.license__fullpath.local);
                n_error = (app.license.security_settings.max_error-(app.auth__-40));
                if n_error == 1
                    message_block = sprintf('Incorrect password, %d attempt remain.',n_error);
                elseif n_error == 0
                    message_block = sprintf('Incorrect password. Credentials suspended.\nPlease, contact users administrator');
                else
                    message_block = sprintf('Incorrect password, %d attempts remain.',n_error);
                end
                set(app.error__message,'Text',message_block,'Visible','on');
                set(app.license_error__message,'Visible','off');
                set(app.newP__message,'Visible','off');
                set(app.final_error__message,'Visible','off');
%                 app.PASSWORDEditField.Value = '';
%                 app.password = '';
            elseif app.auth__ == 5
%                                                                             try %#ok<*TRYNC>
%                                                                                 waitbar(1,hout);
                                                                                close(hout);
%                                                                             end
                set(app.error__message,'Text','Suspended credentials. Please, contact users administrator','Visible','on');
                set(app.license_error__message,'Visible','off');
                set(app.newP__message,'Visible','off');
                set(app.final_error__message,'Visible','off');
%                 app.PASSWORDEditField.Value = '';
%                 app.password = '';
            elseif app.auth__ == 6
%                                                                             try %#ok<*TRYNC>
%                                                                                 waitbar(1,hout);
                                                                                close(hout);
%                                                                             end
                set(app.error__message,'Text','Password expired. Please, renew it','Visible','on');
                app.HTML.Data = '';
                waitfor(trace4__pwdreset(app.username, app.password,0));
%                 app.PASSWORDEditField.Value = '';
%                 app.password = '';
            elseif app.auth__ == 7
%                                                                             try %#ok<*TRYNC>
%                                                                                 waitbar(1,hout);
                                                                                close(hout);
%                                                                             end
                set(app.error__message,'Text','License error 04012005. Please, contact the software provider','Visible','on');
%                 app.PASSWORDEditField.Value = '';
%                 app.password = '';
            elseif app.auth__ == 8
%                                                                             try %#ok<*TRYNC>
%                                                                                 waitbar(1,hout);
                                                                                close(hout);
%                                                                             end
                set(app.error__message,'Text','Credentials expired. Please, contact users administrator.','Visible','on');
%                 app.PASSWORDEditField.Value = '';
%                 app.password = '';
            elseif app.auth__ == 9
%                                                                             try %#ok<*TRYNC>
%                                                                                 waitbar(1,hout);
                                                                                close(hout);
%                                                                             end
                set(app.error__message,'Text','Suspended credentials. Please, contact software provider.','Visible','on');
                app.license = load(app.license__fullpath.local);
                license_priv = load(app.license__fullpath.private);
                index = find([app.license.security_settings.retrive{:,1}] == 2);
%                 app.PASSWORDEditField.Value = '';
%                 app.password = '';
                if isempty(index)
                    all_retrieve = [license_priv.security_settings.retrive, app.license.security_settings.retrive];
                    ind = [all_retrieve{:,3}] == 0;
                    temp = all_retrieve(ind,:);
                    index = randi(length(temp));
                    code_error = char(temp{index,2});
                    app.license.auth_use.pwd{1,1} = crypting(char(temp{index,1}), 1, 0);
                    app.license.auth_use.pwd{2,1} = 1;
                    app.license.auth_use.pwd{3,1} = 0;
                    Index_or = find(strcmp(code_error,all_retrieve)) - length(all_retrieve);
                    app.license.security_settings.retrive(Index_or,1) = {2};
                    security_settings = app.license.security_settings;
                    auth_use = app.license.auth_use;
                    save(app.license__fullpath.local,'security_settings','-append');
                    save(app.license__fullpath.local,'auth_use','-append');
                else
                    code_error = license_priv.security_settings.retrive{index,2};
                end
                
                text = char(sprintf('Administrator credentials suspended.\nPlease, contact DeepTrace Technologies at customercare@deeptracetech.com\nand comunicate the following code: %s',code_error));
                uialert(app.TRACE4BUSUIFigure, text, 'Error','Icon','error');
            else
%                                                                             try %#ok<*TRYNC>
%                                                                                 waitbar(1,hout);
                                                                                close(hout);
%                                                                             end
                set(app.error__message,'Text','Username or password incorrect','Visible','on');
                set(app.license_error__message,'Visible','off');
                set(app.final_error__message,'Visible','off');
                set(app.newP__message,'Visible','off');
%                 app.PASSWORDEditField.Value = '';
%                 app.password = '';
            end
            try 
                app.SIGNINButton.Enable = 'on';
%                 pause (0.1)
            catch
                
            end
        end

        % Callback function
        function CHANGEPASSWORDButtonPushed(app, event)
            try [app.auth__,~] = authentication(app.username, app.password, app.license__fullpath);
                if app.auth__ == 1
                    user=app.username;
                    pass=app.password;
                    closereq;
                    trace4__pwdreset(user,pass,2);
                elseif app.auth__ == 2
                    set(app.license_error__message,'Visible','on');
                    set(app.error__message,'Visible','off');
                else
                    set(app.error__message,'Visible','on');
                    set(app.license_error__message,'Visible','off');

                end
                
            catch
                set(app.license_error__message,'Visible','off');
                set(app.error__message,'Visible','on');
            end
        end

        % Value changed function: USERNAMEEditField
        function USERNAMEEditFieldValueChanged(app, event)
            app.username = app.USERNAMEEditField.Value;
        end

        % Value changed function: PASSWORDEditField
        function PASSWORDEditFieldValueChanged(app, event)
            app.password = app.PASSWORDEditField.Value;
        end

        % Key press function: TRACE4BUSUIFigure
        function TRACE4BUSUIFigureKeyPress(app, event)
            key = event.Key;
            if strcmp(key,'return')
                app.SIGNINButtonPushed();
            end
        end

        % Window key press function: TRACE4BUSUIFigure
        function TRACE4BUSUIFigureWindowKeyPress(app, event)
            key = event.Key;
            if strcmp(key,'return')
                app.SIGNINButtonPushed();
            end
        end

        % Value changing function: PASSWORDEditField
        function PASSWORDEditFieldValueChanging(app, event)
%             try
%                 last__event = event.Value(1,end);
%                 if ~strcmp(last__event,'backspace')
%                     app.password = [app.password last__event];
%                     app.PASSWORDEditField.Value = [app.PASSWORDEditField.Value '●'];
%                 elseif strcmp(last__event,'backspace') && size(app.password) > 1
%                     app.password = app.password(1:end-1);
%                     app.PASSWORDEditField.Value = app.PASSWORDEditField.Value(1:end-1);
%                 elseif strcmp(last__event,'backspace') && size(app.password) <= 1
%                     app.password = [];
%                     app.PASSWORDEditField.Value = [];
%                 else
% 
%                 end
%             end
            app.password = event.Value;
        end

        % Button pushed function: LostpasswordButton
        function LostpasswordButtonPushed(app, event)
            uialert(app.TRACE4BUSUIFigure, 'Please, contact your system administrator to recover or reset your password.', 'Lost password?','Icon','info');
        end

        % Value changed function: RemembermeCheckBox
        function RemembermeCheckBoxValueChanged(app, event)
            value = app.RemembermeCheckBox.Value;

            try
                
                [check__pref, preferences__fullpath] = check__preferences__local();
                if check__pref == 1
                    load(preferences__fullpath, 't4bus__preferences');
                    t4bus__preferences.remember__me = value;
                    save(preferences__fullpath, '-append', 't4bus__preferences');
                end
                
            catch ME
                
                uialert(app.TRACE4BUSUIFigure, 'Unexpected error.', 'Error','Icon','error')
                
            end
        end

        % Data changed function: HTML
        function HTMLDataChanged(app, event)
            app.password = app.HTML.Data;            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TRACE4BUSUIFigure = uifigure('Visible', 'off');
            app.TRACE4BUSUIFigure.Name = 'TRACE4BUS';
            app.TRACE4BUSUIFigure.WindowKeyPressFcn = createCallbackFcn(app, @TRACE4BUSUIFigureWindowKeyPress, true);
            app.TRACE4BUSUIFigure.KeyPressFcn = createCallbackFcn(app, @TRACE4BUSUIFigureKeyPress, true);
            app.logo = uiimage(app.TRACE4BUSUIFigure);
            app.logo.ImageSource = 'logo-trace4bus.png';
            app.subtitle = uilabel(app.TRACE4BUSUIFigure);
            app.subtitle.Enable = 'off';
            app.subtitle.Visible = 'off';
            app.subtitle.Text = {'AI-based tools'; 'for statistical analyses'};
            app.ed__img = uiimage(app.TRACE4BUSUIFigure);
            app.ed__img.ImageSource = 'sample-bus.png';
            app.label = uilabel(app.TRACE4BUSUIFigure);
            app.label.Visible = 'off';
            app.label.Text = '© 2020 DeepTrace Technologies S.R.L.';
            app.PASSWORDEditFieldLabel = uilabel(app.TRACE4BUSUIFigure);
            app.PASSWORDEditFieldLabel.Text = 'PASSWORD';
            app.PASSWORDEditField = uieditfield(app.TRACE4BUSUIFigure, 'text');
            app.PASSWORDEditField.ValueChangedFcn = createCallbackFcn(app, @PASSWORDEditFieldValueChanged, true);
            app.PASSWORDEditField.ValueChangingFcn = createCallbackFcn(app, @PASSWORDEditFieldValueChanging, true);
            app.PASSWORDEditField.Editable = 'off';
            app.PASSWORDEditField.Enable = 'off';
            app.PASSWORDEditField.Visible = 'off';
            app.LostpasswordButton = uibutton(app.TRACE4BUSUIFigure, 'push');
            app.LostpasswordButton.ButtonPushedFcn = createCallbackFcn(app, @LostpasswordButtonPushed, true);
            app.LostpasswordButton.Text = 'Lost password?';
            app.RemembermeCheckBox = uicheckbox(app.TRACE4BUSUIFigure);
            app.RemembermeCheckBox.ValueChangedFcn = createCallbackFcn(app, @RemembermeCheckBoxValueChanged, true);
            app.RemembermeCheckBox.Text = 'Remember me';
            app.ed__img_2 = uiimage(app.TRACE4BUSUIFigure);
            app.ed__img_2.ImageSource = 'logo-verb-horiz-nobkg.png';
            app.text_initialization = uilabel(app.TRACE4BUSUIFigure);
            app.text_initialization.Visible = 'off';
            app.text_initialization.Text = 'Initialising...';
            app.text_general = uilabel(app.TRACE4BUSUIFigure);
            app.text_general.Text = 'Predict the risk of Breast Cancer';
            app.error__message = uilabel(app.TRACE4BUSUIFigure);
            app.error__message.Visible = 'off';
            app.error__message.Text = 'Username or password incorrect';
            app.license_error__message = uilabel(app.TRACE4BUSUIFigure);
            app.license_error__message.Visible = 'off';
            app.license_error__message.Text = 'Licence error';
            app.final_error__message = uilabel(app.TRACE4BUSUIFigure);
            app.final_error__message.Visible = 'off';
            app.final_error__message.Text = 'DEMO LICENSE EXPIRED';
            app.newP__message = uilabel(app.TRACE4BUSUIFigure);
            app.newP__message.Visible = 'off';
            app.newP__message.Text = 'Temporary password inserted. Please change the password.';
            app.USERNAMEEditFieldLabel = uilabel(app.TRACE4BUSUIFigure);
            app.USERNAMEEditFieldLabel.Text = 'USERNAME';
            app.USERNAMEEditField = uieditfield(app.TRACE4BUSUIFigure, 'text');
            app.USERNAMEEditField.ValueChangedFcn = createCallbackFcn(app, @USERNAMEEditFieldValueChanged, true);
            app.HTML = uihtml(app.TRACE4BUSUIFigure);
            app.HTML.HTMLSource = 'passwordEdit.html';
            app.HTML.DataChangedFcn = createCallbackFcn(app, @HTMLDataChanged, true);
            app.SIGNINButton = uibutton(app.TRACE4BUSUIFigure, 'push');
            app.SIGNINButton.ButtonPushedFcn = createCallbackFcn(app, @SIGNINButtonPushed, true);
            app.SIGNINButton.Text = 'SIGN IN';
            app.subtitle_2 = uilabel(app.TRACE4BUSUIFigure);
            app.subtitle_2.Text = 'BREAST MASS ANALYZER';
            % Show the figure after all components are created
            app.TRACE4BUSUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = trace4BUS_login(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TRACE4BUSUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)trace4BUS_login__gui_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TRACE4BUSUIFigure)
        end
    end
end