classdef trace4__pwdreset < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TRACE4BUSUIFigure            matlab.ui.Figure
        Image                        matlab.ui.control.Image
        error__message               matlab.ui.control.Label
        NEWPASSWORDEditFieldLabel    matlab.ui.control.Label
        NEWPASSWORDEditField         matlab.ui.control.EditField
        CONFIRMNEWPASSWORDLabel      matlab.ui.control.Label
        CONFIRMNEWPASSWORDEditField  matlab.ui.control.EditField
        newpassword__mismatch        matlab.ui.control.Label
        label                        matlab.ui.control.Label
        HTML                         matlab.ui.control.HTML
        HTML_2                       matlab.ui.control.HTML
        ChangepasswordButton         matlab.ui.control.Button
    end

    
    properties (Access = private)
        username
        password
        origin
        license__fullpath
        check
        newpwd
        confirm__newpwd
        license
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, usern, pwd, orig)
            % Set main buttons / functions invisible

            gui__handles{1} = app.NEWPASSWORDEditField;
            gui__handles{2} = app.NEWPASSWORDEditFieldLabel;
            gui__handles{3} = app.CONFIRMNEWPASSWORDEditField;
            gui__handles{4} = app.CONFIRMNEWPASSWORDLabel;
            gui__handles{5} = app.HTML;
            gui__handles{6} = app.HTML_2;
            for i = 1:size(gui__handles,2)
                set(gui__handles{i},'Visible','off');
            end
            change__figicon(app.TRACE4BUSUIFigure);
            label_copy = string__copyright;
            set(app.label,'text',label_copy);
            app.username = usern;
            app.password = pwd;
            app.origin = orig;
            movegui(app.TRACE4BUSUIFigure,'center');
            [cl, app.license__fullpath] = check__license__local();
%             [cl, app.license__fullpath] = check__license();
            if cl == 1
                for i = 1:size(gui__handles,2)
                    set(gui__handles{i},'Visible','on');
                end
            end            
        end

        % Button pushed function: ChangepasswordButton
        function ChangepasswordButtonPushed(app, event)
            if ~isempty(app.newpwd) && ~isempty(app.confirm__newpwd)
                set(app.error__message,'Visible','off');
                set(app.newpassword__mismatch,'Visible','off');
                pause(0.1)
                app.license = load(app.license__fullpath);
%                 index = find(strcmp(app.license.auth_use.login(1,:),app.username));
                usr__ = crypting(app.username, 1, 0);
                index = find(cellfun(@(s) ~isempty(strfind(usr__,s)), app.license.auth_use.login(1,:)));
                pwd = crypting(app.license.auth_use.pwd{1,index}, 0, 0);
                app.check = reset__password(app.username, pwd, app.license__fullpath, app.newpwd, app.confirm__newpwd, app.origin);
                if app.check == 1
    %                 if app.origin == 2
    %                     closereq;
    %                     trace4AD_login;
    %                 else
                        closereq;
    %                 end
                elseif app.check == 0
                    set(app.error__message,'Text','Username or password incorrect','Visible','on');
                elseif app.check == -1                
                    set(app.newpassword__mismatch,'Visible','on');
                elseif app.check == -3
                    app.license = load(app.license__fullpath);
                    testo = sprintf('Password too short. At least %d letters/digits', app.license.security_settings.min_length);
                    set(app.error__message,'Text',testo,'Visible','on');
                elseif app.check == -4
                    app.license = load(app.license__fullpath);
    %                 testo = sprintf('Password unsecure. add at least 1 Up Case, 1 Low case and 1 digit', app.license.security_settings.min_length);
                    set(app.error__message,'Text','Insecure password, enter at least 1 uppercase letter, 1 lowercase letter and 1 digit','Visible','on');
                elseif app.check == 6
                    set(app.error__message,'Text','Password expired. Please renew it','Visible','on');
                elseif app.check == 7
                    set(app.error__message,'Text','License error 04012005. Please, contact the software provider','Visible','on');
                end
            end
        end

        % Value changed function: NEWPASSWORDEditField
        function NEWPASSWORDEditFieldValueChanged(app, event)
            app.newpwd = app.NEWPASSWORDEditField.Value;
        end

        % Value changed function: CONFIRMNEWPASSWORDEditField
        function CONFIRMNEWPASSWORDEditFieldValueChanged(app, event)
            app.confirm__newpwd = app.CONFIRMNEWPASSWORDEditField.Value;
        end

        % Key press function: TRACE4BUSUIFigure
        function TRACE4BUSUIFigureKeyPress(app, event)
            key = event.Key;
            if strcmp(key,'return')
                app.ChangepasswordButtonPushed();
            end
        end

        % Window key press function: TRACE4BUSUIFigure
        function TRACE4BUSUIFigureWindowKeyPress(app, event)
            key = event.Key;
            if strcmp(key,'return')
                app.ChangepasswordButtonPushed();
            end
        end

        % Data changed function: HTML
        function HTMLDataChanged(app, event)
            app.newpwd = app.HTML.Data;
            
        end

        % Data changed function: HTML_2
        function HTML_2DataChanged(app, event)
            app.confirm__newpwd = app.HTML_2.Data;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TRACE4BUSUIFigure = uifigure('Visible', 'off');
            app.TRACE4BUSUIFigure.Name = 'TRACE4BUS';
            app.TRACE4BUSUIFigure.WindowKeyPressFcn = createCallbackFcn(app, @TRACE4BUSUIFigureWindowKeyPress, true);
            app.TRACE4BUSUIFigure.KeyPressFcn = createCallbackFcn(app, @TRACE4BUSUIFigureKeyPress, true);
            app.Image = uiimage(app.TRACE4BUSUIFigure);
            app.Image.ImageSource = 'logo-trace4bus.png';
            app.error__message = uilabel(app.TRACE4BUSUIFigure);
            app.error__message.Visible = 'off';
            app.error__message.Text = 'Username or password incorrect';
            app.NEWPASSWORDEditFieldLabel = uilabel(app.TRACE4BUSUIFigure);
            app.NEWPASSWORDEditFieldLabel.Text = 'NEW PASSWORD';
            app.NEWPASSWORDEditField = uieditfield(app.TRACE4BUSUIFigure, 'text');
            app.NEWPASSWORDEditField.ValueChangedFcn = createCallbackFcn(app, @NEWPASSWORDEditFieldValueChanged, true);
            app.NEWPASSWORDEditField.Editable = 'off';
            app.NEWPASSWORDEditField.Enable = 'off';
            app.CONFIRMNEWPASSWORDLabel = uilabel(app.TRACE4BUSUIFigure);
            app.CONFIRMNEWPASSWORDLabel.Text = {'CONFIRM'; 'NEW PASSWORD'};
            app.CONFIRMNEWPASSWORDEditField = uieditfield(app.TRACE4BUSUIFigure, 'text');
            app.CONFIRMNEWPASSWORDEditField.ValueChangedFcn = createCallbackFcn(app, @CONFIRMNEWPASSWORDEditFieldValueChanged, true);
            app.CONFIRMNEWPASSWORDEditField.Editable = 'off';
            app.CONFIRMNEWPASSWORDEditField.Enable = 'off';
            app.newpassword__mismatch = uilabel(app.TRACE4BUSUIFigure);
            app.newpassword__mismatch.Visible = 'off';
            app.newpassword__mismatch.Text = 'New password mismatch';
            app.label = uilabel(app.TRACE4BUSUIFigure);
            app.label.Text = '© 2020 DeepTrace Technologies S.R.L.';
            app.HTML = uihtml(app.TRACE4BUSUIFigure);
            app.HTML.HTMLSource = 'passwordEdit.html';
            app.HTML.DataChangedFcn = createCallbackFcn(app, @HTMLDataChanged, true);
            app.HTML_2 = uihtml(app.TRACE4BUSUIFigure);
            app.HTML_2.HTMLSource = 'passwordEdit.html';
            app.HTML_2.DataChangedFcn = createCallbackFcn(app, @HTML_2DataChanged, true);
            app.ChangepasswordButton = uibutton(app.TRACE4BUSUIFigure, 'push');
            app.ChangepasswordButton.ButtonPushedFcn = createCallbackFcn(app, @ChangepasswordButtonPushed, true);
            app.ChangepasswordButton.Text = 'Change password';
            % Show the figure after all components are created
            app.TRACE4BUSUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = trace4__pwdreset(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TRACE4BUSUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TRACE4BUSUIFigure)
        end
    end
end