classdef trace4__superadmin < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TRACE4BUSUIFigure     matlab.ui.Figure
        DocumentationMenu     matlab.ui.container.Menu
        TechnicalsheetMenu    matlab.ui.container.Menu
        UsermanualMenu        matlab.ui.container.Menu
        Image                 matlab.ui.control.Image
        LogoutButton          matlab.ui.control.Button
        user_n                matlab.ui.control.Label
        user_n1               matlab.ui.control.Label
        user_n2               matlab.ui.control.Label
        user_n3               matlab.ui.control.Label
        user_n4               matlab.ui.control.Label
        user_n5               matlab.ui.control.Label
        user_n6               matlab.ui.control.Label
        usern_l               matlab.ui.control.Label
        usern_1               matlab.ui.control.Label
        usern_2               matlab.ui.control.Label
        usern_3               matlab.ui.control.Label
        usern_4               matlab.ui.control.Label
        usern_5               matlab.ui.control.Label
        usern_6               matlab.ui.control.Label
        passw_l               matlab.ui.control.Label
        passw_1               matlab.ui.control.Label
        passw_2               matlab.ui.control.Label
        passw_3               matlab.ui.control.Label
        passw_4               matlab.ui.control.Label
        passw_5               matlab.ui.control.Label
        passw_6               matlab.ui.control.Label
        Changep1_Button       matlab.ui.control.Button
        DeleteU1_Button       matlab.ui.control.Button
        label                 matlab.ui.control.Label
        Changep2_Button       matlab.ui.control.Button
        DeleteU2_Button       matlab.ui.control.Button
        Changep3_Button       matlab.ui.control.Button
        DeleteU3_Button       matlab.ui.control.Button
        Changep4_Button       matlab.ui.control.Button
        DeleteU4_Button       matlab.ui.control.Button
        Changep5_Button       matlab.ui.control.Button
        DeleteU5_Button       matlab.ui.control.Button
        Changep6_Button       matlab.ui.control.Button
        DeleteU6_Button       matlab.ui.control.Button
        AddU1_Button          matlab.ui.control.Button
        AddU2_Button          matlab.ui.control.Button
        AddU3_Button          matlab.ui.control.Button
        AddU4_Button          matlab.ui.control.Button
        AddU5_Button          matlab.ui.control.Button
        AddU6_Button          matlab.ui.control.Button
        text_complexity       matlab.ui.control.Label
        ButtonGroup           matlab.ui.container.ButtonGroup
        HighComplexityButton  matlab.ui.control.ToggleButton
        LowComplexityButton   matlab.ui.control.ToggleButton
        text_min_length       matlab.ui.control.Label
        min_length            matlab.ui.control.NumericEditField
        text_max_error        matlab.ui.control.Label
        max_error             matlab.ui.control.NumericEditField
        text_expiration       matlab.ui.control.Label
        expiration            matlab.ui.control.NumericEditField
        DownloadLOGButton     matlab.ui.control.Button
        text_expiration_acc   matlab.ui.control.Label
        expiration_acc        matlab.ui.control.NumericEditField
        ChangePASSWORDButton  matlab.ui.control.Button
        text_timeout          matlab.ui.control.Label
        timeout               matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        username
        password
        license__fullpath
        check
        newpwd
        confirm__newpwd
        newusr
        license
        time_logout
        lista
        indices
        in
    end
    
    methods (Access = private)
        function disable_all(app)
            set(app.LogoutButton,'Enable','off')
            set(app.AddU1_Button,'Enable','off')
            set(app.AddU2_Button,'Enable','off')
            set(app.AddU3_Button,'Enable','off')
            set(app.AddU4_Button,'Enable','off')
            set(app.AddU5_Button,'Enable','off')
%             set(app.AddU6_Button,'Enable','off')
            set(app.DeleteU1_Button,'Enable','off')
            set(app.DeleteU2_Button,'Enable','off')
            set(app.DeleteU3_Button,'Enable','off')
            set(app.DeleteU4_Button,'Enable','off')
            set(app.DeleteU5_Button,'Enable','off')
%             set(app.DeleteU6_Button,'Enable','off')
            set(app.Changep1_Button,'Enable','off')
            set(app.Changep2_Button,'Enable','off')
            set(app.Changep3_Button,'Enable','off')
            set(app.Changep4_Button,'Enable','off')
            set(app.Changep5_Button,'Enable','off')
%             set(app.Changep6_Button,'Enable','off')
            set(app.LogoutButton,'Enable','off')
        end
        
        function update_fields(app)
            app.license=load(app.license__fullpath);
            
            if app.license.security_settings.complexity == 1
                set(app.ButtonGroup,'SelectedObject',app.ButtonGroup.Buttons(1,1))
            else
                set(app.ButtonGroup,'SelectedObject',app.ButtonGroup.Buttons(1,2))
            end
            set(app.min_length,'Value',app.license.security_settings.min_length)
            set(app.max_error,'Value',app.license.security_settings.max_error)
            set(app.expiration,'Value',app.license.security_settings.expiration)
            set(app.timeout,'Value',app.license.security_settings.timeout)
            
            
            if app.expiration_acc.Value<app.expiration.Value
                app.expiration_acc.Value = app.expiration.Value;
                app.license.security_settings.expiration_acc = app.expiration_acc.Value;
                security_settings = app.license.security_settings;
                save(app.license__fullpath, 'security_settings','-append');
                set(app.expiration_acc,'Value',app.expiration_acc.Value)
            else
                set(app.expiration_acc,'Value',app.license.security_settings.expiration_acc)
            end
            if app.expiration.Value == Inf
                set(app.expiration_acc,'Enable','off')
            else
                set(app.expiration_acc,'Enable','on')
                set(app.expiration_acc,'Limits',[app.expiration.Value Inf])
            end
            
            app.lista = app.license.auth_use.login(3,:);
%             N_users = sum(strcmpi(app.lista(1,1),app.lista))+2;
            N_users = size(app.license.auth_use.login,2);
            [~,app.indices] = find(strcmpi(app.lista(1,1),app.lista));
%             N_users = size(app.license.auth_use.login,2);
            set(app.AddU1_Button,'Enable','off')
            set(app.AddU2_Button,'Enable','off')
            set(app.AddU3_Button,'Enable','off')
            set(app.AddU4_Button,'Enable','off')
            set(app.AddU5_Button,'Enable','off')
%             set(app.AddU6_Button,'Enable','off')
            
            set(app.DeleteU1_Button,'Enable','off')
            set(app.DeleteU2_Button,'Enable','off')
            set(app.DeleteU3_Button,'Enable','off')
            set(app.DeleteU4_Button,'Enable','off')
            set(app.DeleteU5_Button,'Enable','off')
%             set(app.DeleteU6_Button,'Enable','off')
            
            set(app.Changep1_Button,'Enable','off')
            set(app.Changep2_Button,'Enable','off')
            set(app.Changep3_Button,'Enable','off')
            set(app.Changep4_Button,'Enable','off')
            set(app.Changep5_Button,'Enable','off')
%             set(app.Changep6_Button,'Enable','off')
            
            set(app.usern_1,'text','--');
%             set(app.passw_1,'text','--');
            set(app.usern_2,'text','--');
%             set(app.passw_2,'text','--');
            set(app.usern_3,'text','--');
%             set(app.passw_3,'text','--');
            set(app.usern_4,'text','--');
%             set(app.passw_4,'text','--');
            set(app.usern_5,'text','--');
%             set(app.passw_5,'text','--');
%             set(app.usern_6,'text','--');
%             set(app.passw_6,'text','--');
            

            if N_users == 1
                set(app.AddU1_Button,'Enable','on')
            elseif N_users > 1
                set(app.AddU2_Button,'Enable','on')
                set(app.AddU1_Button,'Enable','off')
                set(app.Changep1_Button,'Enable','on')
                set(app.DeleteU1_Button,'Enable','on')
                set(app.usern_1,'text',app.license.auth_use.login{1,app.indices(2)});
                set(app.passw_1,'text',app.license.auth_use.pwd{1,app.indices(2)});
                if N_users > 2
                    set(app.AddU3_Button,'Enable','on')
                    set(app.AddU2_Button,'Enable','off')
                    set(app.Changep2_Button,'Enable','on')
                    set(app.DeleteU2_Button,'Enable','on')
                    set(app.usern_2,'text',app.license.auth_use.login{1,app.indices(3)});
                    set(app.passw_2,'text',app.license.auth_use.pwd{1,app.indices(3)});
                    if N_users > 3
                        set(app.AddU4_Button,'Enable','on')
                        set(app.AddU3_Button,'Enable','off')
                        set(app.Changep3_Button,'Enable','on')
                        set(app.DeleteU3_Button,'Enable','on')
                        set(app.usern_3,'text',app.license.auth_use.login{1,app.indices(4)});
                        set(app.passw_3,'text',app.license.auth_use.pwd{1,app.indices(4)});
                        if N_users > 4
                            set(app.AddU5_Button,'Enable','on')
                            set(app.AddU4_Button,'Enable','off')
                            set(app.Changep4_Button,'Enable','on')
                            set(app.DeleteU4_Button,'Enable','on')
                            set(app.usern_4,'text',app.license.auth_use.login{1,app.indices(5)});
                            set(app.passw_4,'text',app.license.auth_use.pwd{1,app.indices(5)});
                            if N_users > 5
%                                 set(app.AddU6_Button,'Enable','on')
                                set(app.AddU5_Button,'Enable','off')
                                set(app.Changep5_Button,'Enable','on')
                                set(app.DeleteU5_Button,'Enable','on')
                                set(app.usern_5,'text',app.license.auth_use.login{1,app.indices(6)});
                                set(app.passw_5,'text',app.license.auth_use.pwd{1,app.indices(6)});
%                                 if N_users > 6
%                                     set(app.AddU6_Button,'Enable','off')
%                                     set(app.Changep6_Button,'Enable','on')
%                                     set(app.DeleteU6_Button,'Enable','on')
%                                     set(app.usern_6,'text',app.license.auth_use.login{1,app.indices(7)});
%                                     set(app.passw_6,'text',app.license.auth_use.pwd{1,app.indices(7)});
%                                 end
                            end
                        end
                    end
                end
            end
        end
        function reset_timer(app)
            load(app.license__fullpath,'security_settings');
            try stop(app.time_logout);
            catch
                app.time_logout = timer;
                app.time_logout.StartDelay = 60*security_settings.timeout;
                app.time_logout.TimerFcn = @(myTimerObj, thisEvent)LogoutButtonPushed(app);
            end
            start(app.time_logout)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, in)
            change__figicon(app.TRACE4BUSUIFigure);
            label_copy = string__copyright;
            set(app.label,'text',label_copy);
%             [~, app.license__fullpath] = check__license();
            [~, app.license__fullpath] = check__license__local();
            update_fields(app)
            
            app.in = in;
            
            movegui(app.TRACE4BUSUIFigure,'center');
            reset_timer(app);
            
        end

        % Button pushed function: LogoutButton
        function LogoutButtonPushed(app, event)
            try delete(app.time_logout); end
            out.modelli = app.in.modelli;
            closereq;
            trace4BUS_login(out.modelli);
        end

        % Button pushed function: AddU1_Button
        function AddU1_ButtonPushed(app, event)
            disable_all(app)
            waitfor(trace4__adduser(app.license.auth_use.login{1,1}, app.license.auth_use.pwd{1,1}));
            set(app.LogoutButton,'Enable','on')
            update_fields(app)
            reset_timer(app);
        end

        % Button pushed function: Changep1_Button
        function Changep1_ButtonPushed(app, event)
            disable_all(app)
            waitfor(trace4__pwdreset(app.license.auth_use.login{1,app.indices(2)}, app.license.auth_use.pwd{1,app.indices(2)},1));
            set(app.LogoutButton,'Enable','on')
            update_fields(app)
            reset_timer(app);
        end

        % Button pushed function: DeleteU1_Button
        function DeleteU1_ButtonPushed(app, event)
            app.license.auth_use.login(:,app.indices(2)) = [];
            app.license.auth_use.pwd(:,app.indices(2)) = [];
            app.license.auth_use.tag_button(:,app.indices(2)+1) = [];
            auth = app.license.auth_use;
            save(app.license__fullpath,'auth','-append');
            update_fields(app)
            reset_timer(app);
        end

        % Button pushed function: AddU2_Button
        function AddU2_ButtonPushed(app, event)
            disable_all(app)
            waitfor(trace4__adduser(app.license.auth_use.login{1,1}, app.license.auth_use.pwd{1,1}));
            set(app.LogoutButton,'Enable','on')
            update_fields(app)
            reset_timer(app);
        end

        % Button pushed function: Changep2_Button
        function Changep2_ButtonPushed(app, event)
            disable_all(app)
            waitfor(trace4__pwdreset(app.license.auth_use.login{1,app.indices(3)}, app.license.auth_use.pwd{1,app.indices(3)},1));
            set(app.LogoutButton,'Enable','on')
            update_fields(app)
            reset_timer(app);
        end

        % Button pushed function: DeleteU2_Button
        function DeleteU2_ButtonPushed(app, event)
            app.license.auth_use.login(:,app.indices(3)) = [];
            app.license.auth_use.pwd(:,app.indices(3)) = [];
            app.license.auth_use.tag_button(:,app.indices(3)+1) = [];
            auth = app.license.auth_use;
            save(app.license__fullpath,'auth','-append');
            update_fields(app)
            reset_timer(app);
        end

        % Button pushed function: AddU3_Button
        function AddU3_ButtonPushed(app, event)
            disable_all(app)
            waitfor(trace4__adduser(app.license.auth_use.login{1,1}, app.license.auth_use.pwd{1,1}));
            set(app.LogoutButton,'Enable','on')
            update_fields(app)
            reset_timer(app);
        end

        % Button pushed function: Changep3_Button
        function Changep3_ButtonPushed(app, event)
            disable_all(app)
            waitfor(trace4__pwdreset(app.license.auth_use.login{1,app.indices(4)}, app.license.auth_use.pwd{1,app.indices(4)},1));
            set(app.LogoutButton,'Enable','on')
            update_fields(app)
            reset_timer(app);
        end

        % Button pushed function: DeleteU3_Button
        function DeleteU3_ButtonPushed(app, event)
            app.license.auth_use.login(:,app.indices(4)) = [];
            app.license.auth_use.pwd(:,app.indices(4)) = [];
            app.license.auth_use.tag_button(:,app.indices(4)+1) = [];
            auth = app.license.auth_use;
            save(app.license__fullpath,'auth','-append');
            update_fields(app)
            reset_timer(app);
        end

        % Button pushed function: AddU4_Button
        function AddU4_ButtonPushed(app, event)
            disable_all(app)
            waitfor(trace4__adduser(app.license.auth_use.login{1,1}, app.license.auth_use.pwd{1,1}));
            set(app.LogoutButton,'Enable','on')
            update_fields(app)
            reset_timer(app);
        end

        % Button pushed function: Changep4_Button
        function Changep4_ButtonPushed(app, event)
            disable_all(app)
            waitfor(trace4__pwdreset(app.license.auth_use.login{1,app.indices(5)}, app.license.auth_use.pwd{1,app.indices(5)},1));
            set(app.LogoutButton,'Enable','on')
            update_fields(app)
            reset_timer(app);
        end

        % Button pushed function: DeleteU4_Button
        function DeleteU4_ButtonPushed(app, event)
            app.license.auth_use.login(:,app.indices(5)) = [];
            app.license.auth_use.pwd(:,app.indices(5)) = [];
            app.license.auth_use.tag_button(:,app.indices(5)+1) = [];
            auth = app.license.auth_use;
            save(app.license__fullpath,'auth','-append');
            update_fields(app)
            reset_timer(app);
        end

        % Button pushed function: AddU5_Button
        function AddU5_ButtonPushed(app, event)
            disable_all(app)
            waitfor(trace4__adduser(app.license.auth_use.login{1,1}, app.license.auth_use.pwd{1,1}));
            set(app.LogoutButton,'Enable','on')
            update_fields(app)
            reset_timer(app);
        end

        % Button pushed function: Changep5_Button
        function Changep5_ButtonPushed(app, event)
            disable_all(app)
            waitfor(trace4__pwdreset(app.license.auth_use.login{1,app.indices(6)}, app.license.auth_use.pwd{1,app.indices(6)},1));
            set(app.LogoutButton,'Enable','on')
            update_fields(app)
            reset_timer(app);
        end

        % Button pushed function: DeleteU5_Button
        function DeleteU5_ButtonPushed(app, event)
            app.license.auth_use.login(:,app.indices(6)) = [];
            app.license.auth_use.pwd(:,app.indices(6)) = [];
            app.license.auth_use.tag_button(:,app.indices(6)+1) = [];
            auth = app.license.auth_use;
            save(app.license__fullpath,'auth','-append');
            update_fields(app)
            reset_timer(app);
        end

        % Button pushed function: AddU6_Button
        function AddU6_ButtonPushed(app, event)
            disable_all(app)
            waitfor(trace4__adduser(app.license.auth_use.login{1,1}, app.license.auth_use.pwd{1,1}));
            set(app.LogoutButton,'Enable','on')
            update_fields(app)
            reset_timer(app);
        end

        % Button pushed function: Changep6_Button
        function Changep6_ButtonPushed(app, event)
            disable_all(app)
            waitfor(trace4__pwdreset(app.license.auth_use.login{1,app.indices(7)}, app.license.auth_use.pwd{1,app.indices(7)},1));
            set(app.LogoutButton,'Enable','on')
            update_fields(app)
            reset_timer(app);
        end

        % Button pushed function: DeleteU6_Button
        function DeleteU6_ButtonPushed(app, event)
            app.license.auth_use.login(:,app.indices(7)) = [];
            app.license.auth_use.pwd(:,app.indices(7)) = [];
            app.license.auth_use.tag_button(:,app.indices(7)+1) = [];
            auth = app.license.auth_use;
            save(app.license__fullpath,'auth','-append');
            update_fields(app)
            reset_timer(app);
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text, 'Low Complexity')
                app.license.security_settings.complexity = 0;
                security_settings = app.license.security_settings;
                save(app.license__fullpath, 'security_settings','-append');
            else
                app.license.security_settings.complexity = 1;
                security_settings = app.license.security_settings;
                save(app.license__fullpath, 'security_settings','-append');
            end
            update_fields(app)
            reset_timer(app);
        end

        % Value changed function: min_length
        function min_lengthValueChanged(app, event)
            if ~isempty(app.min_length.Value) && app.min_length.Value>0
                app.license.security_settings.min_length = app.min_length.Value;
                security_settings = app.license.security_settings;
                save(app.license__fullpath, 'security_settings','-append');
            end
            update_fields(app)
            reset_timer(app);
        end

        % Value changed function: max_error
        function max_errorValueChanged(app, event)

            if ~isempty(app.max_error.Value) && app.max_error.Value>0
                app.license.security_settings.max_error = app.max_error.Value;
                security_settings = app.license.security_settings;
                save(app.license__fullpath, 'security_settings','-append');
            end
            update_fields(app)
            reset_timer(app);
        end

        % Value changed function: expiration
        function expirationValueChanged(app, event)
            if ~isempty(app.expiration.Value) && app.expiration.Value>0
                app.license.security_settings.expiration = app.expiration.Value;
                security_settings = app.license.security_settings;
                save(app.license__fullpath, 'security_settings','-append');
            end
            update_fields(app)
            reset_timer(app);
        end

        % Button pushed function: DownloadLOGButton
        function DownloadLOGButtonPushed(app, event)
            [file,path] = uiputfile('*.csv');
            if ~isempty(file) && ~isnumeric(file)
                file = fullfile(path,file);
                
                table0(1,1)={'date'};
                table0(1,2)={'user'};
                table0(1,3)={'event'};
                
                table2=[table0;app.license.log];

                T = cell2table(table2(2:end,:),'VariableNames',table2(1,:));
                writetable(T,file);
            end
            reset_timer(app);
        end

        % Button pushed function: ChangePASSWORDButton
        function ChangePASSWORDButtonPushed(app, event)
            disable_all(app)
            waitfor(trace4__pwdreset(app.license.auth_use.login{1,1}, app.license.auth_use.pwd{1,1},0));
            set(app.LogoutButton,'Enable','on')
            update_fields(app)
            reset_timer(app);
        end

        % Value changed function: expiration_acc
        function expiration_accValueChanged(app, event)
            if ~isempty(app.expiration_acc.Value) && app.expiration_acc.Value>0
                app.license.security_settings.expiration_acc = app.expiration_acc.Value;
                security_settings = app.license.security_settings;
                save(app.license__fullpath, 'security_settings','-append');
            end
            update_fields(app)
            reset_timer(app);
        end

        % Value changed function: timeout
        function timeoutValueChanged(app, event)

            if ~isempty(app.timeout.Value) && app.timeout.Value>0
                app.license.security_settings.timeout = app.timeout.Value;
                security_settings = app.license.security_settings;
                save(app.license__fullpath, 'security_settings','-append');
            end
            update_fields(app)
            reset_timer(app);
        end

        % Menu selected function: TechnicalsheetMenu
        function TechnicalsheetMenuSelected(app, event)
            if isdeployed
                
                p = mfilename('fullpath');
                endout = regexp(p, filesep, 'split');
            
                for i = 1:size(endout,2)-2
                    if i == 1
                        path_exe = endout{1,1};
                    else
                        path_exe = strcat(path_exe, '/' ,endout{1,i});
                    end  
                end
                
                path_pdf = fullfile(path_exe, 'docs', 'TRACE4BUS_SA_TechnicalSheet_ML.pdf');
                system(['start "TRACE4BUS | Technical Sheet" ','"', path_pdf,'"']);
                
            else
                
                system(['start "TRACE4BUS | Technical Sheet" ','"', 'docs/TRACE4BUS_SA_TechnicalSheet_ML.pdf','"']);
                
            end
        end

        % Menu selected function: UsermanualMenu
        function UsermanualMenuSelected(app, event)
            if isdeployed
                
                p = mfilename('fullpath');
                endout = regexp(p, filesep, 'split');
            
                for i = 1:size(endout,2)-2
                    if i == 1
                        path_exe = endout{1,1};
                    else
                        path_exe = strcat(path_exe, '/' ,endout{1,i});
                    end  
                end
                
                path_pdf = fullfile(path_exe, 'docs', 'TRACE4BUS_SA_UserManual_ML.pdf');
                system(['start "TRACE4BUS | User Manual" ','"', path_pdf,'"']);
                
            else
                
                system(['start "TRACE4BUS | User Manual" ','"', 'docs/TRACE4BUS_SA_UserManual_ML.pdf','"']);
                
            end
        end

        % Callback function
        function ResultstableMenuSelected(app, event)
%             if isdeployed
%                 
%                 p = mfilename('fullpath');
%                 endout = regexp(p, filesep, 'split');
%             
%                 for i = 1:size(endout,2)-2
%                     if i == 1
%                         path_exe = endout{1,1};
%                     else
%                         path_exe = strcat(path_exe, '/' ,endout{1,i});
%                     end  
%                 end
%                 saving__directory = uigetdir('','SELECT FOLDER WHERE DOCUMENT WILL BE SAVED');
%                 figure(app.TRACE4BUSUIFigure);
%                 path_pdf = fullfile(path_exe, 'docs', '3-tabella risultati esame neuropsicologico TRACE4AD.docx');
%                 if ~isempty(saving__directory) && ~isnumeric(saving__directory)
%                     copyfile(path_pdf,saving__directory,'f');
%                 end
% %                 system(['start "TRACE4AD | NPA table" ','"', path_pdf,'"']);
%                 
%             else
%                 saving__directory = uigetdir('','SELECT FOLDER WHERE DOCUMENT WILL BE SAVED');%uigetdir();
%                 figure(app.TRACE4BUSUIFigure);
%                 if ~isempty(saving__directory) && ~isnumeric(saving__directory)
%                     copyfile('docs/3-tabella risultati esame neuropsicologico TRACE4AD.docx',saving__directory, 'f');
%                 end
% %                 system(['start "TRACE4AD | NPA table" ','"', 'docs/3-tabella risultati esame neuropsicologico TRACE4AD.docx','"']);
%                 
%             end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TRACE4BUSUIFigure = uifigure('Visible', 'off');
            app.TRACE4BUSUIFigure.Name = 'TRACE4BUS';
            app.DocumentationMenu = uimenu(app.TRACE4BUSUIFigure);
            app.DocumentationMenu.Text = 'Documentation';
            app.TechnicalsheetMenu = uimenu(app.DocumentationMenu);
            app.TechnicalsheetMenu.MenuSelectedFcn = createCallbackFcn(app, @TechnicalsheetMenuSelected, true);
            app.TechnicalsheetMenu.Text = 'Technical sheet';
            app.UsermanualMenu = uimenu(app.DocumentationMenu);
            app.UsermanualMenu.MenuSelectedFcn = createCallbackFcn(app, @UsermanualMenuSelected, true);
            app.UsermanualMenu.Text = 'User manual';
            app.Image = uiimage(app.TRACE4BUSUIFigure);
            app.Image.ImageSource = 'logo-trace4bus.png';
            app.LogoutButton = uibutton(app.TRACE4BUSUIFigure, 'push');
            app.LogoutButton.ButtonPushedFcn = createCallbackFcn(app, @LogoutButtonPushed, true);
            app.LogoutButton.Text = 'Logout';
            app.user_n = uilabel(app.TRACE4BUSUIFigure);
            app.user_n.Text = '#';
            app.user_n1 = uilabel(app.TRACE4BUSUIFigure);
            app.user_n1.Text = '1';
            app.user_n2 = uilabel(app.TRACE4BUSUIFigure);
            app.user_n2.Text = '2';
            app.user_n3 = uilabel(app.TRACE4BUSUIFigure);
            app.user_n3.Text = '3';
            app.user_n4 = uilabel(app.TRACE4BUSUIFigure);
            app.user_n4.Text = '4';
            app.user_n5 = uilabel(app.TRACE4BUSUIFigure);
            app.user_n5.Text = '5';
            app.user_n6 = uilabel(app.TRACE4BUSUIFigure);
            app.user_n6.Visible = 'off';
            app.user_n6.Text = '6';
            app.usern_l = uilabel(app.TRACE4BUSUIFigure);
            app.usern_l.Text = 'USERNAME';
            app.usern_1 = uilabel(app.TRACE4BUSUIFigure);
            app.usern_1.Text = '--';
            app.usern_2 = uilabel(app.TRACE4BUSUIFigure);
            app.usern_2.Text = '--';
            app.usern_3 = uilabel(app.TRACE4BUSUIFigure);
            app.usern_3.Text = '--';
            app.usern_4 = uilabel(app.TRACE4BUSUIFigure);
            app.usern_4.Text = '--';
            app.usern_5 = uilabel(app.TRACE4BUSUIFigure);
            app.usern_5.Text = '--';
            app.usern_6 = uilabel(app.TRACE4BUSUIFigure);
            app.usern_6.Visible = 'off';
            app.usern_6.Text = '--';
            app.passw_l = uilabel(app.TRACE4BUSUIFigure);
            app.passw_l.Visible = 'off';
            app.passw_l.Text = 'PASSWORD';
            app.passw_1 = uilabel(app.TRACE4BUSUIFigure);
            app.passw_1.Visible = 'off';
            app.passw_1.Text = '--';
            app.passw_2 = uilabel(app.TRACE4BUSUIFigure);
            app.passw_2.Visible = 'off';
            app.passw_2.Text = '--';
            app.passw_3 = uilabel(app.TRACE4BUSUIFigure);
            app.passw_3.Visible = 'off';
            app.passw_3.Text = '--';
            app.passw_4 = uilabel(app.TRACE4BUSUIFigure);
            app.passw_4.Visible = 'off';
            app.passw_4.Text = '--';
            app.passw_5 = uilabel(app.TRACE4BUSUIFigure);
            app.passw_5.Visible = 'off';
            app.passw_5.Text = '--';
            app.passw_6 = uilabel(app.TRACE4BUSUIFigure);
            app.passw_6.Visible = 'off';
            app.passw_6.Text = '--';
            app.Changep1_Button = uibutton(app.TRACE4BUSUIFigure, 'push');
            app.Changep1_Button.ButtonPushedFcn = createCallbackFcn(app, @Changep1_ButtonPushed, true);
            app.Changep1_Button.Enable = 'off';
            app.Changep1_Button.Text = 'Change password';
            app.DeleteU1_Button = uibutton(app.TRACE4BUSUIFigure, 'push');
            app.DeleteU1_Button.ButtonPushedFcn = createCallbackFcn(app, @DeleteU1_ButtonPushed, true);
            app.DeleteU1_Button.Enable = 'off';
            app.DeleteU1_Button.Text = 'Delete user';
            app.label = uilabel(app.TRACE4BUSUIFigure);
            app.label.Text = '© 2020 DeepTrace Technologies S.R.L.';
            app.Changep2_Button = uibutton(app.TRACE4BUSUIFigure, 'push');
            app.Changep2_Button.ButtonPushedFcn = createCallbackFcn(app, @Changep2_ButtonPushed, true);
            app.Changep2_Button.Enable = 'off';
            app.Changep2_Button.Text = 'Change password';
            app.DeleteU2_Button = uibutton(app.TRACE4BUSUIFigure, 'push');
            app.DeleteU2_Button.ButtonPushedFcn = createCallbackFcn(app, @DeleteU2_ButtonPushed, true);
            app.DeleteU2_Button.Enable = 'off';
            app.DeleteU2_Button.Text = 'Delete user';
            app.Changep3_Button = uibutton(app.TRACE4BUSUIFigure, 'push');
            app.Changep3_Button.ButtonPushedFcn = createCallbackFcn(app, @Changep3_ButtonPushed, true);
            app.Changep3_Button.Enable = 'off';
            app.Changep3_Button.Text = 'Change password';
            app.DeleteU3_Button = uibutton(app.TRACE4BUSUIFigure, 'push');
            app.DeleteU3_Button.ButtonPushedFcn = createCallbackFcn(app, @DeleteU3_ButtonPushed, true);
            app.DeleteU3_Button.Enable = 'off';
            app.DeleteU3_Button.Text = 'Delete user';
            app.Changep4_Button = uibutton(app.TRACE4BUSUIFigure, 'push');
            app.Changep4_Button.ButtonPushedFcn = createCallbackFcn(app, @Changep4_ButtonPushed, true);
            app.Changep4_Button.Enable = 'off';
            app.Changep4_Button.Text = 'Change password';
            app.DeleteU4_Button = uibutton(app.TRACE4BUSUIFigure, 'push');
            app.DeleteU4_Button.ButtonPushedFcn = createCallbackFcn(app, @DeleteU4_ButtonPushed, true);
            app.DeleteU4_Button.Enable = 'off';
            app.DeleteU4_Button.Text = 'Delete user';
            app.Changep5_Button = uibutton(app.TRACE4BUSUIFigure, 'push');
            app.Changep5_Button.ButtonPushedFcn = createCallbackFcn(app, @Changep5_ButtonPushed, true);
            app.Changep5_Button.Enable = 'off';
            app.Changep5_Button.Text = 'Change password';
            app.DeleteU5_Button = uibutton(app.TRACE4BUSUIFigure, 'push');
            app.DeleteU5_Button.ButtonPushedFcn = createCallbackFcn(app, @DeleteU5_ButtonPushed, true);
            app.DeleteU5_Button.Enable = 'off';
            app.DeleteU5_Button.Text = 'Delete user';
            app.Changep6_Button = uibutton(app.TRACE4BUSUIFigure, 'push');
            app.Changep6_Button.ButtonPushedFcn = createCallbackFcn(app, @Changep6_ButtonPushed, true);
            app.Changep6_Button.Enable = 'off';
            app.Changep6_Button.Visible = 'off';
            app.Changep6_Button.Text = 'Change password';
            app.DeleteU6_Button = uibutton(app.TRACE4BUSUIFigure, 'push');
            app.DeleteU6_Button.ButtonPushedFcn = createCallbackFcn(app, @DeleteU6_ButtonPushed, true);
            app.DeleteU6_Button.Enable = 'off';
            app.DeleteU6_Button.Visible = 'off';
            app.DeleteU6_Button.Text = 'Delete user';
            app.AddU1_Button = uibutton(app.TRACE4BUSUIFigure, 'push');
            app.AddU1_Button.ButtonPushedFcn = createCallbackFcn(app, @AddU1_ButtonPushed, true);
            app.AddU1_Button.Enable = 'off';
            app.AddU1_Button.Text = 'Add user';
            app.AddU2_Button = uibutton(app.TRACE4BUSUIFigure, 'push');
            app.AddU2_Button.ButtonPushedFcn = createCallbackFcn(app, @AddU2_ButtonPushed, true);
            app.AddU2_Button.Enable = 'off';
            app.AddU2_Button.Text = 'Add user';
            app.AddU3_Button = uibutton(app.TRACE4BUSUIFigure, 'push');
            app.AddU3_Button.ButtonPushedFcn = createCallbackFcn(app, @AddU3_ButtonPushed, true);
            app.AddU3_Button.Enable = 'off';
            app.AddU3_Button.Text = 'Add user';
            app.AddU4_Button = uibutton(app.TRACE4BUSUIFigure, 'push');
            app.AddU4_Button.ButtonPushedFcn = createCallbackFcn(app, @AddU4_ButtonPushed, true);
            app.AddU4_Button.Enable = 'off';
            app.AddU4_Button.Text = 'Add user';
            app.AddU5_Button = uibutton(app.TRACE4BUSUIFigure, 'push');
            app.AddU5_Button.ButtonPushedFcn = createCallbackFcn(app, @AddU5_ButtonPushed, true);
            app.AddU5_Button.Enable = 'off';
            app.AddU5_Button.Text = 'Add user';
            app.AddU6_Button = uibutton(app.TRACE4BUSUIFigure, 'push');
            app.AddU6_Button.ButtonPushedFcn = createCallbackFcn(app, @AddU6_ButtonPushed, true);
            app.AddU6_Button.Enable = 'off';
            app.AddU6_Button.Visible = 'off';
            app.AddU6_Button.Text = 'Add user';
            app.text_complexity = uilabel(app.TRACE4BUSUIFigure);
            app.text_complexity.Text = 'PASSWORD COMPLEXITY';
            app.ButtonGroup = uibuttongroup(app.TRACE4BUSUIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.HighComplexityButton = uitogglebutton(app.ButtonGroup);
            app.HighComplexityButton.Tooltip = {'Password will require at least one uppercase letter, one lowercase letter and one digit'};
            app.HighComplexityButton.Text = 'High Complexity';
            app.HighComplexityButton.Value = true;
            app.LowComplexityButton = uitogglebutton(app.ButtonGroup);
            app.LowComplexityButton.Tooltip = {'Password will not require specific characters/digits'};
            app.LowComplexityButton.Text = 'Low Complexity';
            app.text_min_length = uilabel(app.TRACE4BUSUIFigure);
            app.text_min_length.Text = 'PASSWORD MIN LENGTH';
            app.min_length = uieditfield(app.TRACE4BUSUIFigure, 'numeric');
            app.min_length.Limits = [3 Inf];
            app.min_length.ValueChangedFcn = createCallbackFcn(app, @min_lengthValueChanged, true);
            app.min_length.Value = 8;
            app.text_max_error = uilabel(app.TRACE4BUSUIFigure);
            app.text_max_error.Text = 'MAX LOGIN ERRORS';
            app.max_error = uieditfield(app.TRACE4BUSUIFigure, 'numeric');
            app.max_error.Limits = [1 Inf];
            app.max_error.ValueChangedFcn = createCallbackFcn(app, @max_errorValueChanged, true);
            app.max_error.Tooltip = {'Number of login attempts before the suspension of user credentials'};
            app.max_error.Value = 3;
            app.text_expiration = uilabel(app.TRACE4BUSUIFigure);
            app.text_expiration.Text = {'PASSWORD EXPIRATION'; '(MONTHS)'};
            app.expiration = uieditfield(app.TRACE4BUSUIFigure, 'numeric');
            app.expiration.Limits = [1 Inf];
            app.expiration.ValueChangedFcn = createCallbackFcn(app, @expirationValueChanged, true);
            app.expiration.Tooltip = {'Number of months before a password must be renewed'};
            app.expiration.Value = 6;
            app.DownloadLOGButton = uibutton(app.TRACE4BUSUIFigure, 'push');
            app.DownloadLOGButton.ButtonPushedFcn = createCallbackFcn(app, @DownloadLOGButtonPushed, true);
            app.DownloadLOGButton.Text = 'Download LOG';
            app.text_expiration_acc = uilabel(app.TRACE4BUSUIFigure);
            app.text_expiration_acc.Text = {'ACCOUNT EXPIRATION'; '(MONTHS)'};
            app.expiration_acc = uieditfield(app.TRACE4BUSUIFigure, 'numeric');
            app.expiration_acc.Limits = [1 Inf];
            app.expiration_acc.ValueChangedFcn = createCallbackFcn(app, @expiration_accValueChanged, true);
            app.expiration_acc.Tooltip = {'Number of months before credentials expire for inactivity'};
            app.expiration_acc.Value = 6;
            app.ChangePASSWORDButton = uibutton(app.TRACE4BUSUIFigure, 'push');
            app.ChangePASSWORDButton.ButtonPushedFcn = createCallbackFcn(app, @ChangePASSWORDButtonPushed, true);
            app.ChangePASSWORDButton.Text = 'Change PASSWORD';
            app.text_timeout = uilabel(app.TRACE4BUSUIFigure);
            app.text_timeout.Text = {'SESSION TIME-OUT'; '(MINUTES)'};
            app.timeout = uieditfield(app.TRACE4BUSUIFigure, 'numeric');
            app.timeout.Limits = [1 Inf];
            app.timeout.ValueChangedFcn = createCallbackFcn(app, @timeoutValueChanged, true);
            app.timeout.Tooltip = {'Number of login attempts before the suspension of user credentials'};
            app.timeout.Value = 10;
            % Show the figure after all components are created
            app.TRACE4BUSUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = trace4__superadmin(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TRACE4BUSUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TRACE4BUSUIFigure)
        end
    end
end