classdef trace4__superuser < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TRACE4BUSUIFigure           matlab.ui.Figure
        Image                       matlab.ui.control.Image
        error__message              matlab.ui.control.Label
        mex                         matlab.ui.control.Label
        LogoutButton                matlab.ui.control.Button
        DownloadstatisticsButton    matlab.ui.control.Button
        SubstitutestatisticsButton  matlab.ui.control.Button
        AdduserButton               matlab.ui.control.Button
        label                       matlab.ui.control.Label
    end

    
    properties (Access = private)
        username
        password
        license__fullpath
        check
        newpwd
        confirm__newpwd
        newusr
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            change__figicon(app.TRACE4BUSUIFigure);
            label_copy = string__copyright;
            set(app.label,'text',label_copy);
            set(app.mex,'Visible','off');
            movegui(app.TRACE4BUSUIFigure,'center');
            [~, app.license__fullpath] = check__license__local();
        end

        % Button pushed function: LogoutButton
        function LogoutButtonPushed(app, event)
            closereq;
            trace4BUS_login;
        end

        % Button pushed function: DownloadstatisticsButton
        function DownloadstatisticsButtonPushed(app, event)
            set(app.mex,'Visible','off');
            load(app.license__fullpath);
            saving__directory = uigetdir();
            date__ = datestr(now);
            formatOut = 'yyyy-mm-dd';
            save(fullfile(saving__directory,[datestr(now,formatOut) '__t4statistics.mat']), '-regexp', '^(?!(event|app|saving__directory|formatOut)$).');
            set(app.mex,'Visible','on','Text','File correctly downloaded');
            figure(app.TRACE4BUSUIFigure);
        end

        % Button pushed function: SubstitutestatisticsButton
        function SubstitutestatisticsButtonPushed(app, event)
            set(app.mex,'Visible','off');
            [external__statistics,path] = uigetfile();
            load(fullfile(path,external__statistics));            
            date__ = datestr(now);
            formatOut = 'yyyy-mm-dd';
            save(fullfile(app.license__fullpath),'-regexp', '^(?!(event|app|formatOut|external__statistics|path)$).', '-append');
            set(app.mex,'Visible','on','Text','File correctly updated');
            figure(app.TRACE4BUSUIFigure);            
        end

        % Button pushed function: AdduserButton
        function AdduserButtonPushed(app, event)
            license = load(app.license__fullpath);
            log = license.auth_use.login{1,1};
            psw = license.auth_use.pwd{1,1};
            closereq;
            trace4__adduser_supuser(log,psw);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TRACE4BUSUIFigure = uifigure('Visible', 'off');
            app.TRACE4BUSUIFigure.Name = 'TRACE4BUS';
            app.Image = uiimage(app.TRACE4BUSUIFigure);
            app.Image.ImageSource = 'logo-trace4bus.png';
            app.error__message = uilabel(app.TRACE4BUSUIFigure);
            app.error__message.Visible = 'off';
            app.error__message.Text = 'Username or password incorrect';
            app.mex = uilabel(app.TRACE4BUSUIFigure);
            app.mex.Visible = 'off';
            app.mex.Text = 'Download completed';
            app.LogoutButton = uibutton(app.TRACE4BUSUIFigure, 'push');
            app.LogoutButton.ButtonPushedFcn = createCallbackFcn(app, @LogoutButtonPushed, true);
            app.LogoutButton.Text = 'Logout';
            app.DownloadstatisticsButton = uibutton(app.TRACE4BUSUIFigure, 'push');
            app.DownloadstatisticsButton.ButtonPushedFcn = createCallbackFcn(app, @DownloadstatisticsButtonPushed, true);
            app.DownloadstatisticsButton.Tooltip = {'Download software-usage statistics from the software'};
            app.DownloadstatisticsButton.Text = {'Download'; 'statistics'};
            app.SubstitutestatisticsButton = uibutton(app.TRACE4BUSUIFigure, 'push');
            app.SubstitutestatisticsButton.ButtonPushedFcn = createCallbackFcn(app, @SubstitutestatisticsButtonPushed, true);
            app.SubstitutestatisticsButton.Tooltip = {'Substitute software-usage statistics using an external file'};
            app.SubstitutestatisticsButton.Text = {'Substitute'; 'statistics'};
            app.AdduserButton = uibutton(app.TRACE4BUSUIFigure, 'push');
            app.AdduserButton.ButtonPushedFcn = createCallbackFcn(app, @AdduserButtonPushed, true);
            app.AdduserButton.Text = 'Add user';
            app.label = uilabel(app.TRACE4BUSUIFigure);
            app.label.Text = '© 2020 DeepTrace Technologies S.R.L.';
            % Show the figure after all components are created
            app.TRACE4BUSUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = trace4__superuser

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TRACE4BUSUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TRACE4BUSUIFigure)
        end
    end
end