classdef trace4bus__multi_test1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                      matlab.ui.Figure
        DocumentationMenu            matlab.ui.container.Menu
        UserManualMenu               matlab.ui.container.Menu
        TechnicalSheetMenu           matlab.ui.container.Menu
        UltrasoundImageProtocolMenu  matlab.ui.container.Menu
        SegmentationProcedureMenu    matlab.ui.container.Menu
        PreferencesMenu              matlab.ui.container.Menu
        SetPathsMenu                 matlab.ui.container.Menu
        ReportsFolderMenu            matlab.ui.container.Menu
        Menu                         matlab.ui.container.Menu
        ChangePasswordMenu           matlab.ui.container.Menu
        LogoutMenu                   matlab.ui.container.Menu
        text__general                matlab.ui.control.Label
        classify                     matlab.ui.control.Button
        mi                           matlab.ui.control.StateButton
        logo                         matlab.ui.control.Image
        Lamp__mi                     matlab.ui.control.Lamp
        DeepTraceTechnologiesSRL2020Label  matlab.ui.control.Label
        PATIENT_ID                   matlab.ui.control.StateButton
        Lamp__PATIENTID              matlab.ui.control.Lamp
        SelectedMRIsListBox          matlab.ui.control.ListBox
        mi_delete                    matlab.ui.control.StateButton
        singlemultiple__sbjs         matlab.ui.control.Switch
        mi_multi                     matlab.ui.control.StateButton
        SelectedMRIsListBox_label    matlab.ui.control.Label
        indications                  matlab.ui.control.Button
        terms                        matlab.ui.control.Button
        regulatory                   matlab.ui.control.Button
        CHANGEPASSWORDButton         matlab.ui.control.Button
        LOGOUTButton                 matlab.ui.control.Button
        testnew1                     matlab.ui.control.Button
        UIpreview                    matlab.ui.control.UIAxes
        subtitle                     matlab.ui.control.Label
    end

    
    properties (Access = private)
        output
        state
        path
        alert__patientid
        switchselection
        username
        password
        check__save
%         check__model
        model__abspath
        working
        last_path
        clin_input
        dx_list
        preview
        preview_list
        malign__zero
        license__fullpath
    end
    
    properties (Access = public)
    end
    
    methods (Access = private)        
        function data__feedback(app)
            set(app.mi,'enable','on');
            set(app.singlemultiple__sbjs,'enable','on')
            set(app.Lamp__mi,'visible','on');
            app.Lamp__mi.Color = [0.84 0.02 0.02];
            
%             set(app.model_solid,'enable','on')
%             set(app.model_cystic,'enable','on')
%             set(app.model_mix,'enable','on')
%             set(app.Lamp__model,'visible','on');
%             app.Lamp__model.Color = [0.84 0.02 0.02];

            set(app.Lamp__PATIENTID,'visible','on');
            app.Lamp__PATIENTID.Color = [0.84 0.02 0.02];             
            
            mi__feedback(app);
            pid__feedback(app);
%             model__feedback(app);

%             if app.state.patient__id == 0
%                 set(app.PATIENT_ID,'enable','on'); 
%             end
           
            set(app.classify,'enable','off');
            
            if app.state.patient__id == 1 %&& app.state.model == 1
                if app.state.img > 0
                    set(app.classify,'enable','on');
                end
            end
        end                
        
        
        function mi__feedback(app)
            try
                if ~isempty(app.path.img) && ischar(app.path.img) %&& endsWith(app.path.img,'.nii') 
                    app.state.img = 1;
                    app.state.datatype.img = 1;
                    app.Lamp__mi.Color = [0.14 0.84 0.02];
                    double_check(app)
%                     set(app.PATIENT_ID,'enable','on');
                else
                    app.state.img = 0;
                    app.state.datatype.img = 0;
                    app.Lamp__mi.Color = [0.84 0.02 0.02];
                    double_check(app)
%                     set(app.PATIENT_ID,'enable','off');
                end           
            end
        end
        
        function model__feedback(app)
            try
                if ~isempty(app.output.dir.model)
                    app.state.model = 1;
                    app.Lamp__model.Color = [0.14 0.84 0.02];
                    double_check(app)
                else
                    app.state.model = 0;
                    app.Lamp__model.Color = [0.84 0.02 0.02];
                    double_check(app)
                end
            end
        end
        function double_check(app)
            if app.state.img == 1 % && app.state.model == 1
                set(app.PATIENT_ID,'enable','on');
            else
                set(app.PATIENT_ID,'enable','off');
            end
            
        end
        
        function pid__feedback(app)
            try
                if ~isempty(app.output.patientid__user) && app.alert__patientid == 0
                    app.state.patient__id = 1;
                    app.Lamp__PATIENTID.Color = [0.14 0.84 0.02];
                    app.ReportsFolderMenu.Enable = 'Off';
                else
                    app.state.patient__id = 0;
                    app.Lamp__PATIENTID.Color = [0.84 0.02 0.02];
                    app.ReportsFolderMenu.Enable = 'On';
                end
            end
        end
        
        
%         function keep_model(app)
%             if strcmp(app.output.model.lesion, 'solid')
%                 set(app.model_solid, 'Background', [0.65,0.65,0.65])
%                 set(app.model_cystic,'Background','w')
%                 set(app.model_mix,'Background','w')
%                 mod=dir(fullfile(app.model__abspath,'model\','*model_solid.mat'));
%                 app.output.dir.model = fullfile(mod(1).folder,mod(1).name);
%                 app.model__feedback();
%             elseif strcmp(app.output.model.lesion, 'cystic')
%                 set(app.model_solid, 'Background', 'w')
%                 set(app.model_cystic,'Background',[0.65,0.65,0.65])
%                 set(app.model_mix,'Background','w')
%                 mod=dir(fullfile(app.model__abspath,'model\','*model_cystic.mat'));
%                 app.output.dir.model = fullfile(mod(1).folder,mod(1).name);
%                 app.model__feedback();
%             elseif strcmp(app.output.model.lesion, 'mix')
%                 set(app.model_solid, 'Background', 'w')
%                 set(app.model_cystic,'Background','w')
%                 set(app.model_mix,'Background',[0.65,0.65,0.65])
%                 mod=dir(fullfile(app.model__abspath,'model\','*model_mix.mat'));
%                 app.output.dir.model = fullfile(mod(1).folder,mod(1).name);
%                 app.model__feedback();
%             end
%         end
        
        function hide_all(app)
            if app.working == 1
                set(app.text__general,'Text','Processing...')
                set(app.classify,'visible','off')
                set(app.mi,'visible','off')
                set(app.logo,'visible','off')
                set(app.Lamp__mi,'visible','off')
%                 set(app.DeepTraceTechnologiesSRL2020Label,'visible','off')
                set(app.PATIENT_ID,'visible','off')
                set(app.Lamp__PATIENTID,'visible','off')
                set(app.UIpreview,'visible','off')
%                 set(app.SelectedMRIsListBox,'visible','off')
%                 set(app.mi_delete,'visible','off')
%                 set(app.singlemultiple__sbjs,'visible','off')
%                 set(app.mi_multi,'visible','off')
%                 set(app.SelectedMRIsListBox_label,'visible','off')
%                 set(app.model_solid,'visible','off')
%                 set(app.model_cystic,'visible','off')
%                 set(app.model_mix,'visible','off')
%                 set(app.Lamp__model,'visible','off')
                set(app.testnew1,'visible','off')
            else
                set(app.text__general,'Text','Evaluate malignancy of Ovarian Lesions')
                set(app.classify,'visible','on')
                set(app.UIpreview,'visible','on')
                set(app.logo,'visible','on')
                set(app.Lamp__mi,'visible','on')
%                 set(app.DeepTraceTechnologiesSRL2020Label,'visible','on')
                set(app.PATIENT_ID,'visible','on')
                set(app.Lamp__PATIENTID,'visible','on')
%                 set(app.singlemultiple__sbjs,'visible','on')
%                 set(app.model_solid,'visible','on')
%                 set(app.model_cystic,'visible','on')
%                 set(app.model_mix,'visible','on')
%                 set(app.Lamp__model,'visible','on')
                
%                 if strcmp(app.singlemultiple__sbjs.Value,'SINGLE SUBJECT')
                    set(app.mi,'visible','on')
%                     set(app.mi_delete,'visible' ,'off')
%                 else
%                     set(app.SelectedMRIsListBox,'visible','on')
%                     set(app.mi_multi,'visible','on')
%                     set(app.SelectedMRIsListBox_label,'visible','on')
%                     set(app.mi_delete,'visible','on')
%                 end
            end
        end

        
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function trace4bus__multi_test1_OpeningFcn(app, in, state, path)
            
            % Change figure icon
            change__figicon(app.figure1);
            app.output.max_ID_length = 7;
            app.output.min_ID_length = 3;
            label_copy = string__copyright;
            set(app.DeepTraceTechnologiesSRL2020Label,'text',label_copy);
            app.output.pathology = in.pathology;
            if strcmp(in.pathology, 'bc')
                set(app.logo,'ImageSource','logo-trace4bus(c).png');
                set(app.figure1, 'Name', 'TRACE4BUS');    
            end
            app.output.buttons_on = in.buttons_on;
            
            app.output.lesion = 'breast';
            app.output.manual__segmentation = 0;
%             app.output.radiomicsmapping = in.radiomicsmapping;
            app.output.imtype = 'US';
            app.output.modality = 'US';
            app.output.modelli = in.modelli;
            
            movegui(app.figure1,'center');  

            app.username = in.username;
            app.password = in.password;
%             app.state = state;
%             app.path = path;
            app.path.img = [];
            app.state.img = 0;
            app.state.patient__id = 0;
            app.path.list_file = [];
            app.dx_list = [];
            app.preview_list = [];
            p = mfilename('fullpath');
            endout = regexp(p, filesep, 'split');
        
            for i = 1:size(endout,2)-1
                if i == 1
                    path_exe = endout{1,1};
                else
                    path_exe = strcat(path_exe, '/' ,endout{1,i});
                end  
            end
            
            app.model__abspath = path_exe;
            
            app.state.img = 0;
            app.state.datatype.img = 0;
            
            app.path = [];

            app.check__save = 0;

            app.data__feedback();
            
            app.working = 0;
            
            try app.output.model = in.model;
%                 app.path.model = in.model_path;
%                 app.keep_model()
                app.state.model = 1;
%                 app.check__model = 1;
            catch 
                app.output.model = [];
                app.state.model = 0;
%                 app.check__model = 0;
            end
            % uialert(app.figure1, 'The files (MRI scan and PDF containing the results of the neuropsychological tests) used for the analysis will be anonymized and their filename will be modified according to the Patient ID. Please, store any relevant information or be sure to have a copy.', 'Warning','Icon','warning')
            
        end

        % Button pushed function: classify
        function classify_Callback(app, event)
            [check__pref, app.output.preferences__fullpath] = check__preferences__local();
            % app.check__save = 0;
            % app.check__model = 0;
%             msg = 'Choose the segmentation method.';
%             title = 'Segmentation';
%             selection = uiconfirm(app.figure1,msg,title,...
%                        'Options',{'Manual segmentation','Copy segmentation from JPG','Add existing segmentation','Cancel'},...
%                        'DefaultOption',1,'CancelOption',4);
%                    
           selection = 'Manual segmentation';
           
            app.working = 1;
            if strcmp(selection,'Cancel')
            else
                app.hide_all;
                if check__pref == 1
                
                    load(app.output.preferences__fullpath, 't4bus__preferences');
                    
                    % Check the existence of the Reports folder
                    if exist(t4bus__preferences.reports__path, 'dir')

                            if strcmp(app.singlemultiple__sbjs.Value,'SINGLE SUBJECT')
                                
                                app.path.save = mkdir__ifnotexist([t4bus__preferences.reports__path '\' app.output.patientid__user '\']);                   
                                if app.alert__patientid == 0
                                    % IF IMG, MOVE AND RENAME MRI
                                    if app.state.datatype.img == 1
                                        [current__mipath, current__mifilename, current__ext] = fileparts(app.path.img);
                                        filtri_ext = {'.dcm','.ima','.nii','.img','.nrrd','.hdr','.jpg','.jpeg','.bmp','.png'};
                                        if sum(strcmpi(current__ext,filtri_ext))
                                        else
                                            current__mifilename = strcat(current__mifilename, current__ext);
                                            current__ext = [];
                                        end
                                        newimg = fullfile(app.path.save, strcat(app.output.patientid__user, current__ext));
                                        if ~strcmp(app.path.img, newimg)
                                            try
                                                copyfile(app.path.img, newimg, 'f');
                                                app.path.img = newimg;
                                                app.check__save = 1;
                                                if isfolder(newimg)
                                                    list_file = dir(app.path.img);
                                                    
                                                    app.path.img = fullfile(app.path.img,list_file(3).name);
                                                end
                                            catch
                                                uialert(app.figure1, 'Impossible to save the ultrasound image file. Please, ensure you have the required privileges or check the file is not being used in another process.', 'Error','Icon','error');
                                                app.alert__patientid = 1;
                                                app.working = 0;
                                                app.hide_all;
                                            end
                                        end
                                    end
    
                                    app.data__feedback() 
                                end
                            else%multisubjects
                                if app.alert__patientid == 0
                                    % IF IMG, MOVE AND RENAME MRI
                                    if app.state.datatype.img == 1
                                        for i = 1 : length(app.path.list_file)
                                            app.path.img = app.path.list_file{i};
                                            app.output.dx = app.dx_list{i};
                                            app.output.patientid__user = app.output.patientid__user_list{i};
                                            app.path.save = mkdir__ifnotexist([t4bus__preferences.reports__path '\' app.output.patientid__user '\']);
                                            app.path.list_save{i} = app.path.save;
                                            [current__mipath, current__mifilename, current__ext] = fileparts(app.path.img);
                                            filtri_ext = {'.dcm','.ima','.nii','.img','.nrrd','.hdr','.jpg','.jpeg','.bmp','.png'};
                                            if sum(strcmpi(current__ext,filtri_ext))
                                            else
                                                current__mifilename = strcat(current__mifilename, current__ext);
                                                current__ext = [];
                                            end
                                            app.path.mipaths{i} = current__mipath;
                                            app.path.mifilename{i} = current__mifilename;
                                            app.path.ext{i} = current__ext;
                                            newimg = fullfile(app.path.save, strcat(app.output.patientid__user, current__ext));
                                            if ~strcmp(app.path.img, newimg)
                                                try
                                                    copyfile(app.path.img, newimg, 'f');
                                                    app.path.img = newimg;
                                                    app.path.img_list{i} = app.path.img;
                                                    app.check__save = 1;
                                                catch
                                                    uialert(app.figure1, 'Impossible to save the ultrasound image file. Please, ensure you have the required privileges or check the file is not being used in another process.', 'Error','Icon','error');
                                                    app.alert__patientid = 1;
                                                    app.path.img_list{i} = [];
                                                    app.working = 0;
                                                    app.hide_all;
                                                end
                                            end
                                        end
                                        app.path.list_file = app.path.img_list;
                                    end
                                    app.data__feedback()
                                end
                            end
    
                    end
                    
                    % Check the existence of the Model
                    try
                        
                        if app.state.img == 1
                            
                            app.path.model = app.output.dir.model;%t4bus__preferences.mrimodel__path;
                            if ~isempty(app.path.model) && ischar(app.path.model)
                                app.state.t4bus = 1;
%                                 app.check__model = 1;
                            end
                        end
    
                    catch 
                        
                        % uialert(app.figure1, 'Unexpected error.', 'Error','Icon','error')
                            
                    end
                    
                    if app.check__save == 0
                        
                        uialert(app.figure1, 'Report folder not found! Please, relink the folder using the "Preferences > Set paths > Reports folder" menu.', 'Error','Icon','error')
                        app.working = 0;
                        app.hide_all;
%                     elseif app.check__model == 0
%                         
%                         if app.state.img == 1 
%                             uialert(app.figure1, 'Model not found! Please, relink the model using the "Preferences > Set paths > models" menu.', 'Error','Icon','error')
%                             app.working = 0;
%                             app.hide_all;
%                         end
                        
                    elseif app.check__save == 1 %&& app.check__model == 1
                        
%                         out.pathology = app.output.pathology;
%                         out.buttons_on = app.output.buttons_on;
%                         out.pid = app.output.patientid__user;
%                         out.path = app.path;
%                         out.username = app.username;
%                         out.password = app.password;
%                         out.model = app.output.model;
                        
%                         state__ = app.state;
%                         path__ = app.path;
                        
                        if strcmp(app.singlemultiple__sbjs.Value,'SINGLE SUBJECT')
                            app.figure1.Visible = "off";
%                             n_subj = 1;
%                             tot_subj = 1;
                            if app.malign__zero == 0
                                if strcmp(selection,'Manual segmentation')
    
                                    app.output.manual__segmentation = 1;
                                    app.output.radiomicsmapping = 0;%%%%%%%% 
                                    drawnow();
                                    app.output.draw_jpg = 0;
                                    
                                    %read input
                                    input.file = app.path.img;
                                    input.path = app.path.save;
                                    input.dimension = 2;
                                    input.output_req = 'volume';
                                    try input.dx = temp_dx;
                                    catch 
                                        input.dx = [];
                                    end
                                    data = identify_load_file(input);
                                    if isempty(data)
                                        disp('load error')
                                    end
                                    %SEGMENTATION
                                    
                                    out.mask = segmentation__2d(app.path.img, app.path.save,data);
                                    
                                    if isempty(out.mask)
                                        app.figure1.Visible = "on";
    %                                     app.figure1.Visible = "on";
                                        uialert(app.figure1, 'Segmentation procedure not correctly completed.', 'Error','Icon','error');
                                        text_log = 'Segmentation NOT completed';
                                        [~, app.license__fullpath.local] = check__license__local();
                                        update_log(app.username,text_log,app.license__fullpath.local)
                                        app.working = 0;
                                        app.hide_all;
    
                                    else                                        prog = 0.1;
                                                                                messageout = 'Processing...';
                                                                                hout = waitbar(prog,messageout);
                                        text_log = 'Segmentation completed';
                                        [~, app.license__fullpath.local] = check__license__local();
                                        update_log(app.username,text_log,app.license__fullpath.local)
                                        input.modello = app.output.modelli;
                                        input.dicm_fig = data.volume;
                                        input.temp.mask2 = out.mask;
                                        input.img__type = 'US';
                                        input.info = data.info;
                                        input.temp_data = trace4bus_feature_extract(input);
                                        input.malign__zero = app.malign__zero;
                                                
                                        try
                                            shape_feat = input.temp_data.shape_feat;
                                        catch
                                            shape_feat = [];
                                        end
                                        if isempty(input.temp_data)
                                                                                try %#ok<*TRYNC>
                                                                                    waitbar(1,hout);
                                                                                    close(hout);
                                                                                end
                                            text_log = 'Radiomic features extraction NOT completed';
                                            [~, app.license__fullpath.local] = check__license__local();
                                            update_log(app.username,text_log,app.license__fullpath.local)
                                            app.figure1.Visible = "on";
                                            uialert(app.figure1, 'Something went wrong during radiomic features extraction. Please, check input data.', 'Error','Icon','error');
                                            app.working = 0;
                                            app.hide_all;
    
                                        else                                
                                                                                try
                                                                                    prog = prog + 0.5;
    %                                                                                 messageout = sprintf('Processing CT %d of %d: segmentation',i,length(app.path.list_file));
                                                                                    waitbar(prog,hout,messageout);
                                                                                    drawnow
                                                                                catch
    %                                                                                 messageout = sprintf('Processing CT %d of %d: segmentation',i,length(app.path.list_file));
                                                                                    hout = waitbar(prog,messageout);
                                                                                    drawnow
                                                                                end
                                            text_log = 'Radiomic features extraction completed';
                                            [~, app.license__fullpath.local] = check__license__local();
                                            update_log(app.username,text_log,app.license__fullpath.local)
                                            app.output.class_results = trace4bus_classify(input);
                                            if isempty(app.output.class_results)
                                                                                try %#ok<*TRYNC>
                                                                                    waitbar(1,hout);
                                                                                    close(hout);
                                                                                end
                                                text_log = 'Classification NOT completed';
                                                [~, app.license__fullpath.local] = check__license__local();
                                                update_log(app.username,text_log,app.license__fullpath.local)
                                                app.figure1.Visible = "on";
                                                uialert(app.figure1, 'Something went wrong during classification. Please, check input data.', 'Error','Icon','error');
                                                app.working = 0;
                                                app.hide_all;
                                            else
                                                                                try
                                                                                    prog = prog + 0.9;
    %                                                                                 messageout = sprintf('Processing CT %d of %d: segmentation',i,length(app.path.list_file));
                                                                                    waitbar(prog,hout,messageout);
                                                                                    drawnow
                                                                                catch
    %                                                                                 messageout = sprintf('Processing CT %d of %d: segmentation',i,length(app.path.list_file));
                                                                                    hout = waitbar(prog,messageout);
                                                                                    drawnow
                                                                                end
                                                out = app.output;
                                                text_log = 'Classification completed';
                                                [~, app.license__fullpath.local] = check__license__local();
                                                update_log(app.username,text_log,app.license__fullpath.local)
                                                out.malign__zero = app.malign__zero;
                                                out.username = app.username;
                                                out.password = app.password;
                                                out.pathology = app.output.pathology;
                                                out.buttons_on = app.output.buttons_on;
                                                out.pid = app.output.patientid__user;
                                                out.path = app.path;
                                                out.clin_input = app.clin_input;
                                                out.shape_feat = shape_feat;
                                                                                try %#ok<*TRYNC>
                                                                                    waitbar(1,hout);
                                                                                    close(hout);
                                                                                end
                                                closereq();
                                                trace4bus__test2(out,[],[]);
                                            end
    %produce and save report
    
                                        end
    
                                        
                                    end
                                end
                            else %bi-rads 2
                                %read input
                                input.file = app.path.img;
                                input.path = app.path.save;
                                input.dimension = 2;
                                input.output_req = 'volume';
                                try input.dx = temp_dx;
                                catch 
                                    input.dx = [];
                                end
                                data = identify_load_file(input);
                                if isempty(data)
                                    disp('load error')
                                end
                                %nome e cartella dove salvare l'immagine
                                image_name = fullfile(mkdir__ifnotexist(fullfile(...
                                    app.path.save, 'volumes')), 'image_nomask.png');
                                imwrite(data.volume,image_name);
                                
                                app.output.class_results.b_class = 'b2';
                                out = app.output;
                                out.malign__zero = app.malign__zero;
                                out.username = app.username;
                                out.password = app.password;
                                out.pathology = app.output.pathology;
                                out.buttons_on = app.output.buttons_on;
                                out.pid = app.output.patientid__user;
                                out.path = app.path;
                                out.clin_input = app.clin_input;
                                out.shape_feat = [];
                                
                                closereq();
                                
                                trace4bus__test2(out,[],[]);
                                
                            end
                        end                                    
                        
                    end
                    
                else
                    app.figure1.Visible = "on";
                    uialert(app.figure1, 'Unable to find the Preferences file. Please, contact your system administrator.', 'Error','Icon','error')
                end
            end
        end

        % Value changed function: mi
        function mi_Callback(app, event)
            filters = {'*.dcm'};
            [path__file, path__dir] = uigetfile(filters);
            figure(app.figure1); % Bringing the window up
            app.output.dx = [];
            if ~isempty(path__file) && ischar(path__file)
                app.preview = quick_view(fullfile(path__dir,path__file));
                if ~isempty(app.preview)
                    load_ok=1;
                else
                    load_ok=0;
                    uialert(app.figure1, 'File format not recognised. Please, check your file format.', 'Warning','Icon','warning');
                end
                if load_ok==1
                    if isa(app.preview,'double') || isa(app.preview,'single')
                        imshow(app.preview, [], 'Parent', app.UIpreview)
                    else
                        imshow(app.preview, 'Parent', app.UIpreview)
                    end   
                    app.path.img = fullfile(path__dir,path__file);
                    msg = 'Does the Breast mass have 0% likelihood of malignancy?';
                    title = 'Likelihood of malignancy';
                    selection = uiconfirm(app.figure1,msg,title,...
                   'Options',{'Yes','No'},...
                   'DefaultOption',2,'CancelOption',2);

                    if strcmpi(selection,'Yes')
                        app.malign__zero = 1;
                        app.classify.Text = 'PRODUCE REPORT WITH BI-RADS 2 CATEGORY';
                    else
                        app.malign__zero = 0;
                        app.classify.Text = 'ANALYSE';
                    end
                end
            end
            if ~isempty(path__file) && ischar(path__file) && load_ok==1 && app.malign__zero == 0
                if strcmp(path__file,'DICOMDIR')
                    set(app.mi,'enable','off');
                    pause(0.01)
                    input.data_mod = 'us';
                    input.path = path__dir;
                    input.file = path__file;
                    op = dicomdir2folder(input);
                    error = op.errore;
                    set(app.mi,'enable','on');
                    pause(0.01)
                    if isempty(op.path)
                        if error == 1
                            uialert(app.figure1, 'More than one US series found. Please, load only one.', 'Error','Icon','error');
                            try app.preview = [];
                            catch
                            end
                            cla(app.UIpreview)
                        elseif error == 2
                            uialert(app.figure1, 'No US series found. Please, check data.', 'Error','Icon','error');
                            try app.preview = [];
                            catch
                            end
                            cla(app.UIpreview)
                        elseif error ==3
                            uialert(app.figure1, 'Something went wrong. Please, check files', 'Error','Icon','error');
                            try app.preview = [];
                            catch
                            end
                            cla(app.UIpreview)
                        end
                        app.path.img = [];
                        app.data__feedback()
                    else
                        app.path.img = op.path;
                        app.data__feedback()
                    end
                else
                    try imfinfo(fullfile(path__dir,path__file));
                        jj = 1;
                        while jj == 1
                            prompt = {'Enter pixel size  (mm):'};
                            dlgtitle = 'Input';
                            dims = [1 35];
                            definput = {''};
                            answer = inputdlg(prompt,dlgtitle,dims,definput);
                            if ~isempty(answer)
                                if ~isempty(answer{1})
                                    dx_str = answer{1};
                                    dx_str = strrep(dx_str,',','.');
                                    if ~isnan(str2double(dx_str))
                                        if str2double(dx_str)<=1 && str2double(dx_str)>0
                                            app.output.dx = str2double(dx_str);
                                            jj = 2;
                                        elseif str2double(dx_str)==0
                                            uiwait(msgbox(sprintf('Warning! pixel size must be greater than 0.\nPlease, try again'),'Warning','warn'));
                                        else
                                            uiwait(msgbox(sprintf('Warning! pixel size is too big.\nIt should be less or equal to 1mm.\nPlease, insert a lower value'),'Warning','warn'));
                                        end
                                    else
%                                         uialert(app.figure1,'Warning! pixel size not correctly entered. Please, try again','Warning','Icon','warning')
                                        uiwait(msgbox('Warning! pixel size not correctly entered. Please, try again','Warning','warn'));
                                    end
                                else
                                    uialert(app.figure1, 'Pixel size not provided. Ultrasound image cannot be analysed.', 'Warning','Icon','warning');
                                    app.output.dx = 'error';
                                    jj = 2;
                                end
                            else
                                uialert(app.figure1, 'Pixel size not provided. Ultrasound image cannot be analysed.', 'Warning','Icon','warning');
                                app.output.dx = 'error';
                                jj = 2;
                            end
                        end
                    catch %not image and not dicomdir
                        datas.path = path__dir;
                        datas.file = path__file;
                        try
                            dx = retrieve_pixelsize(datas);
                            if sum(size(dx))==2
                                app.output.dx = 'error';
                                uialert(app.figure1, 'The input file must be a 2D ultrasound image in a format accepted by the software.', 'Error','Icon','error');
                                app.path.img = [];
                            else
                                app.output.dx = dx(1);
                                try third_dx = dx(3);
                                catch
                                    third_dx = 1;
                                end
                                if dx(1) == 0.5 && dx(2) == 0.5 && third_dx == 0.5
                                    jj = 1;
                                    while jj == 1
                                        prompt = {'Warning, pixel size not found. Please, enter pixel size  (mm):'};
                                        dlgtitle = 'Input';
                                        dims = [1 35];
                                        definput = {''};
                                        answer = inputdlg(prompt,dlgtitle,dims,definput);
                                        if ~isempty(answer)
                                            if ~isempty(answer{1})
                                                
                                                dx_str = answer{1};
                                                dx_str = strrep(dx_str,',','.');
                                                if ~isnan(str2double(dx_str))
                                                    if str2double(dx_str)<=1 && str2double(dx_str)>0
                                                        app.output.dx = str2double(dx_str);
                                                        jj = 2;
                                                    elseif str2double(dx_str)==0
                                                        uiwait(msgbox(sprintf('Warning! pixel size must be greater than 0.\nPlease, try again'),'Warning','warn'));
                                                    else
                                                        uiwait(msgbox(sprintf('Warning! pixel size is too big.\nIt should be less or equal to 1mm.\nPlease, try again'),'Warning','warn'));
                                                    end
                                                else
    %                                                 uialert(app.figure1,'Warning! pixel size not correctly entered. Please, try again','Warning','Icon','warning')
                                                    uiwait(msgbox('Warning! pixel size not correctly entered. Please, try again','Warning','warn'));
                                                end
                                                
                                            else
                                                uialert(app.figure1, 'Pixel size not provided. Ultrasound image cannot be analysed.', 'Warning','Icon','warning');
                                                app.output.dx = 'error';
                                                jj = 2;
                                            end
                                        else
                                            uialert(app.figure1, 'Pixel size not provided. Ultrasound image cannot be analysed.', 'Warning','Icon','warning');
                                            app.output.dx = 'error';
                                            jj = 2;
                                        end
                                    end
                                end
                            end
                            
                            
                        catch
                            
                        end
                        
                    end
%                         uialert(app.figure1, 'Not valid file format. Please select only dicom/nifti/nrrd files.', 'Error','Icon','error');
%                         app.path.img = [];
%                         app.data__feedback()
                    if isnumeric(app.output.dx)
                        

                    else
                        try app.preview = [];
                        catch
                        end
                        cla(app.UIpreview)
                        app.path.img = [];
                        try if strcmp(app.output.dx,'a')
                                uialert(app.figure1, 'File format not recognised. Please, check your file format.', 'Warning','Icon','warning');
                            end
                        catch
                        end
                    end
                    app.data__feedback()
                end
            elseif ~isempty(path__file) && ischar(path__file) && load_ok==1 && app.malign__zero == 1 %BI-RADS 2
                
                if strcmp(path__file,'DICOMDIR')
                    set(app.mi,'enable','off');
                    pause(0.01)
                    input.data_mod = 'us';
                    input.path = path__dir;
                    input.file = path__file;
                    op = dicomdir2folder(input);
                    error = op.errore;
                    set(app.mi,'enable','on');
                    pause(0.01)
                    if isempty(op.path)
                        if error == 1
                            uialert(app.figure1, 'More than one US series found. Please, load only one.', 'Error','Icon','error');
                            try app.preview = [];
                            catch
                            end
                            cla(app.UIpreview)
                        elseif error == 2
                            uialert(app.figure1, 'No US series found. Please, check data.', 'Error','Icon','error');
                            try app.preview = [];
                            catch
                            end
                            cla(app.UIpreview)
                        elseif error ==3
                            uialert(app.figure1, 'Something went wrong. Please, check files', 'Error','Icon','error');
                            try app.preview = [];
                            catch
                            end
                            cla(app.UIpreview)
                        end
                        app.path.img = [];
                        app.data__feedback()
                    else
                        app.path.img = op.path;
                        app.data__feedback()
                    end
                else
                    try imfinfo(fullfile(path__dir,path__file));
%                         jj = 1;
%                         while jj == 1
%                             prompt = {'Enter pixel size  (mm):'};
%                             dlgtitle = 'Input';
%                             dims = [1 35];
%                             definput = {''};
%                             answer = inputdlg(prompt,dlgtitle,dims,definput);
%                             if ~isempty(answer)
%                                 if ~isempty(answer{1})
%                                     dx_str = answer{1};
%                                     dx_str = strrep(dx_str,',','.');
%                                     if ~isnan(str2double(dx_str))
%                                         if str2double(dx_str)<=1 && str2double(dx_str)>0
%                                             app.output.dx = str2double(dx_str);
%                                             jj = 2;
%                                         elseif str2double(dx_str)==0
%                                             uiwait(msgbox(sprintf('Warning! pixel size must be greater than 0.\nPlease, try again'),'Warning','warn'));
%                                         else
%                                             uiwait(msgbox(sprintf('Warning! pixel size is too big.\nIt should be less or equal to 1mm.\nPlease, insert a lower value'),'Warning','warn'));
%                                         end
%                                     else
% %                                         uialert(app.figure1,'Warning! pixel size not correctly entered. Please, try again','Warning','Icon','warning')
%                                         uiwait(msgbox('Warning! pixel size not correctly entered. Please, try again','Warning','warn'));
%                                     end
%                                 else
%                                     uialert(app.figure1, 'Pixel size not provided. Ultrasound image cannot be analysed.', 'Warning','Icon','warning');
%                                     app.output.dx = 'error';
%                                     jj = 2;
%                                 end
%                             else
%                                 uialert(app.figure1, 'Pixel size not provided. Ultrasound image cannot be analysed.', 'Warning','Icon','warning');
%                                 app.output.dx = 'error';
%                                 jj = 2;
%                             end
%                         end
                    catch %not image and not dicomdir
                        datas.path = path__dir;
                        datas.file = path__file;
                        try
                            dx = retrieve_pixelsize(datas);
                            if sum(size(dx))==2
                                app.output.dx = 'error';
                                uialert(app.figure1, 'The input file must be a 2D ultrasound image in a format accepted by the software.', 'Error','Icon','error');
                                app.path.img = [];
                            else
                                app.output.dx = dx(1);
%                                 try third_dx = dx(3);
%                                 catch
%                                     third_dx = 1;
%                                 end
%                                 if dx(1) == 0.5 && dx(2) == 0.5 && third_dx == 0.5
%                                     jj = 1;
%                                     while jj == 1
%                                         prompt = {'Warning, pixel size not found. Please, enter pixel size  (mm):'};
%                                         dlgtitle = 'Input';
%                                         dims = [1 35];
%                                         definput = {''};
%                                         answer = inputdlg(prompt,dlgtitle,dims,definput);
%                                         if ~isempty(answer)
%                                             if ~isempty(answer{1})
%                                                 
%                                                 dx_str = answer{1};
%                                                 dx_str = strrep(dx_str,',','.');
%                                                 if ~isnan(str2double(dx_str))
%                                                     if str2double(dx_str)<=1 && str2double(dx_str)>0
%                                                         app.output.dx = str2double(dx_str);
%                                                         jj = 2;
%                                                     elseif str2double(dx_str)==0
%                                                         uiwait(msgbox(sprintf('Warning! pixel size must be greater than 0.\nPlease, try again'),'Warning','warn'));
%                                                     else
%                                                         uiwait(msgbox(sprintf('Warning! pixel size is too big.\nIt should be less or equal to 1mm.\nPlease, try again'),'Warning','warn'));
%                                                     end
%                                                 else
%     %                                                 uialert(app.figure1,'Warning! pixel size not correctly entered. Please, try again','Warning','Icon','warning')
%                                                     uiwait(msgbox('Warning! pixel size not correctly entered. Please, try again','Warning','warn'));
%                                                 end
%                                                 
%                                             else
%                                                 uialert(app.figure1, 'Pixel size not provided. Ultrasound image cannot be analysed.', 'Warning','Icon','warning');
%                                                 app.output.dx = 'error';
%                                                 jj = 2;
%                                             end
%                                         else
%                                             uialert(app.figure1, 'Pixel size not provided. Ultrasound image cannot be analysed.', 'Warning','Icon','warning');
%                                             app.output.dx = 'error';
%                                             jj = 2;
%                                         end
%                                     end
%                                 end
                            end
                        catch
                            ...
                        end
                    end
%                         uialert(app.figure1, 'Not valid file format. Please select only dicom/nifti/nrrd files.', 'Error','Icon','error');
%                         app.path.img = [];
%                         app.data__feedback()
                    if isnumeric(app.output.dx)
                        ...
                    else
                        try app.preview = [];
                        catch
                        end
                        cla(app.UIpreview)
                        app.path.img = [];
                        try if strcmp(app.output.dx,'a')
                                uialert(app.figure1, 'File format not recognised. Please, check your file format.', 'Warning','Icon','warning');
                            end
                        catch
                        end
                    end
                    app.data__feedback()
                end
                
            end
        end

        % Value changed function: mi_multi
        function mi_multiValueChanged(app, event)
%             filters = {'*.nii; *.dcm; *.nrrd; *.jpg; *.jpeg; *.bmp; *.png'};
%             try [path__file, path__dir] = uigetfile(fullfile(app.last_path,filters),'select file');
%             catch
%                 [path__file, path__dir] = uigetfile(filters);
%             end
%             
%             figure(app.figure1); % Bringing the window up
%             app.output.dx = [];
%             
%             if ~isempty(path__file) && ischar(path__file)
%                 app.preview = quick_view(fullfile(path__dir,path__file));
%                 if ~isempty(app.preview)
%                     load_ok=1;
%                 else
%                     load_ok=0;
%                     uialert(app.figure1, 'File format not recognised. Please, check your file format.', 'Warning','Icon','warning');
%                 end
%                 if load_ok==1
%                     
%                     if isa(app.preview,'double') || isa(app.preview,'single')
%                         imshow(app.preview, [], 'Parent', app.UIpreview)
%                     else
%                         imshow(app.preview, 'Parent', app.UIpreview)
%                     end
%                     
%                     j = 0;
%                     empty_val = 0;
%                     last_val = char(app.SelectedMRIsListBox.Items(end));
%                     if strcmp(last_val,'none')
%                         i = 1;
%                     else
%                         i = length(app.SelectedMRIsListBox.Items) + 1;
%                     end
%                     while j == 0
%                         prompt = {'\fontsize{11}Presence of Acoustic Shadows:';...
%                             '\fontsize{11}Post-menopausal:';...
%                             '\fontsize{11}Serum CA-125 level (U/mL): [Not mandatory]'};
%                         name = 'Supplementary inputs';
%                         formats = struct('type', {}, 'style', {}, 'items', {}, ...
%                           'format', {}, 'limits', {}, 'size', {});
%                     
%                         formats(1,1).type   = 'list';
%                         formats(1,1).style  = 'popupmenu';
%                         formats(1,1).items  = {'YES','NO'};
%                     
%                         formats(2,1).type   = 'list';
%                         formats(2,1).style  = 'popupmenu';
%                         formats(2,1).items  = {'YES','NO'};
%                         
%                         formats(3,1).type = 'edit';
%                         formats(3,1).format = 'float';
%                         formats(3,1).limits = [0 Inf]; % non-negative decimal number
%                         formats(3,1).size = 50;
%                     
%                         defaultanswer = {1, 1, []};
%                         opts.Interpreter = 'tex';
%                         [answer,cancelled] = inputsdlg(prompt, name, formats, defaultanswer,opts);
%                         if cancelled==0
%                                 app.clin_input(i).AcShadow = answer{1};
%                                 app.clin_input(i).Menopause = answer{2};
%     %                             answer{3} = strrep(answer{3},',','.');
%                                 if ~isempty(answer{3})
%                                     app.clin_input(i).Ca125 = answer{3};
%                                     empty_val = 0;
%                                 else
%                                     empty_val = 1;
%                                 end
%                         else
%                             empty_val = 1;
%                         end
%                         if empty_val == 1
%                             uiwait(msgbox(sprintf('Warning! Supplementary inputs must be provided or confirmed.\nPlease, try again'),'Warning','warn'));
%                         else
%                             j = 1;
%                         end
%                     end
%                 end
%             end
%             
%             
%             if ~isempty(path__file) && ischar(path__file) && load_ok == 1
%                 app.last_path = path__dir;
%                 try imfinfo(fullfile(path__dir,path__file));
%                     jj = 1;
%                     while jj == 1
%                         prompt = {'Enter pixel size  (mm):'};
%                         dlgtitle = 'Input';
%                         dims = [1 35];
%                         definput = {''};
%                         answer = inputdlg(prompt,dlgtitle,dims,definput);
%                         if ~isempty(answer)
%                             if ~isempty(answer{1})
%                             
%                                 dx_str = answer{1};
%                                 dx_str = strrep(dx_str,',','.');
%                                 if ~isnan(str2double(dx_str))
%                                     if str2double(dx_str)<=1 && str2double(dx_str)>0
%                                         app.output.dx = str2double(dx_str);
%                                         jj = 2;
%                                     elseif str2double(dx_str)==0
%                                         uiwait(msgbox(sprintf('Warning! pixel size must be greater than 0.\nPlease, try again'),'Warning','warn'));
%                                     else
%                                         uiwait(msgbox(sprintf('Warning! pixel size is too big.\nIt should be less or equal to 1mm.\nPlease, try again'),'Warning','warn'));
%                                     end
%                                 else
% %                                     uialert(app.figure1,'Warning! pixel size not correctly entered. Please, try again','Warning','Icon','warning')
%                                     uiwait(msgbox('Warning! pixel size not correctly entered. Please, try again','Warning','warn'));
%                                 end
%                                 
%                             else
%                                 uialert(app.figure1, 'Pixel size not provided. Ultrasound image cannot be analysed.', 'Warning','Icon','warning');
%                                 app.output.dx = 'error';
%                                 jj = 2;
%                             end
%                         else
%                             uialert(app.figure1, 'Pixel size not provided. Ultrasound image cannot be analysed.', 'Warning','Icon','warning');
%                             app.output.dx = 'error';
%                             jj = 2;
%                         end
%                     end
% %                     uialert(app.figure1, 'Not valid file format. Please select only dicom/nifti/nrrd files.', 'Error','Icon','error');
%                 catch
%                     datas.path = path__dir;
%                     datas.file = path__file;
%                     try
%                         dx = retrieve_pixelsize(datas);
%                         if sum(size(dx))==2
%                             app.output.dx = 'error';
%                             uialert(app.figure1, 'The input file must be a 2D Ultrasound image in a format accepted by the software.', 'Error','Icon','error');
%                             app.path.img = [];
%                         else
%                             app.output.dx = dx(1);
%                             try third_dx = dx(3);
%                             catch
%                                 third_dx = 1;
%                             end
%                             if dx(1) == 0.5 && dx(2) == 0.5 && third_dx == 0.5
%                                 jj = 1;
%                                 while jj == 1
%                                     prompt = {'Warning, pixel size not found. Please, enter pixel size  (mm):'};
%                                     dlgtitle = 'Input';
%                                     dims = [1 35];
%                                     definput = {''};
%                                     answer = inputdlg(prompt,dlgtitle,dims,definput);
%                                     if ~isempty(answer)
%                                         if ~isempty(answer{1})
%                                             
%                                             dx_str = answer{1};
%                                             dx_str = strrep(dx_str,',','.');
%                                             if ~isnan(str2double(dx_str))
%                                                 if str2double(dx_str)<=1 && str2double(dx_str)>0
%                                                     app.output.dx = str2double(dx_str);
%                                                     jj = 2;
%                                                 elseif str2double(dx_str)==0
%                                                     uiwait(msgbox(sprintf('Warning! pixel size must be greater than 0.\nPlease, try again'),'Warning','warn'));
%                                                 else
%                                                     uiwait(msgbox(sprintf('Warning! pixel size is too big.\nIt should be less or equal to 1mm.\nPlease, try again'),'Warning','warn'));
%                                                 end
%                                             else
%     %                                             uialert(app.figure1,'Warning! pixel size not correctly entered. Please, try again','Warning','Icon','warning')
%                                                 uiwait(msgbox('Warning! pixel size not correctly entered. Please, try again','Warning','warn'));
%                                             end
%                                             
%                                         else
%                                             uialert(app.figure1, 'Pixel size not provided. Ultrasound image cannot be analysed.', 'Warning','Icon','warning');
%                                             app.output.dx = 'error';
%                                             jj = 2;
%                                         end
%                                     else
%                                         uialert(app.figure1, 'Pixel size not provided. Ultrasound image cannot be analysed.', 'Warning','Icon','warning');
%                                         app.output.dx = 'error';
%                                         jj = 2;
%                                     end
%                                 end
%                             end
%                         end
%                     catch
%                         
%                     end
%                 end
% %                 app.path.img = fullfile(path__dir,path__file);    
%                 if isnumeric(app.output.dx)
%                     app.path.img = fullfile(path__dir,path__file);
%                 else
%                     try app.preview = [];
%                     catch
%                     end
%                     cla(app.UIpreview)
%                     last_val = char(app.SelectedMRIsListBox.Items(end));
%                     if strcmp(last_val,'none')
%                         app.path.img = [];
%                     else
%                         app.path.img = app.path.list_file{end};
%                     end
%                     try if strcmp(app.output.dx,'a')
%                             uialert(app.figure1, 'File format not recognised. Please, check your file format.', 'Warning','Icon','warning');
%                         end
%                     catch
%                     end
%                 end
%                 app.data__feedback()
%                 
%                 if isnumeric(app.output.dx)
%                     k = strfind(path__dir,'\');
%                     if k(end) == length(path__dir)
%                         kk = k(end-1)+1;
%                     else
%                         kk = k(end)+1;
%                     end
%                     
%                     last_val = char(app.SelectedMRIsListBox.Items(end));
%                     if strcmp(last_val,'none')
%                         number_selected = 1;
%                     else
%                         number_selected = length(app.SelectedMRIsListBox.Items) + 1;
%                     end
%                     
%                     temp_file = sprintf('%d. %s',number_selected,fullfile(path__dir(kk:end),path__file));
%     
%                     app.path.img = fullfile(path__dir,path__file);
%                     app.data__feedback()
%                     set(app.SelectedMRIsListBox,'enable','on');
%                     last_val = char(app.SelectedMRIsListBox.Items(end));
%                     if strcmp(last_val,'none')
%                         app.SelectedMRIsListBox.Items = {temp_file};
%                         set(app.mi_delete,'enable','on');
%                     else
%                         app.SelectedMRIsListBox.Items = [app.SelectedMRIsListBox.Items temp_file];
%                     end
%                     app.path.list_file{end+1} = app.path.img;
%                     app.dx_list{end+1} = app.output.dx;
%                     app.preview_list{end+1} = app.preview;
%                     app.SelectedMRIsListBox.Value = app.SelectedMRIsListBox.Items{end};
%                     [cl, license__fullpath] = check__license();
%                     load(license__fullpath);
%                     if (length(app.path.list_file)>=(counter.demomax.bc-counter.bc.testmodel) && counter.demomax.bc > 0) || length(app.path.list_file)>=10
%                         set(app.mi_multi,'enable','off');
%                     end
%                 end
%                 
%             end
        end

        % Value changed function: mi_delete
        function mi_deleteValueChanged(app, event)
            last_val = char(app.SelectedMRIsListBox.Items(end));
            try app.preview = [];
            catch
            end
            cla(app.UIpreview)
            if strcmp(last_val,'none')
            else
                [~, index] = ismember(app.SelectedMRIsListBox.Value, app.SelectedMRIsListBox.Items);
                if length(app.SelectedMRIsListBox.Items) == 1
                    app.SelectedMRIsListBox.Items(index) = {'none'};
                    app.path.list_file = [];
                    app.dx_list = [];
                    app.preview_list = [];
                    app.clin_input = [];

                    app.state.img = 0;
                    app.state.datatype.img = 0;
                    app.Lamp__mi.Color = [0.84 0.02 0.02];
                    set(app.PATIENT_ID,'enable','off');
                else
                    k = strfind(app.SelectedMRIsListBox.Value,'.');
                    number_to_del = str2double(app.SelectedMRIsListBox.Value(1:k(1)-1));
                    app.SelectedMRIsListBox.Items(index) = [];
                    app.path.list_file(number_to_del) = [];
                    app.dx_list(number_to_del) = [];
                    app.preview_list(number_to_del) = [];
                    app.clin_input(number_to_del) = [];
                    for i = 1:length(app.SelectedMRIsListBox.Items)
                        if i >= number_to_del 
                            item = char(app.SelectedMRIsListBox.Items(i));
                            k = strfind(item,'.');
                            old_num = str2double(item(1:k(1)-1));
                            new_num = old_num-1;
                            temp_file = sprintf('%d%s',new_num,item(k(1):end));
                            app.SelectedMRIsListBox.Items(i) = {temp_file};
                            app.preview = app.preview_list{i};
                            if isa(app.preview,'double') || isa(app.preview,'single')
                                imshow(app.preview, [], 'Parent', app.UIpreview)
                            else
                                imshow(app.preview, 'Parent', app.UIpreview)
                            end
                        end
                    end
                end
                set(app.mi_multi,'enable','on');
            end


        end

        % Value changed function: PATIENT_ID
        function PATIENT_IDValueChanged(app, event)
            app.alert__patientid = 1;
            [check__pref, app.output.preferences__fullpath] = check__preferences__local();
            set(app.PreferencesMenu,'enable' ,'off');
            if check__pref == 1
                load(app.output.preferences__fullpath, 't4bus__preferences');
                
                % while alert__patientid ~= 0
%                 prompt = {'Enter PATIENT ID:'};
%                 dlgtitle = 'PATIENT ID';
%                 dims = [1 50];
%                 definput = {'e.g. 001'};
%                 app.output.patientid__user = inputdlg(prompt, dlgtitle, dims, definput);
                if exist(t4bus__preferences.reports__path, 'dir')
                    inputpid.report_path = t4bus__preferences.reports__path;
                    if strcmp(app.singlemultiple__sbjs.Value,'SINGLE SUBJECT')
                        
                        inputpid.pathology = app.output.pathology;
                        inputpid.buttons_on = app.output.buttons_on;
    %                     if (app.state.datatype.img > app.state.datatype.nps) && app.state.img > 0
                            inputpid.mod = 'USI';
    %                     elseif (app.state.datatype.img < app.state.datatype.nps) && app.state.nps > 0
    %                         inputpid.mod = 'NPS';
    %                     elseif (app.state.datatype.img + app.state.datatype.nps > 1) && ...
    %                             (app.state.img + app.state.nps > 1)
    %                         inputpid.mod = 'MNP';
    %                     end                
%     %                     if app.state.datatype.img == 1
                            inputpid.save = fullfile(fileparts(app.path.img), 'tempid.mat');
    %                     elseif app.state.datatype.img == 0 && app.state.datatype.nps == 1
    %                         inputpid.save = fullfile(fileparts(app.path.nps), 'tempid.mat');
    %                     end
                        
%                         if app.state.patient__id == 1
%                             ind1_1 = strfind(app.output.patientid__user, '__P') + 3; % P001.01 BEGIN PATIENT ID
%                             kk = strfind(app.output.patientid__user, '.');
%                                 ind1_2 = kk(1) - 1; % P001.01 END PATIENT ID
%                                 ind2_1 = kk(1) + 1; % P001.01 BEGIN VISIT ID
%                                 ind2_2 = kk(1) + 2; % P001.01 END VISIT ID
%                             waitfor(patientid__gui(inputpid, app.output.patientid__user(ind1_1:ind1_2), app.output.patientid__user(ind2_1:ind2_2))); 
%                         else
%                             waitfor(patientid__gui(inputpid, 'e.g. 001', 'e.g. 01')); 
%                         end
                        
                        inputpid.max_id = app.output.max_ID_length;
                        inputpid.min_id = app.output.min_ID_length;
                        if app.state.patient__id == 1
                            ind1_1 = strfind(app.output.patientid__user, '__P') + 3; % P001.01 BEGIN PATIENT ID
                            kk = strfind(app.output.patientid__user, '.');
                            ind1_2 = kk(1) - 1; % P001.01 END PATIENT ID
                            ind2_1 = kk(1) + 1; % P001.01 BEGIN VISIT ID
                            ind2_2 = kk(1) + 2; % P001.01 END VISIT ID
                            waitfor(patientid__gui(inputpid, app.output.patientid__user(ind1_1:ind1_2), app.output.patientid__user(ind2_1:ind2_2))); 
                        else
%                             temp_id = "0";
%                             temp_id = char(temp_id);
%                             for k = 2 : inputpid.max_id
%                                 temp_id(k) = '0';
%                             end
                            temp_id = [];
                            waitfor(patientid__gui(inputpid, temp_id, [])); 
                        end
                        
                        % PID
                        try piderror = load(inputpid.save, 'ERROR');
                            piderror = piderror.ERROR;
                        catch
                            piderror = 1;
                        end
                        if piderror == 0
                            tempid = load(inputpid.save, 'PID');
                            app.output.patientid__user = tempid.PID;
%                             new__filename = [app.output.patientid__user];                    
                        end
                        try delete(inputpid.save);
                        catch                        
                        end
                        
                        % Check ID
                        try app.output.patientid__user;
                            if isempty(app.output.patientid__user) || piderror == 1
                                uialert(app.figure1, 'Invalid format of the Patient ID. Please, verify its correctness.', 'Error','Icon','error');
                                app.alert__patientid = 1;
                                app.state.patient__id = 0;
                                app.output.patientid__user = [];
                                app.data__feedback() 
                            else
                                % IF NPS, check Patient ID
                                % Alert: Patient IDs do not match -> proceed or modify
    %                             if app.state.datatype.nps == 1
    %                                 if strcmp(app.output.patientid__nps(1:end-4), app.output.patientid__user(1:end-4))
    %                                     app.alert__patientid = 0;
    %                                 else
    %                                     uialert(app.figure1, 'The Patient ID entered does not match with the one retrieved from the neuropsychological scoresheet. By deafult, the system will keep the Patient ID manually entered by the user. Please, modify it if necessary.', 'Alert','Icon','warning');
    %                                     app.alert__patientid = 0;
    %                                 end  
    %                             else
                                    app.alert__patientid = 0;
    %                             end
                            end                 
                        catch
                            uialert(app.figure1, 'Invalid format of the Patient ID. Please, verify its correctness.', 'Error','Icon','error');
                            app.alert__patientid = 1;
                            app.state.patient__id = 0;
                            app.output.patientid__user = [];
                            app.data__feedback()                     
                        end                
                        
                    % end
                    
                        if app.alert__patientid == 0
                            text_log = sprintf('file successfully loaded with Patient ID: %s', app.output.patientid__user);
                            [~, app.license__fullpath.local] = check__license__local();
                            update_log(app.username,text_log,app.license__fullpath.local)
                            app.data__feedback() 
                            
                        end
                            
                    else%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%MULTI
                        set(app.mi_multi,'enable' ,'off')
                        set(app.mi_delete,'enable' ,'off')
                        set(app.singlemultiple__sbjs,'enable' ,'off')
                        i = 1;
                        app.working = 1;
                        app.hide_all;
                        set(app.text__general,'Text','Insert IDs')
                        while i <= length(app.path.list_file) 
                            app.path.img = app.path.list_file{i};
                            inputpid.pathology = app.output.pathology;
                            inputpid.buttons_on = app.output.buttons_on;
                            inputpid.mod = 'USI';
    
                            inputpid.save = fullfile(fileparts(app.path.img), 'tempid.mat');
                            j = 0;
                            while j == 0
                                if app.state.patient__id == 1
                                    ind1_1 = strfind(app.output.patientid__user, '__P') + 3; % P001.01 BEGIN PATIENT ID
                                    ind1_2 = ind1_1 + 2; % P001.01 END PATIENT ID
                                    ind2_1 = ind1_1 + 4; % P001.01 BEGIN VISIT ID
                                    ind2_2 = ind1_1 + 5; % P001.01 END VISIT ID
                                    waitfor(patientid__multi_gui(inputpid, app.output.patientid__user(ind1_1:ind1_2), app.output.patientid__user(ind2_1:ind2_2), char(app.SelectedMRIsListBox.Items(i)))); 
                                else
                                    waitfor(patientid__multi_gui(inputpid, 'e.g. 001', 'e.g. 01', char(app.SelectedMRIsListBox.Items(i)))); 
                                end
                                try piderror = load(inputpid.save, 'ERROR');
                                    piderror = piderror.ERROR;                
                                    if piderror == 0
                                        tempid = load(inputpid.save, 'PID');
                                        try confronto_id = strcmp(tempid.PID,app.output.patientid__user_list);
                                                confronto_id(i)=0;
                                                check_multyID = sum(confronto_id);
                                        catch
                                            check_multyID = 0;
                                        end
                                        if check_multyID > 0
                                            uialert(app.figure1, 'ID allready used during this analysis section. Please, change it.', 'Error','Icon','error');
                                            
                                        else
                                            app.output.patientid__user = tempid.PID;
                                            app.output.patientid__user_list{i} = app.output.patientid__user;
        %                                     new__filename = [app.output.patientid__user];
                                            delete(inputpid.save);
                                            j = 1;
                                        end
                                                            
                                    end
                                    
                                catch
                                    app.output.patientid__user = [];
                                    j = 1;
                                end
                            end
                            
                            % PID
                            
                            
                            % Check ID
                            try app.output.patientid__user;
                                if isempty(app.output.patientid__user) || piderror == 1
    %                                 uialert(app.figure1, 'Invalid format of the Patient ID. Please, verify its correctness.', 'Error','Icon','error');
                                    message2 = strcat(char(app.SelectedMRIsListBox.Items(i)), ': invalid format of the Patient ID. Please, verify its correctness.');
                                    uialert(app.figure1, message2, 'Error','Icon','error');
                                    app.alert__patientid = 1;
                                    app.state.patient__id = 0;
                                    app.output.patientid__user = [];
                                    app.data__feedback() 
                                else
                                    app.alert__patientid = 0;
                                end                 
                            catch
    %                             uialert(app.figure1, 'Invalid format of the Patient ID. Please, verify its correctness.', 'Error','Icon','error');
                                message2 = strcat(char(app.SelectedMRIsListBox.Items(i)), ': invalid format of the Patient ID. Please, verify its correctness.');
                                uialert(app.figure1, message2, 'Error','Icon','error');
                                app.alert__patientid = 1;
                                app.state.patient__id = 0;
                                app.output.patientid__user = [];
                                app.data__feedback()
                            end
                            
                            i = i + 1;
                            app.working = 1;
                            app.hide_all;
                            set(app.text__general,'Text','Insert IDs')
                        end
                        app.working = 0;
                        app.hide_all;
                        if app.alert__patientid == 0
                            
                            app.data__feedback() 
                        
                        end
                    end
                else
                    app.figure1.Visible = "on";
                    uialert(app.figure1, 'Report folder not found! Please, relink the folder using the "Preferences > Set paths > Reports folder" menu.', 'Error','Icon','error')
                end
            else
                app.figure1.Visible = "on";
                uialert(app.figure1, 'Unable to find the Preferences file. Please, contact your system administrator.', 'Error','Icon','error')
                    
            end
            
            set(app.PreferencesMenu,'enable' ,'on');


        end

        % Value changed function: singlemultiple__sbjs
        function singlemultiple__sbjsValueChanged(app, event)
            app.switchselection = app.singlemultiple__sbjs.Value;
            try app.preview = [];
            catch
            end
            cla(app.UIpreview)
            if strcmp(app.switchselection,'SINGLE SUBJECT')
                set(app.mi,'Visible','on');
                set(app.mi,'Enable','on'); 
                set(app.mi_multi,'Visible','off');
                set(app.mi_multi,'Enable','off');
                set(app.mi_delete,'enable' ,'off')
                set(app.SelectedMRIsListBox,'visible','off');
                
                set(app.SelectedMRIsListBox_label,'visible','off');
                app.SelectedMRIsListBox.Items = {'none'};
                app.path.list_file = [];
                app.dx_list = [];
                app.preview_list = [];
                app.output.dx = [];
%                 set(app.Lamp__mi,'Position',[254,341,20,20]);
%                 set(app.mi,'Position',[288,322,374,51]);
                app.output.patientid__user = [];

                app.path.img = [];
                app.data__feedback()
                set(app.PATIENT_ID,'Text','Enter PATIENT ID');
                set(app.mi_delete,'visible' ,'off')
            else
                set(app.mi,'Visible','off'); 
                set(app.mi,'Enable','off'); 
                set(app.mi_multi,'Visible','on');
                set(app.mi_multi,'Enable','on');
                
                set(app.mi_delete,'enable' ,'off')
                set(app.SelectedMRIsListBox,'visible','on')
                set(app.SelectedMRIsListBox_label,'visible','on');
%                 set(app.Lamp__mi,'Position',[51,341,20,20]);
%                 set(app.mi,'Position',[85,322,374,51]);
                
                app.path.list_file = [];
                app.dx_list = [];
                app.preview_list = [];
                app.output.dx = [];
                app.path.img = [];
                app.output.patientid__user = [];
                app.data__feedback()
                set(app.PATIENT_ID,'Text','Confirm PATIENTs and enter IDs');
                set(app.PATIENT_ID,'enable','off');
                set(app.mi_delete,'visible' ,'on')
            end
        end

        % Button pushed function: indications
        function indicationsButtonPushed(app, event)
            uialert(app.figure1, sprintf(' TRACE4BUS is indicated for women with breast masses detected at ultrasound imaging and considered suspicious for breast cancer by physicians with experience in breast ultrasound. Physicians with experience in breast ultrasound can use TRACE4BUS as a support for: reporting ultrasound-detected suspicious breast masses; assigning the diagnostic category to such masses according to the BI-RADS classification; and, as a consequence, making an informed decision to send such masses to ultrasound-guided needle biopsy or to short interval follow-up. Physicians with experience in breast ultrasound can use TRACE4BUS as a support for diagnosing and managing patients with ultrasound-detected suspicious breast masses. The TRACE4BUS software provides an assessment of suspicious breast masses at the date of the ultrasound examination in which such masses are detected.\n\nIt should be noted that DeepTrace Technologies S.R.L. considers TRACE4BUS as a support to physicians with experience in breast ultrasound for reporting, diagnosis and decision-making of suspicious breast masses. However, these medical professionals retain the ultimate decision-making responsibility.'), 'Indications of use','Icon','info');
        end

        % Button pushed function: terms
        function termsButtonPushed(app, event)
            uialert(app.figure1, sprintf("1.	Acceptance of Agreement. You agree to the terms and conditions outlined in this Terms of Use Agreement ('Agreement') with respect to the software 'TRACE4BUS' created by DeepTrace Technologies S.R.L. (the 'software').\n2.	Intellectual Property/Limited License to Users. Any unauthorized use of the Materials, Content or Tools within this Software may violate laws and the Terms of Use. You agree not to copy, republish, frame, download, transmit, modify, rent, lease, loan, sell, assign, distribute, license, sublicense, reverse engineer, or create derivative works and tools based on this Software, its Materials, Content or Tools or their selection and arrangement, except as expressly authorized herein by DeepTrace Technologies S.R.L. You agree that you will not decompile, reverse engineer, or otherwise attempt to discover the source code of this software.\n3.	Limited Right to Use. The viewing, using, printing or downloading of any Material, Content, Graphic, Form or Document from this software grants you only a limited, nonexclusive license for use solely by the purpose defined under the Contract with by DeepTrace Technologies S.R.L, and not for republication, distribution, assignment, sublicense, sale, preparation of derivative works or other use unauthorized by DeepTrace Technologies S.R.L. No part of any content, form or document may be reproduced in any form or incorporated into any information retrieval system, electronic or mechanical, other than for the use defined under the Contract (but not for resale or redistribution). The rights granted to you constitute a license and not a transfer of title."), 'Terms of use','Icon','info');
        end

        % Button pushed function: regulatory
        function regulatoryButtonPushed(app, event)
            Regulatory();
%             uialert(app.figure1, "This software is not a medical device. It is not CE marked nor FDA cleared. Any use of this software and the associated information is for research and statistical analysis only.", 'Regulatory','Icon','info');
        end

        % Button pushed function: CHANGEPASSWORDButton
        function CHANGEPASSWORDButtonPushed(app, event)
            try
                trace4__pwdreset(app.username,app.password,2);
            catch
                uialert(app.figure1, 'Unexpected error.', 'Error','Icon','error')
            end
        end

        % Button pushed function: LOGOUTButton
        function LOGOUTButtonPushed(app, event)
            out = app.output.modelli;
            closereq;
            trace4BUS_login(out);
        end

        % Menu selected function: TechnicalSheetMenu
        function TechnicalSheetMenuSelected(app, event)
            if isdeployed
                
                p = mfilename('fullpath');
                endout = regexp(p, filesep, 'split');
            
                for i = 1:size(endout,2)-1
                    if i == 1
                        path_exe = endout{1,1};
                    else
                        path_exe = strcat(path_exe, '/' ,endout{1,i});
                    end  
                end
                
                path_pdf = fullfile(path_exe, 'docs', 'SWE-TRACE4BUSv2.0-D4_TechSheet.pdf');
                system(['start "TRACE4BUS | Technical Sheet" ','"', path_pdf,'"']);
                
            else
                
                system(['start "TRACE4BUS | Technical Sheet" ','"', 'docs/SWE-TRACE4BUSv2.0-D4_TechSheet.pdf','"']);
                
            end

        end

        % Menu selected function: UserManualMenu
        function UserManualMenuSelected(app, event)
            if isdeployed
                
                p = mfilename('fullpath');
                endout = regexp(p, filesep, 'split');
            
                for i = 1:size(endout,2)-1
                    if i == 1
                        path_exe = endout{1,1};
                    else
                        path_exe = strcat(path_exe, '/' ,endout{1,i});
                    end  
                end
                
                path_pdf = fullfile(path_exe, 'docs', 'SWE-TRACE4BUSv2.0-D2_UserManual.pdf');
                system(['start "TRACE4BUS | Technical Sheet" ','"', path_pdf,'"']);
                
            else
                
                system(['start "TRACE4BUS | User Manual" ','"', 'docs/SWE-TRACE4BUSv2.0-D2_UserManual.pdf','"']);
                
            end

        end

        % Menu selected function: ReportsFolderMenu
        function ReportsFolderMenuSelected(app, event)
            try
                
                [check__pref, app.output.preferences__fullpath] = check__preferences__local();
                if check__pref == 1
                    tempdir = uigetdir('*.mat');
                    if ~isempty(tempdir) && ischar(tempdir)
                        figure(app.figure1); % Bringing the window up
                        load(app.output.preferences__fullpath, 't4bus__preferences');
                        t4bus__preferences.reports__path = tempdir;
                        save(app.output.preferences__fullpath, '-append', 't4bus__preferences');
                        uialert(app.figure1, 'Reports path successfully updated.', 'Info','Icon','info')
                    end
                end


            catch 
                
                uialert(app.figure1, 'Unexpected error.', 'Error','Icon','error')
                
            end            
        end

        % Menu selected function: ChangePasswordMenu
        function ChangePasswordMenuSelected(app, event)
            try
                trace4__pwdreset(app.username,app.password,2);
            catch
                uialert(app.figure1, 'Unexpected error.', 'Error','Icon','error')
            end
        end

        % Menu selected function: LogoutMenu
        function LogoutMenuSelected(app, event)
            out = app.output.modelli;
            text_log = 'Logout completed';
            [~, app.license__fullpath.local] = check__license__local();
            update_log(app.username,text_log,app.license__fullpath.local)
            closereq;
            trace4BUS_login(out);
        end

        % Button pushed function: testnew1
        function testnew1ButtonPushed(app, event)
            app.figure1.Visible = "off";
%             try delete(app.path.nimg); end
%             
            try
                arg2 = [];
                arg2.pathology = 'bc';
                arg2.buttons_on = app.output.buttons_on;
                arg2.username = app.username;
                arg2.password = app.password;
                arg2.modelli = app.output.modelli;
%                 try rmfield(arg2,'model__path'); end
                trace4bus__multi_test1(arg2, [], []);
            catch
                out = app.output.modelli;
                trace4BUS_login(out);
            end
            closereq;
        end

        % Menu selected function: UltrasoundImageProtocolMenu
        function UltrasoundImageProtocolMenuSelected(app, event)
            if isdeployed
                
                p = mfilename('fullpath');
                endout = regexp(p, filesep, 'split');
            
                for i = 1:size(endout,2)-1
                    if i == 1
                        path_exe = endout{1,1};
                    else
                        path_exe = strcat(path_exe, '/' ,endout{1,i});
                    end  
                end
                
                path_pdf = fullfile(path_exe, 'docs', 'SWE-TRACE4BUSv2.0-D5_AcquisitionProtocol.pdf');
                system(['start "TRACE4BUS | USI Protocol" ','"', path_pdf,'"']);
                
            else
                
                system(['start "TRACE4BUS | USI Protocol" ','"', 'docs/SWE-TRACE4BUSv2.0-D5_AcquisitionProtocol.pdf','"']);
                
            end
        end

        % Value changed function: SelectedMRIsListBox
        function SelectedMRIsListBoxValueChanged(app, event)
%             value = app.SelectedMRIsListBox.Value;
            k = strfind(app.SelectedMRIsListBox.Value,'.');
            number_to_show = str2double(app.SelectedMRIsListBox.Value(1:k(1)-1));
            app.preview = app.preview_list{number_to_show};
            if isa(app.preview,'double') || isa(app.preview,'single')
                imshow(app.preview, [], 'Parent', app.UIpreview)
            else
                imshow(app.preview, 'Parent', app.UIpreview)
            end
            
        end

        % Menu selected function: SegmentationProcedureMenu
        function SegmentationProcedureMenuSelected(app, event)
            if isdeployed
                
                p = mfilename('fullpath');
                endout = regexp(p, filesep, 'split');
            
                for i = 1:size(endout,2)-1
                    if i == 1
                        path_exe = endout{1,1};
                    else
                        path_exe = strcat(path_exe, '/' ,endout{1,i});
                    end  
                end
                
                path_pdf = fullfile(path_exe, 'docs', 'SWE-TRACE4BUSv2.0-D6_SegmentationProcedure.pdf');
                system(['start "TRACE4BUS | Segmentation procedure" ','"', path_pdf,'"']);
                
            else
                
                system(['start "TRACE4BUS | Segmentation procedure" ','"', 'docs/SWE-TRACE4BUSv2.0-D6_SegmentationProcedure.pdf','"']);
                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'TRACE4BUS';
            app.DocumentationMenu = uimenu(app.figure1);
            app.DocumentationMenu.Text = '  Documentation  ';
            app.UserManualMenu = uimenu(app.DocumentationMenu);
            app.UserManualMenu.MenuSelectedFcn = createCallbackFcn(app, @UserManualMenuSelected, true);
            app.UserManualMenu.Text = 'User Manual';
            app.TechnicalSheetMenu = uimenu(app.DocumentationMenu);
            app.TechnicalSheetMenu.MenuSelectedFcn = createCallbackFcn(app, @TechnicalSheetMenuSelected, true);
            app.TechnicalSheetMenu.Text = 'Technical Sheet';
            app.UltrasoundImageProtocolMenu = uimenu(app.DocumentationMenu);
            app.UltrasoundImageProtocolMenu.MenuSelectedFcn = createCallbackFcn(app, @UltrasoundImageProtocolMenuSelected, true);
            app.UltrasoundImageProtocolMenu.Text = 'Ultrasound Image Protocol';
            app.SegmentationProcedureMenu = uimenu(app.DocumentationMenu);
            app.SegmentationProcedureMenu.MenuSelectedFcn = createCallbackFcn(app, @SegmentationProcedureMenuSelected, true);
            app.SegmentationProcedureMenu.Text = 'Segmentation Procedure';
            app.PreferencesMenu = uimenu(app.figure1);
            app.PreferencesMenu.Text = '  Preferences  ';
            app.SetPathsMenu = uimenu(app.PreferencesMenu);
            app.SetPathsMenu.Text = 'Set Paths';
            app.ReportsFolderMenu = uimenu(app.SetPathsMenu);
            app.ReportsFolderMenu.MenuSelectedFcn = createCallbackFcn(app, @ReportsFolderMenuSelected, true);
            app.ReportsFolderMenu.Text = 'Reports Folder';
            app.Menu = uimenu(app.figure1);
            app.Menu.Enable = 'off';
            app.Menu.Text = '                                                                                                                                                                                                                                             ';
            app.ChangePasswordMenu = uimenu(app.figure1);
            app.ChangePasswordMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangePasswordMenuSelected, true);
            app.ChangePasswordMenu.Text = '  Change Password  ';
            app.LogoutMenu = uimenu(app.figure1);
            app.LogoutMenu.MenuSelectedFcn = createCallbackFcn(app, @LogoutMenuSelected, true);
            app.LogoutMenu.Text = '  Logout  ';
            app.text__general = uilabel(app.figure1);
            app.text__general.Text = 'Predict the risk of Breast Cancer';
            app.classify = uibutton(app.figure1, 'push');
            app.classify.ButtonPushedFcn = createCallbackFcn(app, @classify_Callback, true);
            app.classify.Enable = 'off';
            app.classify.Text = 'ANALYSE';
            app.mi = uibutton(app.figure1, 'state');
            app.mi.ValueChangedFcn = createCallbackFcn(app, @mi_Callback, true);
            app.mi.Enable = 'off';
            app.mi.Text = 'Load ULTRASOUND IMAGE OF A BREAST MASS';
            app.logo = uiimage(app.figure1);
            app.logo.ImageSource = 'logo-trace4bus(c).png';
            app.Lamp__mi = uilamp(app.figure1);
            app.Lamp__mi.Visible = 'off';
            app.DeepTraceTechnologiesSRL2020Label = uilabel(app.figure1);
            app.DeepTraceTechnologiesSRL2020Label.Text = '© DeepTrace Technologies SRL | 2020';
            app.PATIENT_ID = uibutton(app.figure1, 'state');
            app.PATIENT_ID.ValueChangedFcn = createCallbackFcn(app, @PATIENT_IDValueChanged, true);
            app.PATIENT_ID.Enable = 'off';
            app.PATIENT_ID.Text = 'Enter PATIENT ID';
            app.Lamp__PATIENTID = uilamp(app.figure1);
            app.Lamp__PATIENTID.Visible = 'off';
            app.SelectedMRIsListBox = uilistbox(app.figure1);
            app.SelectedMRIsListBox.Items = {'none'};
            app.SelectedMRIsListBox.ValueChangedFcn = createCallbackFcn(app, @SelectedMRIsListBoxValueChanged, true);
            app.SelectedMRIsListBox.Enable = 'off';
            app.SelectedMRIsListBox.Visible = 'off';
            app.SelectedMRIsListBox.Value = 'none';
            app.mi_delete = uibutton(app.figure1, 'state');
            app.mi_delete.ValueChangedFcn = createCallbackFcn(app, @mi_deleteValueChanged, true);
            app.mi_delete.Enable = 'off';
            app.mi_delete.Visible = 'off';
            app.mi_delete.Text = 'Delete selected USI';
            app.singlemultiple__sbjs = uiswitch(app.figure1, 'slider');
            app.singlemultiple__sbjs.Items = {'SINGLE SUBJECT', 'MULTIPLE SUBJECTS'};
            app.singlemultiple__sbjs.ValueChangedFcn = createCallbackFcn(app, @singlemultiple__sbjsValueChanged, true);
            app.singlemultiple__sbjs.Enable = 'off';
            app.singlemultiple__sbjs.Visible = 'off';
            app.singlemultiple__sbjs.Value = 'SINGLE SUBJECT';
            app.mi_multi = uibutton(app.figure1, 'state');
            app.mi_multi.ValueChangedFcn = createCallbackFcn(app, @mi_multiValueChanged, true);
            app.mi_multi.Enable = 'off';
            app.mi_multi.Visible = 'off';
            app.mi_multi.Text = 'Load ULTRASOUND IMAGE OF A BREAST MASS';
            app.SelectedMRIsListBox_label = uilabel(app.figure1);
            app.SelectedMRIsListBox_label.Enable = 'off';
            app.SelectedMRIsListBox_label.Visible = 'off';
            app.SelectedMRIsListBox_label.Text = 'Selected USIs';
            app.indications = uibutton(app.figure1, 'push');
            app.indications.ButtonPushedFcn = createCallbackFcn(app, @indicationsButtonPushed, true);
            app.indications.Tooltip = {''};
            app.indications.Text = 'INDICATIONS OF USE';
            app.terms = uibutton(app.figure1, 'push');
            app.terms.ButtonPushedFcn = createCallbackFcn(app, @termsButtonPushed, true);
            app.terms.Tooltip = {''};
            app.terms.Text = 'TERMS OF USE';
            app.regulatory = uibutton(app.figure1, 'push');
            app.regulatory.ButtonPushedFcn = createCallbackFcn(app, @regulatoryButtonPushed, true);
            app.regulatory.Tooltip = {''};
            app.regulatory.Text = 'REGULATORY';
            app.CHANGEPASSWORDButton = uibutton(app.figure1, 'push');
            app.CHANGEPASSWORDButton.ButtonPushedFcn = createCallbackFcn(app, @CHANGEPASSWORDButtonPushed, true);
            app.CHANGEPASSWORDButton.Enable = 'off';
            app.CHANGEPASSWORDButton.Visible = 'off';
            app.CHANGEPASSWORDButton.Text = 'CHANGE PASSWORD';
            app.LOGOUTButton = uibutton(app.figure1, 'push');
            app.LOGOUTButton.ButtonPushedFcn = createCallbackFcn(app, @LOGOUTButtonPushed, true);
            app.LOGOUTButton.Enable = 'off';
            app.LOGOUTButton.Visible = 'off';
            app.LOGOUTButton.Text = 'LOGOUT';
            app.testnew1 = uibutton(app.figure1, 'push');
            app.testnew1.ButtonPushedFcn = createCallbackFcn(app, @testnew1ButtonPushed, true);
            app.testnew1.Enable = 'off';
            app.testnew1.Visible = 'off';
            app.testnew1.Text = 'NEW ANALYSIS';
            app.UIpreview = uiaxes(app.figure1);
            title(app.UIpreview, '')
            xlabel(app.UIpreview, '')
            ylabel(app.UIpreview, '')
            app.subtitle = uilabel(app.figure1);
            app.subtitle.Text = 'BREAST MASS ANALYZER';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = trace4bus__multi_test1(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)trace4bus__multi_test1_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end