classdef trace4bus__test2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                        matlab.ui.Figure
        DocumentationMenu              matlab.ui.container.Menu
        UserManualMenu                 matlab.ui.container.Menu
        TechnicalSheetMenu             matlab.ui.container.Menu
        UltrasoundImageProtocolMenu    matlab.ui.container.Menu
        SegmentationProcedureMenu      matlab.ui.container.Menu
        PreferencesMenu                matlab.ui.container.Menu
        SetPathsMenu                   matlab.ui.container.Menu
        ReportsFolderMenu              matlab.ui.container.Menu
        Menu                           matlab.ui.container.Menu
        ChangePasswordMenu             matlab.ui.container.Menu
        LogoutMenu                     matlab.ui.container.Menu
        logo                           matlab.ui.control.Image
        diagnosis                      matlab.ui.control.Label
        viewreport                     matlab.ui.control.Button
        testnew1                       matlab.ui.control.Button
        PROCESSINGLabel                matlab.ui.control.Label
        DeepTraceTechnologiesSRLLabel  matlab.ui.control.Label
        CHANGEPASSWORDButton           matlab.ui.control.Button
        LOGOUTButton                   matlab.ui.control.Button
        indications                    matlab.ui.control.Button
        terms                          matlab.ui.control.Button
        regulatory                     matlab.ui.control.Button
        viewreport_feat                matlab.ui.control.Button
        subtitle                       matlab.ui.control.Label
        shape_diam                     matlab.ui.control.Label
        shape_area                     matlab.ui.control.Label
        shape_contour                  matlab.ui.control.Label
        shape_orientation              matlab.ui.control.Label
        shape_diam_2                   matlab.ui.control.Label
        shape_area_2                   matlab.ui.control.Label
        shape_contour_2                matlab.ui.control.Label
        shape_orientation_2            matlab.ui.control.Label
        shape_title                    matlab.ui.control.Label
        diagnosis_2                    matlab.ui.control.Label
    end

    
    properties (Access = private)
        output;
        img;    
        slice__1;
        slice__2;
        slice__3;  
        res;
        pdf__path;
        username;
        password;
        license__fullpath
    end
    
    properties (Access = public)
        state;
        path;
        res__path;
        input_data;
    end
    
    methods (Access = private)
                
        function update__img(app,stage)
            try
                temp__img = load_nii(app.path.nimg);
                app.img = temp__img.img; clear temp__img
                try delete(app.path.nimg); end
            end
            
            if stage == 0
                app.slice__1 = round(size(app.img,3)/2);
                app.slice__2 = round(size(app.img,1)/2);
                app.slice__3 = round(size(app.img,2)/2);                
            end
            
            app.mri__axial.Visible = "on"; app.AxialviewLabel.Visible = "on";
            app.mri__sagittal.Visible = "on"; app.SagittalviewLabel.Visible = "on";
            app.mri__coronal.Visible = "on"; app.CoronalviewLabel.Visible = "on";
            
%             app.DisplaybiomarkersSwitchLabel.Visible = "on";
%             app.DisplaybiomarkersSwitch.Visible = "on";
            app.figurecaption.Visible = "on";
            
            % Updating slider limits
            app.Slider__axialmri.Limits = [1,size(app.img,3)]; app.Slider__axialmri.Value = app.slice__1;
            app.Slider__sagittalmri.Limits = [1,size(app.img,1)]; app.Slider__sagittalmri.Value = app.slice__2;
            app.Slider__coronalmri.Limits = [1,size(app.img,2)]; app.Slider__coronalmri.Value = app.slice__3;
            
            % Updating #slice shown
            app.AxialviewLabel.Text = ['Axial view (' num2str(app.slice__1) ')'];
            app.SagittalviewLabel.Text = ['Sagittal view (' num2str(app.slice__2) ')'];
            app.CoronalviewLabel.Text = ['Coronal view (' num2str(app.slice__3) ')'];
            
%             imshow(rot90(squeeze(app.img(:,:,app.slice__1))), 'parent', app.mri__axial);
%             imshow(rot90(squeeze(app.img(app.slice__2,:,:))), 'parent', app.mri__sagittal);   
%             imshow(rot90(squeeze(app.img(:,app.slice__3,:))), 'parent', app.mri__coronal);            
            
            app.Slider__axialmri.Visible = "on";
            app.Slider__sagittalmri.Visible = "on";
            app.Slider__coronalmri.Visible = "on";            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function trace4bus__test2_OpeningFcn(app, in, state, path)
            
            % Change figure icon
            change__figicon(app.figure1);
            
            label_copy = string__copyright;
            set(app.DeepTraceTechnologiesSRLLabel,'text',label_copy);
            app.state = state;
            app.path = path;
            app.output = in;
            app.output.pathology = in.pathology;
            app.output.buttons_on = in.buttons_on;
            app.username = in.username;
            app.password = in.password;
            [~, app.license__fullpath.local] = check__license__local();
            
            if strcmp(in.pathology, 'bc')
                set(app.logo,'ImageSource','logo-trace4bus(c).png');
                set(app.figure1, 'Name', 'TRACE4BUS'); 
            end 
                        
            movegui(app.figure1,'center');
            
            try
                %produce text
                if strcmpi(app.output.class_results.b_class, 'b2')
                    out_text = sprintf('The breast mass has been indicated to belong to BI-RADS 2 category (0%% likelihood of malignancy).');
                elseif strcmpi(app.output.class_results.b_class, 'b3')
                    out_text = sprintf('The breast mass has been predicted to belong to BI-RADS 3 category (between 0%% and 2%% likelihood of malignancy):\nshort-interval follow-up or continuous surveillance is suggested.');
                elseif strcmpi(app.output.class_results.b_class, 'b4')
                    out_text = sprintf('The breast mass has been predicted to belong to BI-RADS 4 category (between 2%% and 95%% likelihood of malignancy):\ntissue diagnosis is suggested.');
                else %strcmpi(app.output.class_results.b_class, 'b5')
                    out_text = sprintf('The breast mass has been predicted to belong to BI-RADS 5 category (more than 95%% likelihood of malignancy):\ntissue diagnosis is suggested.');
                end
                app.diagnosis.Text = out_text;
                app.diagnosis.Visible = "on";
                app.diagnosis_2.Visible = "on";
                if strcmpi(app.output.class_results.b_class, 'b2')
                    extra_text = [];
                else
                    try
                        max_length_txt = num2str(round(app.output.shape_feat(1,1),1));
                        k1 = strfind(max_length_txt,'.');
                        try max_length_txt = strcat(max_length_txt(1:k1(1)+1));
                        catch
                        end
                        area_txt = num2str(round(app.output.shape_feat(1,2),1));
                        k1 = strfind(area_txt,'.');
                        try area_txt = strcat(area_txt(1:k1(1)+1));
                        catch
                        end
                        if app.output.shape_feat(1,7) == 0
                            shape = 'round';
                        elseif app.output.shape_feat(1,7) == 1
                            shape = 'oval';
                        else
                            shape = 'irregular';
                        end
                        if app.output.shape_feat(1,6) == 0
                            orientation = sprintf('parallel (%d degree)',round(app.output.shape_feat(1,3)));
                        else
                            orientation = sprintf('not parallel (%d degree)',round(app.output.shape_feat(1,3)));
                        end
                        app.shape_title.Visible = 'on';
                        app.shape_diam.Visible = 'on';
                        space = ' ';
    %                     extra_text.diam = sprintf(['%s', blanks(3), '%s'], app.shape_diam.Text, max_length_txt);
                        extra_text.diam = [app.shape_diam.Text,space,max_length_txt];
                        app.shape_diam_2.Text = max_length_txt;
                        app.shape_diam_2.Visible = 'on';
                        
    %                     space = '                     ';
                        app.shape_area.Visible = 'on';
    %                     extra_text.area = sprintf(['%s', blanks(21), '%s'], app.shape_area.Text, area_txt);
                        extra_text.area = [app.shape_area.Text,space,area_txt];
                        app.shape_area_2.Text = area_txt;
                        app.shape_area_2.Visible = 'on';
                        
    %                     space = '                                  ';
                        app.shape_contour.Visible = 'on';
    %                     extra_text.shape = sprintf(['%s', blanks(34), '%s'], app.shape_contour.Text, shape);
                        extra_text.shape = [app.shape_contour.Text,space,shape];
                        app.shape_contour_2.Text = shape;
                        app.shape_contour_2.Visible = 'on';
                        
    %                     space = '                        ';
                        app.shape_orientation.Visible = 'on';
    %                     extra_text.orientation = sprintf(['%s', blanks(24), '%s'], app.shape_orientation.Text, orientation);
                        orientation = strrep(orientation," degree",char(176));
                        extra_text.orientation = [app.shape_orientation.Text,space,orientation];
                        extra_text.orientation = strcat(extra_text.orientation(1,1),extra_text.orientation(1,2),extra_text.orientation(1,3));
                        app.shape_orientation_2.Text = orientation;
                        app.shape_orientation_2.Visible = 'on';
                        
                    catch
                        extra_text = [];
                    end
                    pause(5)
                    msg = strcat(out_text(1:66),'. Do you agree with BI-RADS classification result?');
                    title = 'Classification agreement';
                    selection = uiconfirm(app.figure1,msg,title,...
                   'Options',{'Yes','No, I want to change it'},...
                   'DefaultOption',1,'CancelOption',1);
                    if strcmpi(selection,'No, I want to change it')
                        if app.output.malign__zero
                            msg = 'Please, indicate the BI-RADS class that you consider correct.';
                            title = 'Correct classification';
                            selection = uiconfirm(app.figure1,msg,title,...
                           'Options',{'BI-RADS 2','BI-RADS 3','Cancel'},...
                           'DefaultOption',3,'CancelOption',3);
                            if strcmpi(selection,'Cancel')
                            elseif strcmpi(selection,'BI-RADS 2')
                                app.output.class_results.b_class = 'b2';
                                out_text = sprintf('The breast mass has been predicted to belong to BI-RADS 2 category (0%% likelihood of malignancy).');
                            elseif strcmpi(selection,'BI-RADS 3')
                                app.output.class_results.b_class = 'b3';
                                out_text = sprintf('The breast mass has been predicted to belong to BI-RADS 3 category (between 0%% and 2%% likelihood of malignancy):\nshort-interval follow-up or continuous surveillance is suggested.');
                            end
    %                         app.diagnosis.Text = out_text;
                        else
                            msg = 'Please, indicate the BI-RADS class that you consider correct.';
                            title = 'Correct classification';
                            selection = uiconfirm(app.figure1,msg,title,...
                           'Options',{'BI-RADS 3','BI-RADS 4','BI-RADS 5','Cancel'},...
                           'DefaultOption',4,'CancelOption',4);
                            if strcmpi(selection,'Cancel')
                            elseif strcmpi(selection,'BI-RADS 3')
                                app.output.class_results.b_class = 'b3';
                                out_text = sprintf('The breast mass has been predicted to belong to BI-RADS 3 category (between 0%% and 2%% likelihood of malignancy):\nshort-interval follow-up or continuous surveillance is suggested.');
                            elseif strcmpi(selection,'BI-RADS 4')
                                app.output.class_results.b_class = 'b4';
                                out_text = sprintf('The breast mass has been predicted to belong to BI-RADS 4 category (between 2%% and 95%% likelihood of malignancy):\ntissue diagnosis is suggested.');
                            elseif strcmpi(selection,'BI-RADS 5')
                                app.output.class_results.b_class = 'b5';
                                out_text = sprintf('The breast mass has been predicted to belong to BI-RADS 5 category (more than 95%% likelihood of malignancy):\ntissue diagnosis is suggested.');
                            end
                            
                        end
    
    %                     app.diagnosis.Visible = "on";
                    end
                    
                    out_text = strrep(out_text,'predicted','indicated');
                end


                app.diagnosis.Text = out_text;
                pause(0.1)
                
                app.pdf__path = trace4bus__testreport(out_text,in.pid,app.output.path.save, extra_text);
                if isempty(app.pdf__path)
                    text_log = 'Report generation NOT completed';
                    [~, app.license__fullpath.local] = check__license__local();
                    update_log(app.username,text_log,app.license__fullpath.local)
                else
                    text_log = 'Report generation completed';
                    [~, app.license__fullpath.local] = check__license__local();
                    update_log(app.username,text_log,app.license__fullpath.local)
                end
                % Increase counter
%                 [~, license__fullpath] = check__license__local();
%                 if ~isempty(app.pdf__path)
%                     increase__counter(license__fullpath,'bc','testmodel');
%                 end  
                
                if ispc
                    app.viewreport.Visible = "on";
                    app.viewreport.Enable = "on";
                end
%                 load(license__fullpath,'counter');
%                 if counter.bc.testmodel < counter.demomax.bc || counter.demomax.bc == 0
                    app.testnew1.Enable = "on";
%                 end
                lista = dir(app.output.path.save);
                lista(1:2) = [];
                for i=1:length(lista)
                    if endsWith(lista(i).name, '.pdf')
                    else
                        try rmdir(fullfile(lista(i).folder,lista(i).name),'s')
                        catch
                            try delete(fullfile(lista(i).folder,lista(i).name))
                            catch
                            end
                        end
                    end
                end
                
%                 try lista = dir(fullfile(app.input_data.path.save,'*bioban*'));
%                     if ~isempty(lista)>0
%                         movefile(fullfile(app.input_data.path.save,'volumes'),...
%                         strcat(app.input_data.path.save,'\',app.input_data.pid,'_biobanca(', num2str(length(lista)),')'));
%                     else
%                         movefile(fullfile(app.input_data.path.save,'volumes'),...
%                         strcat(app.input_data.path.save,'\',app.input_data.pid,'_biobanca'));
%                     end
%                 catch
%                     try endout = regexp(app.input_data.path.save,filesep,'split');
%                         app.input_data.path.save = endout{1,1};
%                         for i = 2:(size(endout,2)-1)
%                             app.input_data.path.save = strcat(app.input_data.path.save,'/',endout{1,i});
%                         end
%                         lista = dir(fullfile(app.input_data.path.save,'*bioban*'));
%                         if ~isempty(lista)>0
%                             movefile(fullfile(app.input_data.path.save,'volumes'),...
%                             strcat(app.input_data.path.save,'\',app.input_data.pid,'_biobanca(', num2str(length(lista)),')'));
%                         else
%                             movefile(fullfile(app.input_data.path.save,'volumes'),...
%                             strcat(app.input_data.path.save,'\',app.input_data.pid,'_biobanca'));
%                         end
%                     catch
%                     end
%                 end
% 
% 
%                 try lista = dir(fullfile(app.input_data.path.save,'*_feature*'));
%                     if ~isempty(lista)>0
%                         j=1;
%                         while j<=length(lista)
%                             if endsWith(lista(j).name,'.pdf')
%                                 lista(j) = [];
%                             else
%                                 j = j+1;
%                             end
%                         end
%                     end
%                     if ~isempty(lista)>0
%                         mkdir__ifnotexist(strcat(app.input_data.path.save,'\',app.input_data.pid,'_features(', num2str(length(lista)),')'));
%                         movefile(fullfile(app.input_data.path.save,'results','*.xlsx'),...
%                             strcat(app.input_data.path.save,'\',app.input_data.pid,'_features(', num2str(length(lista)),')'));
%                         movefile(fullfile(app.input_data.path.save,'*__features_report.pdf'),...
%                             strcat(app.input_data.path.save,'\',app.input_data.pid,'_features(', num2str(length(lista)),')'));
%                     else
%                         mkdir__ifnotexist(strcat(app.input_data.path.save,'\',app.input_data.pid,'_features'));
%                         movefile(fullfile(app.input_data.path.save,'results','*.xlsx'),...
%                             strcat(app.input_data.path.save,'\',app.input_data.pid,'_features'));
%                         movefile(fullfile(app.input_data.path.save,'*__features_report.pdf'),...
%                             strcat(app.input_data.path.save,'\',app.input_data.pid,'_features'));
%                     end
%                 catch
%                 end
% 
%                 try rmdir(fullfile(app.input_data.path.save,'results'),'s'); end
%                 try delete(fullfile(app.input_data.path.save,'*.mat')); end
%                 try delete(app.res__path); end
%                 try delete(app.path.nimg); end
%                 try delete(strcat(app.path.save, '\mri__montage.png')); end
                
            catch ME
                
                disp(ME)
                set(app.PROCESSINGLabel, 'Text', 'Something went wrong. Please, check data and model.')
%                 set(app.Image2, 'Enable', 'On');
                set(app.testnew1, 'Enable', 'On');
                    
            end
            
        end

        % Button pushed function: testnew1
        function testnew1ButtonPushed(app, event)
            app.figure1.Visible = "off";
%             try delete(app.path.nimg); end
            
            try
                arg2 = [];
                arg2.pathology = 'bc';
                arg2.buttons_on = app.output.buttons_on;
%                 arg2.model = app.output.results.results.model;
                arg2.modelli = app.output.modelli;
%                 arg2.model_path = app.output.path.model;
                arg2.username = app.username;
                arg2.password = app.password;
%                 try rmfield(arg2,'model__path'); end
                trace4bus__multi_test1(arg2, [], []);
            catch
                out = app.output.modelli;
                trace4BUS_login(out);
            end
            closereq;
        end

        % Button pushed function: viewreport
        function viewreportButtonPushed(app, event)
            system(['start "TRACE4BUS | Test Report" ','"', app.pdf__path,'"']);
        end

        % Button pushed function: CHANGEPASSWORDButton
        function CHANGEPASSWORDButtonPushed(app, event)
            try
                trace4__pwdreset(app.username,app.password,2);
            catch
                uialert(app.figure1, 'Unexpected error.', 'Error','Icon','error=')
            end
        end

        % Button pushed function: LOGOUTButton
        function LOGOUTButtonPushed(app, event)
            out = app.output.modelli;
            text_log = 'Logout completed';
            [~, app.license__fullpath.local] = check__license__local();
            update_log(app.username,text_log,app.license__fullpath.local)
            closereq;
            trace4BUS_login(out);
        end

        % Button pushed function: indications
        function indicationsButtonPushed(app, event)
            uialert(app.figure1, sprintf(' TRACE4BUS is indicated for women with breast masses detected at ultrasound imaging and considered suspicious for breast cancer by physicians with experience in breast ultrasound. Physicians with experience in breast ultrasound can use TRACE4BUS as a support for: reporting ultrasound-detected suspicious breast masses; assigning the diagnostic category to such masses according to the BI-RADS classification; and, as a consequence, making an informed decision to send such masses to ultrasound-guided needle biopsy or to short interval follow-up. Physicians with experience in breast ultrasound can use TRACE4BUS as a support for diagnosing and managing patients with ultrasound-detected suspicious breast masses. The TRACE4BUS software provides an assessment of suspicious breast masses at the date of the ultrasound examination in which such masses are detected.\n\nIt should be noted that DeepTrace Technologies S.R.L. considers TRACE4BUS as a support to physicians with experience in breast ultrasound for reporting, diagnosis and decision-making of suspicious breast masses. However, these medical professionals retain the ultimate decision-making responsibility.'), 'Indications of use','Icon','info');
        end

        % Button pushed function: terms
        function termsButtonPushed(app, event)
            uialert(app.figure1, sprintf("1.	Acceptance of Agreement. You agree to the terms and conditions outlined in this Terms of Use Agreement ('Agreement') with respect to the software 'TRACE4BUS' created by DeepTrace Technologies S.R.L. (the 'software').\n2.	Intellectual Property/Limited License to Users. Any unauthorized use of the Materials, Content or Tools within this Software may violate laws and the Terms of Use. You agree not to copy, republish, frame, download, transmit, modify, rent, lease, loan, sell, assign, distribute, license, sublicense, reverse engineer, or create derivative works and tools based on this Software, its Materials, Content or Tools or their selection and arrangement, except as expressly authorized herein by DeepTrace Technologies S.R.L. You agree that you will not decompile, reverse engineer, or otherwise attempt to discover the source code of this software.\n3.	Limited Right to Use. The viewing, using, printing or downloading of any Material, Content, Graphic, Form or Document from this software grants you only a limited, nonexclusive license for use solely by the purpose defined under the Contract with by DeepTrace Technologies S.R.L, and not for republication, distribution, assignment, sublicense, sale, preparation of derivative works or other use unauthorized by DeepTrace Technologies S.R.L. No part of any content, form or document may be reproduced in any form or incorporated into any information retrieval system, electronic or mechanical, other than for the use defined under the Contract (but not for resale or redistribution). The rights granted to you constitute a license and not a transfer of title."), 'Terms of use','Icon','info');
        end

        % Button pushed function: regulatory
        function regulatoryButtonPushed(app, event)
            Regulatory();
%             uialert(app.figure1, "This software is not a medical device. It is not CE marked nor FDA cleared. Any use of this software and the associated information is for research and statistical analysis only.", 'Regulatory','Icon','info');
        end

        % Menu selected function: TechnicalSheetMenu
        function TechnicalSheetMenuSelected(app, event)
            if isdeployed
                
                p = mfilename('fullpath');
                endout = regexp(p, filesep, 'split');
            
                for i = 1:size(endout,2)-1
                    if i == 1
                        path_exe = endout{1,1};
                    else
                        path_exe = strcat(path_exe, '/' ,endout{1,i});
                    end  
                end
                
                path_pdf = fullfile(path_exe, 'docs', 'SWE-TRACE4BUSv2.0-D4_TechSheet.pdf');
                system(['start "TRACE4BUS | Technical Sheet" ','"', path_pdf,'"']);
                
            else
                
                system(['start "TRACE4BUS | Technical Sheet" ','"', 'docs/SWE-TRACE4BUSv2.0-D4_TechSheet.pdf','"']);
                
            end

        end

        % Menu selected function: UserManualMenu
        function UserManualMenuSelected(app, event)
            if isdeployed
                
                p = mfilename('fullpath');
                endout = regexp(p, filesep, 'split');
            
                for i = 1:size(endout,2)-1
                    if i == 1
                        path_exe = endout{1,1};
                    else
                        path_exe = strcat(path_exe, '/' ,endout{1,i});
                    end  
                end
                
                path_pdf = fullfile(path_exe, 'docs', 'SWE-TRACE4BUSv2.0-D2_UserManual.pdf');
                system(['start "TRACE4BUS | Technical Sheet" ','"', path_pdf,'"']);
                
            else
                
                system(['start "TRACE4BUS | User Manual" ','"', 'docs/SWE-TRACE4BUSv2.0-D2_UserManual.pdf','"']);
                
            end

        end

        % Menu selected function: ReportsFolderMenu
        function ReportsFolderMenuSelected(app, event)
            try
                
                [check__pref, app.output.preferences__fullpath] = check__preferences__local();
                if check__pref == 1
                    tempdir = uigetdir('*.mat');
                    if ~isempty(tempdir) && ischar(tempdir)
                        figure(app.figure1); % Bringing the window up
                        load(app.output.preferences__fullpath, 't4bus__preferences');
                        t4bus__preferences.reports__path = tempdir;
                        save(app.output.preferences__fullpath, '-append', 't4bus__preferences');
                        uialert(app.figure1, 'Reports path successfully updated.', 'Info','Icon','info')
                    end
                end


            catch 
                
                uialert(app.figure1, 'Unexpected error.', 'Error','Icon','error')
                
            end     
        end

        % Menu selected function: ChangePasswordMenu
        function ChangePasswordMenuSelected(app, event)
            try
                trace4__pwdreset(app.username,app.password,2);
            catch
                uialert(app.figure1, 'Unexpected error.', 'Error','Icon','error=')
            end
        end

        % Menu selected function: LogoutMenu
        function LogoutMenuSelected(app, event)
            out = app.output.modelli;
            closereq;
            trace4BUS_login(out);
        end

        % Button pushed function: viewreport_feat
        function viewreport_featButtonPushed(app, event)
            system(['start "TRACE4BUS | Features Report" ','"', app.output.us__pdfpath,'"']);
        end

        % Menu selected function: UltrasoundImageProtocolMenu
        function UltrasoundImageProtocolMenuSelected(app, event)
            if isdeployed
                
                p = mfilename('fullpath');
                endout = regexp(p, filesep, 'split');
            
                for i = 1:size(endout,2)-1
                    if i == 1
                        path_exe = endout{1,1};
                    else
                        path_exe = strcat(path_exe, '/' ,endout{1,i});
                    end  
                end
                
                path_pdf = fullfile(path_exe, 'docs', 'SWE-TRACE4BUSv2.0-D5_AcquisitionProtocol.pdf');
                system(['start "TRACE4BUS | USI Protocol" ','"', path_pdf,'"']);
                
            else
                
                system(['start "TRACE4BUS | USI Protocol" ','"', 'docs/SWE-TRACE4BUSv2.0-D5_AcquisitionProtocol.pdf','"']);
                
            end
        end

        % Menu selected function: SegmentationProcedureMenu
        function SegmentationProcedureMenuSelected(app, event)
            if isdeployed
                
                p = mfilename('fullpath');
                endout = regexp(p, filesep, 'split');
            
                for i = 1:size(endout,2)-1
                    if i == 1
                        path_exe = endout{1,1};
                    else
                        path_exe = strcat(path_exe, '/' ,endout{1,i});
                    end  
                end
                
                path_pdf = fullfile(path_exe, 'docs', 'SWE-TRACE4BUSv2.0-D6_SegmentationProcedure.pdf');
                system(['start "TRACE4BUS | Segmentation procedure" ','"', path_pdf,'"']);
                
            else
                
                system(['start "TRACE4BUS | Segmentation procedure" ','"', 'docs/SWE-TRACE4BUSv2.0-D6_SegmentationProcedure.pdf','"']);
                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'TRACE4BUS';
            app.DocumentationMenu = uimenu(app.figure1);
            app.DocumentationMenu.Text = '  Documentation  ';
            app.UserManualMenu = uimenu(app.DocumentationMenu);
            app.UserManualMenu.MenuSelectedFcn = createCallbackFcn(app, @UserManualMenuSelected, true);
            app.UserManualMenu.Text = 'User Manual';
            app.TechnicalSheetMenu = uimenu(app.DocumentationMenu);
            app.TechnicalSheetMenu.MenuSelectedFcn = createCallbackFcn(app, @TechnicalSheetMenuSelected, true);
            app.TechnicalSheetMenu.Text = 'Technical Sheet';
            app.UltrasoundImageProtocolMenu = uimenu(app.DocumentationMenu);
            app.UltrasoundImageProtocolMenu.MenuSelectedFcn = createCallbackFcn(app, @UltrasoundImageProtocolMenuSelected, true);
            app.UltrasoundImageProtocolMenu.Text = 'Ultrasound Image Protocol';
            app.SegmentationProcedureMenu = uimenu(app.DocumentationMenu);
            app.SegmentationProcedureMenu.MenuSelectedFcn = createCallbackFcn(app, @SegmentationProcedureMenuSelected, true);
            app.SegmentationProcedureMenu.Text = 'Segmentation Procedure';
            app.PreferencesMenu = uimenu(app.figure1);
            app.PreferencesMenu.Text = '  Preferences  ';
            app.SetPathsMenu = uimenu(app.PreferencesMenu);
            app.SetPathsMenu.Text = 'Set Paths';
            app.ReportsFolderMenu = uimenu(app.SetPathsMenu);
            app.ReportsFolderMenu.MenuSelectedFcn = createCallbackFcn(app, @ReportsFolderMenuSelected, true);
            app.ReportsFolderMenu.Text = 'Reports Folder';
            app.Menu = uimenu(app.figure1);
            app.Menu.Enable = 'off';
            app.Menu.Text = '                                                                                                                                                                                                                    ';
            app.ChangePasswordMenu = uimenu(app.figure1);
            app.ChangePasswordMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangePasswordMenuSelected, true);
            app.ChangePasswordMenu.Text = '  Change Password  ';
            app.LogoutMenu = uimenu(app.figure1);
            app.LogoutMenu.MenuSelectedFcn = createCallbackFcn(app, @LogoutMenuSelected, true);
            app.LogoutMenu.Text = '  Logout  ';
            app.logo = uiimage(app.figure1);
            app.logo.ImageSource = 'logo-trace4bus(c).png';
            app.diagnosis = uilabel(app.figure1);
            app.diagnosis.Visible = 'off';
            app.diagnosis.Text = 'The tested patient was automatically classified as belonging to the () diagnostic class.';
            app.viewreport = uibutton(app.figure1, 'push');
            app.viewreport.ButtonPushedFcn = createCallbackFcn(app, @viewreportButtonPushed, true);
            app.viewreport.Enable = 'off';
            app.viewreport.Visible = 'off';
            app.viewreport.Text = {'View extended'; 'report (pdf)'};
            app.testnew1 = uibutton(app.figure1, 'push');
            app.testnew1.ButtonPushedFcn = createCallbackFcn(app, @testnew1ButtonPushed, true);
            app.testnew1.Enable = 'off';
            app.testnew1.Text = 'NEW ANALYSIS';
            app.PROCESSINGLabel = uilabel(app.figure1);
            app.PROCESSINGLabel.Visible = 'off';
            app.PROCESSINGLabel.Text = 'PROCESSING...';
            app.DeepTraceTechnologiesSRLLabel = uilabel(app.figure1);
            app.DeepTraceTechnologiesSRLLabel.Text = '© DeepTrace Technologies SRL | 2020';
            app.CHANGEPASSWORDButton = uibutton(app.figure1, 'push');
            app.CHANGEPASSWORDButton.ButtonPushedFcn = createCallbackFcn(app, @CHANGEPASSWORDButtonPushed, true);
            app.CHANGEPASSWORDButton.Enable = 'off';
            app.CHANGEPASSWORDButton.Visible = 'off';
            app.CHANGEPASSWORDButton.Text = 'CHANGE PASSWORD';
            app.LOGOUTButton = uibutton(app.figure1, 'push');
            app.LOGOUTButton.ButtonPushedFcn = createCallbackFcn(app, @LOGOUTButtonPushed, true);
            app.LOGOUTButton.Enable = 'off';
            app.LOGOUTButton.Visible = 'off';
            app.LOGOUTButton.Text = 'LOGOUT';
            app.indications = uibutton(app.figure1, 'push');
            app.indications.ButtonPushedFcn = createCallbackFcn(app, @indicationsButtonPushed, true);
            app.indications.Tooltip = {''};
            app.indications.Text = 'INDICATIONS OF USE';
            app.terms = uibutton(app.figure1, 'push');
            app.terms.ButtonPushedFcn = createCallbackFcn(app, @termsButtonPushed, true);
            app.terms.Tooltip = {''};
            app.terms.Text = 'TERMS OF USE';
            app.regulatory = uibutton(app.figure1, 'push');
            app.regulatory.ButtonPushedFcn = createCallbackFcn(app, @regulatoryButtonPushed, true);
            app.regulatory.Tooltip = {''};
            app.regulatory.Text = 'REGULATORY';
            app.viewreport_feat = uibutton(app.figure1, 'push');
            app.viewreport_feat.ButtonPushedFcn = createCallbackFcn(app, @viewreport_featButtonPushed, true);
            app.viewreport_feat.Enable = 'off';
            app.viewreport_feat.Visible = 'off';
            app.viewreport_feat.Text = {'View features'; 'report (pdf)'};
            app.subtitle = uilabel(app.figure1);
            app.subtitle.Text = 'BREAST MASS ANALYZER';
            app.shape_diam = uilabel(app.figure1);
            app.shape_diam.Visible = 'off';
            app.shape_diam.Text = 'Maximum length (mm):';
            app.shape_area = uilabel(app.figure1);
            app.shape_area.Visible = 'off';
            app.shape_area.Text = 'Area (mm2):';
            app.shape_contour = uilabel(app.figure1);
            app.shape_contour.Visible = 'off';
            app.shape_contour.Text = 'Shape:';
            app.shape_orientation = uilabel(app.figure1);
            app.shape_orientation.Visible = 'off';
            app.shape_orientation.Text = 'Orientation:';
            app.shape_diam_2 = uilabel(app.figure1);
            app.shape_diam_2.Visible = 'off';
            app.shape_diam_2.Text = '';
            app.shape_area_2 = uilabel(app.figure1);
            app.shape_area_2.Visible = 'off';
            app.shape_area_2.Text = '';
            app.shape_contour_2 = uilabel(app.figure1);
            app.shape_contour_2.Visible = 'off';
            app.shape_contour_2.Text = '';
            app.shape_orientation_2 = uilabel(app.figure1);
            app.shape_orientation_2.Visible = 'off';
            app.shape_orientation_2.Text = '';
            app.shape_title = uilabel(app.figure1);
            app.shape_title.Visible = 'off';
            app.shape_title.Text = 'BI-RADS DESCRIPTORS';
            app.diagnosis_2 = uilabel(app.figure1);
            app.diagnosis_2.Visible = 'off';
            app.diagnosis_2.Text = {'BI-RADS predictors shown as additional output of the analysis (in addition to the BI-RADS category of the breast mass), '; 'i.e., maximum length, area, shape, and orientation, refer to the contour manually drawn by the end-user.'; 'These predictors may include tissues external to the suspicious mass, in particular when acoustic shadow or perilesional reactive phenomena are present near the breast mass.'};
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = trace4bus__test2(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)trace4bus__test2_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end